classdef TAT2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        S2T2                            matlab.ui.Figure
        LoadMenu                        matlab.ui.container.Menu
        InputMenu                       matlab.ui.container.Menu
        AllInputMenu                    matlab.ui.container.Menu
        EnvironmentMenu                 matlab.ui.container.Menu
        GMMMenu                         matlab.ui.container.Menu
        TMMandDissipationMenu           matlab.ui.container.Menu
        ResultsMenu                     matlab.ui.container.Menu
        TransientMenu                   matlab.ui.container.Menu
        OptimizationMenu                matlab.ui.container.Menu
        GridLayout_all                  matlab.ui.container.GridLayout
        ReadytoRunSimulationLabel       matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        ConfirmSaveButton               matlab.ui.control.Button
        TMMDissipationLabel             matlab.ui.control.Label
        Lamp_3                          matlab.ui.control.Lamp
        GMMLabel                        matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        EnvironmentLabel                matlab.ui.control.Label
        InputSetLamp                    matlab.ui.control.Lamp
        InputsetupLabel                 matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ExternalEnvironmentTab          matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        CasestudiesSwitch               matlab.ui.control.Switch
        CasestudiesSwitchLabel          matlab.ui.control.Label
        OrbitalPeriodTextArea           matlab.ui.control.TextArea
        AttitudePanel                   matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        WorstCasePanel_2                matlab.ui.container.Panel
        GridLayout53                    matlab.ui.container.GridLayout
        FaceDropDown_3                  matlab.ui.control.DropDown
        FaceDropDown_3Label             matlab.ui.control.Label
        PointingDropDown_3              matlab.ui.control.DropDown
        PointingDropDown_3Label         matlab.ui.control.Label
        SpinCheckBox_2                  matlab.ui.control.CheckBox
        FaceDropDown_4                  matlab.ui.control.DropDown
        PointingDropDown_4              matlab.ui.control.DropDown
        WorstCasePanel                  matlab.ui.container.Panel
        GridLayout52                    matlab.ui.container.GridLayout
        FaceDropDown                    matlab.ui.control.DropDown
        FaceDropDownLabel               matlab.ui.control.Label
        PointingDropDown                matlab.ui.control.DropDown
        PointingDropDownLabel           matlab.ui.control.Label
        SpinCheckBox                    matlab.ui.control.CheckBox
        FaceDropDown_2                  matlab.ui.control.DropDown
        PointingDropDown_2              matlab.ui.control.DropDown
        GenerateplotandSaveButton       matlab.ui.control.Button
        SCOrbitalDataPanel              matlab.ui.container.Panel
        GridLayout50                    matlab.ui.container.GridLayout
        AltitudeEditField               matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel          matlab.ui.control.Label
        BetaAngleEditField              matlab.ui.control.NumericEditField
        BetaAngleEditFieldLabel         matlab.ui.control.Label
        RAANEditField                   matlab.ui.control.NumericEditField
        RAANEditFieldLabel              matlab.ui.control.Label
        SMAEditField                    matlab.ui.control.NumericEditField
        SMAEditFieldLabel               matlab.ui.control.Label
        ArgumentperiapsidEditField      matlab.ui.control.NumericEditField
        ArgumentperiapsidLabel          matlab.ui.control.Label
        InclinationEditField            matlab.ui.control.NumericEditField
        InclinationEditFieldLabel       matlab.ui.control.Label
        EccentricityEditField           matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel      matlab.ui.control.Label
        degLabel_4                      matlab.ui.control.Label
        KmLabel_3                       matlab.ui.control.Label
        degLabel_3                      matlab.ui.control.Label
        degLabel_2                      matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        KmLabel_2                       matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        SimulationDataPanel             matlab.ui.container.Panel
        GridLayout49                    matlab.ui.container.GridLayout
        Label_3                         matlab.ui.control.Label
        HourdecimalEditField            matlab.ui.control.NumericEditField
        HourdecimalEditFieldLabel       matlab.ui.control.Label
        hLabel                          matlab.ui.control.Label
        OrbitsEditField                 matlab.ui.control.NumericEditField
        OrbitsEditFieldLabel            matlab.ui.control.Label
        TimeStepEditField               matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel          matlab.ui.control.Label
        SimulationdateDatePicker        matlab.ui.control.DatePicker
        SimulationdateLabel             matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        CentralbodypropertiesPanel      matlab.ui.container.Panel
        GridLayout51                    matlab.ui.container.GridLayout
        PlanetIRfluxEditField           matlab.ui.control.NumericEditField
        PlanetIRfluxEditFieldLabel      matlab.ui.control.Label
        PlanetRadiusEditField           matlab.ui.control.NumericEditField
        PlanetRadiusEditFieldLabel      matlab.ui.control.Label
        AlbedoCoefEditField             matlab.ui.control.NumericEditField
        AlbedoCoefEditFieldLabel        matlab.ui.control.Label
        GravitationalConstantEditField  matlab.ui.control.NumericEditField
        GravitationalConstantLabel      matlab.ui.control.Label
        SolarConstEditField             matlab.ui.control.NumericEditField
        SolarConstEditFieldLabel        matlab.ui.control.Label
        CentralBodyDropDown             matlab.ui.control.DropDown
        CentralBodyDropDownLabel        matlab.ui.control.Label
        Km3s2Label                      matlab.ui.control.Label
        KmLabel                         matlab.ui.control.Label
        Wm2Label_2                      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Wm2Label                        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        InternalEnvironmentTab          matlab.ui.container.Tab
        OperatingModesPanel             matlab.ui.container.Panel
        ModesLamp                       matlab.ui.control.Lamp
        ConfirmOperatingModesButton     matlab.ui.control.Button
        OperativeModesTable             matlab.ui.control.Table
        NModesEditField                 matlab.ui.control.NumericEditField
        NumberofOperatingModesEditFieldLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        GMMTab                          matlab.ui.container.Tab
        GridLayout26                    matlab.ui.container.GridLayout
        VisibilitySlider_3              matlab.ui.control.Slider
        VisibilitySlider_3Label         matlab.ui.control.Label
        CalculateViewFactorButton       matlab.ui.control.Button
        ShowIDButton_3                  matlab.ui.control.Button
        IDListBox_3                     matlab.ui.control.ListBox
        PreviewandUpdateGMMButton       matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        StructureTab                    matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        PropertiesPanel_2               matlab.ui.container.Panel
        GridLayout42                    matlab.ui.container.GridLayout
        UITable2_ex                     matlab.ui.control.Table
        NodesconfigurationandGeometryPanel_2  matlab.ui.container.Panel
        GridLayout41                    matlab.ui.container.GridLayout
        UITable1_ex                     matlab.ui.control.Table
        SolarPanelsTab                  matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        SolarPanelsLabel                matlab.ui.control.Label
        SolarPanelsSpinner              matlab.ui.control.Spinner
        DeleteItemButton                matlab.ui.control.Button
        PropertiesPanel                 matlab.ui.container.Panel
        GridLayout30                    matlab.ui.container.GridLayout
        UITable2_3                      matlab.ui.control.Table
        NodesconfigurationandGeometryPanel  matlab.ui.container.Panel
        GridLayout29                    matlab.ui.container.GridLayout
        UITable_2                       matlab.ui.control.Table
        okLamp_3                        matlab.ui.control.Lamp
        SolarPanelsInitializationButton  matlab.ui.control.Button
        BoardsTab                       matlab.ui.container.Tab
        GridLayout33                    matlab.ui.container.GridLayout
        BoardsSpinner                   matlab.ui.control.Spinner
        BoardsSpinnerLabel              matlab.ui.control.Label
        DeleteItemButton_2              matlab.ui.control.Button
        PropertiesPanel_3               matlab.ui.container.Panel
        GridLayout32                    matlab.ui.container.GridLayout
        UITable2_4                      matlab.ui.control.Table
        NodesconfigurationandGeometryPanel_3  matlab.ui.container.Panel
        GridLayout31                    matlab.ui.container.GridLayout
        UITable_3                       matlab.ui.control.Table
        okLamp_4                        matlab.ui.control.Lamp
        BoardsInitializationButton      matlab.ui.control.Button
        ParallelepipedsTab              matlab.ui.container.Tab
        GridLayout37                    matlab.ui.container.GridLayout
        ParallelepipedsLabel            matlab.ui.control.Label
        ParallelepipedsSpinner          matlab.ui.control.Spinner
        DeleteItemButton_3              matlab.ui.control.Button
        OpticalPropertiesPanel          matlab.ui.container.Panel
        GridLayout35                    matlab.ui.container.GridLayout
        UITable2_7                      matlab.ui.control.Table
        MechanicalPropertiesPanel       matlab.ui.container.Panel
        GridLayout36                    matlab.ui.container.GridLayout
        UITable2_5                      matlab.ui.control.Table
        NodesconfigurationandGeometryPanel_4  matlab.ui.container.Panel
        GridLayout34                    matlab.ui.container.GridLayout
        UITable_4                       matlab.ui.control.Table
        okLamp_5                        matlab.ui.control.Lamp
        ParallelepipedsInitializationButton  matlab.ui.control.Button
        CylindersTab                    matlab.ui.container.Tab
        GridLayout38                    matlab.ui.container.GridLayout
        CylindersLabel                  matlab.ui.control.Label
        CylindersSpinner                matlab.ui.control.Spinner
        DeleteItemButton_4              matlab.ui.control.Button
        PropertiesPanel_5               matlab.ui.container.Panel
        GridLayout40                    matlab.ui.container.GridLayout
        UITable2_6                      matlab.ui.control.Table
        NodesconfigurationandGeometryPanel_5  matlab.ui.container.Panel
        GridLayout39                    matlab.ui.container.GridLayout
        UITable_5                       matlab.ui.control.Table
        okLamp_6                        matlab.ui.control.Lamp
        CylindersInitializationButton   matlab.ui.control.Button
        UIAxes2_2                       matlab.ui.control.UIAxes
        TMMDissipationTab               matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        VisibilitySlider_2              matlab.ui.control.Slider
        VisibilitySlider_2Label         matlab.ui.control.Label
        ShowIDButton_2                  matlab.ui.control.Button
        IDListBox_2                     matlab.ui.control.ListBox
        AdditionalconductionPanel       matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        PreviewButton_2                 matlab.ui.control.Button
        Del_conductionButton            matlab.ui.control.Button
        Add_conductionButton            matlab.ui.control.Button
        UITable5                        matlab.ui.control.Table
        DissipationsPanel               matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        PreviewButton_3                 matlab.ui.control.Button
        Del_dissipationButton           matlab.ui.control.Button
        PreviewButton                   matlab.ui.control.Button
        Add_dissipationButton           matlab.ui.control.Button
        NodalDissipationsPanel          matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        UITable4                        matlab.ui.control.Table
        ItemDissipationsPanel           matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        UITable3                        matlab.ui.control.Table
        GenerateTMMButton               matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        AnalysisPostprocessingTab       matlab.ui.container.Tab
        GridLayout48                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        RunGraphTab                     matlab.ui.container.Tab
        GridLayout23                    matlab.ui.container.GridLayout
        WorstcasePanel_4                matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        Tree_2                          matlab.ui.container.Tree
        WorstcasePanel_3                matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        Tree                            matlab.ui.container.Tree
        PlotTemperatureButton           matlab.ui.control.Button
        PreparationLabel                matlab.ui.control.Label
        Lamp_4                          matlab.ui.control.Lamp
        RunSimulationButton             matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        HeatMapTab                      matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        TrasparencyButtonGroup          matlab.ui.container.ButtonGroup
        BothButton                      matlab.ui.control.RadioButton
        InternalButton                  matlab.ui.control.RadioButton
        ExternalButton                  matlab.ui.control.RadioButton
        TimeControlPanel                matlab.ui.container.Panel
        TimesEditField                  matlab.ui.control.NumericEditField
        TimesEditFieldLabel             matlab.ui.control.Label
        TimeminSlider                   matlab.ui.control.Slider
        TimeminSliderLabel              matlab.ui.control.Label
        HeatMapTemperatureCPanel        matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        UIAxes6                         matlab.ui.control.UIAxes
        SatellitepositionPanel          matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        UIAxes5                         matlab.ui.control.UIAxes
        HeatMapTab_2                    matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        TrasparencyButtonGroup_2        matlab.ui.container.ButtonGroup
        BothButton_2                    matlab.ui.control.RadioButton
        InternalButton_2                matlab.ui.control.RadioButton
        ExternalButton_2                matlab.ui.control.RadioButton
        TimeControlPanel_2              matlab.ui.container.Panel
        TimesEditField_2                matlab.ui.control.NumericEditField
        TimesEditField_2Label           matlab.ui.control.Label
        TimeminSlider_2                 matlab.ui.control.Slider
        TimeminSlider_2Label            matlab.ui.control.Label
        HeatMapTemperatureCPanel_2      matlab.ui.container.Panel
        UIAxes6_2                       matlab.ui.control.UIAxes
        SatellitepositionPanel_2        matlab.ui.container.Panel
        UIAxes5_2                       matlab.ui.control.UIAxes
        DesignTab                       matlab.ui.container.Tab
        GridLayout43                    matlab.ui.container.GridLayout
        TabGroup4                       matlab.ui.container.TabGroup
        OptimizationsettingsTab         matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        Lamp_7                          matlab.ui.control.Lamp
        DecisionmakeralgorithmsettingPanel  matlab.ui.container.Panel
        GridLayout44                    matlab.ui.container.GridLayout
        UITable13                       matlab.ui.control.Table
        PerformpruningofsolutionduringtransientCheckBox  matlab.ui.control.CheckBox
        PerformtransientonlyonParetosolutionsCheckBox  matlab.ui.control.CheckBox
        ConfirmStartOptimizationButton  matlab.ui.control.Button
        OptimizationalgorithmsettingPanel  matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        SavetransientresultsafteroptimizationCheckBox  matlab.ui.control.CheckBox
        OrbitstransientEditField        matlab.ui.control.NumericEditField
        OrbitstransientEditFieldLabel   matlab.ui.control.Label
        PlotobjectivespaceduringoptimizationCheckBox  matlab.ui.control.CheckBox
        TimesteptransientsEditField     matlab.ui.control.NumericEditField
        TimesteptransientsEditFieldLabel  matlab.ui.control.Label
        PopulationsizeEditField         matlab.ui.control.NumericEditField
        PopulationsizeEditFieldLabel    matlab.ui.control.Label
        MaxfunctionevaluationsEditField  matlab.ui.control.NumericEditField
        MaxfunctionevaluationsEditFieldLabel  matlab.ui.control.Label
        OptimizationalgorithmDropDown   matlab.ui.control.DropDown
        OptimizationalgorithmDropDownLabel  matlab.ui.control.Label
        HeatersPanel                    matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        UITable10_2                     matlab.ui.control.Table
        Lamp_5                          matlab.ui.control.Lamp
        ItemwithheaterPanel_2           matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        Tree3_4                         matlab.ui.container.CheckBoxTree
        ItemwithheaterPanel             matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        Tree3_3                         matlab.ui.container.CheckBoxTree
        UITable10                       matlab.ui.control.Table
        ConfirmheaterselectionButton    matlab.ui.control.Button
        ConductancelinksThermalstrapsPanel  matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        ConductivityWmKEditField        matlab.ui.control.NumericEditField
        ConductivityWmKEditFieldLabel   matlab.ui.control.Label
        Sectionareaupperboundmm2EditField  matlab.ui.control.NumericEditField
        Sectionareaupperboundmm2EditFieldLabel  matlab.ui.control.Label
        Sectionarealowerboundmm2EditField  matlab.ui.control.NumericEditField
        Sectionarealowerboundmm2EditFieldLabel  matlab.ui.control.Label
        EndingitemgroupofthelinksPanel  matlab.ui.container.Panel
        GridLayout9_2                   matlab.ui.container.GridLayout
        Tree3_2                         matlab.ui.container.CheckBoxTree
        StartingitemgroupofthelinksPanel  matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        Tree3                           matlab.ui.container.CheckBoxTree
        LinksSpinner                    matlab.ui.control.Spinner
        LinksSpinnerLabel               matlab.ui.control.Label
        OpticalpropertiesPanel_2        matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        Lamp_6                          matlab.ui.control.Lamp
        ConfirmpropertyselectionButton  matlab.ui.control.Button
        Tree2                           matlab.ui.container.CheckBoxTree
        UITable8                        matlab.ui.control.Table
        UIAxes8                         matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        ExportAllsolutionstabulardatatospreadsheetButton  matlab.ui.control.Button
        AllsolutionsPanel               matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        UITable12                       matlab.ui.control.Table
        UIAxes9                         matlab.ui.control.UIAxes
        RobustnessanalysisTab           matlab.ui.container.Tab
        GridLayout45                    matlab.ui.container.GridLayout
        RobustnessanalysissettingsPanel  matlab.ui.container.Panel
        GridLayout46                    matlab.ui.container.GridLayout
        PerturbationoptionsButtonGroup  matlab.ui.container.ButtonGroup
        ApplyperturbationbasedonsolutionparametersButton  matlab.ui.control.RadioButton
        ApplyperturbationbasedonupperandlowerboundsButton  matlab.ui.control.RadioButton
        GridLayout47                    matlab.ui.container.GridLayout
        Lamp_8                          matlab.ui.control.Lamp
        StartRobustnessAnalysisButton   matlab.ui.control.Button
        Maximumrelativevariationofoptimizationparameters01EditField  matlab.ui.control.NumericEditField
        MaximumrelativevariationofoptimizationparametersEditFieldLabel  matlab.ui.control.Label
        PerturbationsforeverytransientsolutionEditField  matlab.ui.control.NumericEditField
        PerturbationsforeverytransientsolutionEditFieldLabel  matlab.ui.control.Label
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        MCRTReflectionTab               matlab.ui.container.Tab
        GridLayoutRef                   matlab.ui.container.GridLayout
        TabGroupRef                     matlab.ui.container.TabGroup
        RunGraphTabRef                  matlab.ui.container.Tab
        GridLayoutRef_2                 matlab.ui.container.GridLayout
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        RunSimulationButtonRef          matlab.ui.control.Button
        WorstcasePanelRef_2             matlab.ui.container.Panel
        GridLayoutRef_4                 matlab.ui.container.GridLayout
        TreeRef_2                       matlab.ui.container.Tree
        WorstcasePanelRef_1             matlab.ui.container.Panel
        GridLayoutRef_3                 matlab.ui.container.GridLayout
        TreeRef_1                       matlab.ui.container.Tree
        PlotTemperatureButtonRef_1      matlab.ui.control.Button
        PreparationLabelRef             matlab.ui.control.Label
        LampRef                         matlab.ui.control.Lamp
        UIAxesRef_1                     matlab.ui.control.UIAxes
        HeatMapTabRef_1                 matlab.ui.container.Tab
        GridLayoutRef_5                 matlab.ui.container.GridLayout
        TrasparencyButtonGroupRef_1     matlab.ui.container.ButtonGroup
        BothButtonRef                   matlab.ui.control.RadioButton
        InternalButtonRef               matlab.ui.control.RadioButton
        ExternalButtonRef               matlab.ui.control.RadioButton
        TimeControlPanelRef_1           matlab.ui.container.Panel
        TimesEditFieldRef_1             matlab.ui.control.NumericEditField
        TimesEditField_3Label_2         matlab.ui.control.Label
        TimeminSliderRef_1              matlab.ui.control.Slider
        TimeminSlider_3Label_2          matlab.ui.control.Label
        HeatMapTemperatureCPanelRef_1   matlab.ui.container.Panel
        GridLayout17_3                  matlab.ui.container.GridLayout
        UIAxesRef_3                     matlab.ui.control.UIAxes
        SatellitepositionPanelRef_1     matlab.ui.container.Panel
        GridLayout16_3                  matlab.ui.container.GridLayout
        UIAxesRef_2                     matlab.ui.control.UIAxes
        HeatMapTabRef_2                 matlab.ui.container.Tab
        GridLayout5_3                   matlab.ui.container.GridLayout
        TrasparencyButtonGroupRef_2     matlab.ui.container.ButtonGroup
        BothButton_5                    matlab.ui.control.RadioButton
        InternalButton_5                matlab.ui.control.RadioButton
        ExternalButton_5                matlab.ui.control.RadioButton
        TimeControlPanelRef_2           matlab.ui.container.Panel
        TimesEditFieldRef_2             matlab.ui.control.NumericEditField
        TimesEditField_4Label_2         matlab.ui.control.Label
        TimeminSliderRef_2              matlab.ui.control.Slider
        TimeminSlider_4Label_2          matlab.ui.control.Label
        HeatMapTemperatureCPanelRef_2   matlab.ui.container.Panel
        UIAxesRef_5                     matlab.ui.control.UIAxes
        SatellitepositionPanelRef_2     matlab.ui.container.Panel
        UIAxesRef_4                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ext %tree information for ext faces
        int %tree information for int faces
        sat %sat model (all data)
        Res_ext % struct of coordinate and temperature for heatmap
        Res_int % struct of coordinate and temperature for heatmap
        Res_ext_c % struct of coordinate and temperature for heatmap
        Res_int_c % struct of coordinate and temperature for heatmap
        handles % Description
        mundi_pl % Description
        mundi_pl_c
        c % Colorbar
        c2 % Colormap
        c_c
        c2_c
        handles_int % Description
        ix % index time
        Res_ext_all % matrix for heat map for external structure
        Res_ext_all_c % Description
        handles_c % Description
        handles_int_c % Description
        name_item % string array of the name of the item, sorted by item ID
        opt_st
    end
    
    properties (Access = private)

        T_orbit = 0 % Description
    end
    
    methods (Access = private)    

        
        function generate_TMM(app)
            if ~isequal(app.ModesLamp.Color, [0 1 0])
    uialert(app.S2T2, 'Confirm the operative modes first (Internal Environment tab).', 'Operative modes not confirmed');
    return
end

            addpath Function\
            load GMM_complete.mat
            app.sat=sat;
            sigma=5.67e-8;
            app.DissipationsPanel.Visible='on';
            app.UIAxes3.Visible='on';
            app.AdditionalconductionPanel.Visible='on';
            [G_c,G0_Irr,G_hc] = TMM2(app.sat,sigma,Vf_G,C,eps_for_Ge);
            plot_GMM4(app.sat,app.UIAxes3);

            app.Lamp_2.Color=[0 1 0];
            app.Lamp_3.Color=[1 0 0];
            save TMM.mat G_c G0_Irr G_hc 
            if isequal (app.InputSetLamp.Color,[0 1 0]) && isequal (app.Lamp.Color,[0 1 0]) && isequal (app.Lamp_2.Color,[0 1 0])
                app.ConfirmSaveButton.Enable='on';
            end
value = app.CasestudiesSwitch.Value;
val = str2double(value);
N_item = 1 + app.sat.geom.Nsp + app.sat.geom.Nb + app.sat.geom.NP + app.sat.geom.Nc;
N_modes = height(app.OperativeModesTable.Data);
mode_names = app.OperativeModesTable.Data.mode_name;

switch val
    case 1
        power_names = mode_names + " [W]";
        col_names = [{'ID_Item','Item_name'}, cellstr(power_names)'];

        app.UITable3.ColumnName = col_names;
        app.UITable4.ColumnName = {'ID_node','Power [W]'};

        tdata = table((1:N_item)', app.name_item, 'VariableNames', {'ID_Item','Item_name'});
        power_data = array2table(zeros(N_item, N_modes), 'VariableNames', cellstr(power_names)');
        tdata = [tdata, power_data];

        app.UITable3.Data = tdata;
        app.UITable4.Data = [];
        app.PreviewButton.Text = 'Preview';
        app.PreviewButton_3.Visible = 0;

    case 2
        power_names_hot  = mode_names + "_HOT [W]";
        power_names_cold = mode_names + "_COLD [W]";

        interleaved_names = strings(2*N_modes,1);
        for m = 1:N_modes
            interleaved_names(2*m-1) = power_names_hot(m);
            interleaved_names(2*m)   = power_names_cold(m);
        end

        col_names = [{'ID_Item','Item_name'}, cellstr(interleaved_names)'];
        app.UITable3.ColumnName = col_names;
        app.UITable4.ColumnName = {'ID_node','Power_HOT [W]','Power_COLD [W]'};

        tdata = table((1:N_item)', app.name_item, 'VariableNames', {'ID_Item','Item_name'});
        power_data = array2table(zeros(N_item, 2*N_modes), 'VariableNames', cellstr(interleaved_names)');
        tdata = [tdata, power_data];

        app.UITable3.Data = tdata;
        app.UITable4.Data = [];
        app.PreviewButton.Text = 'Preview hot case';
        app.PreviewButton_3.Text = 'Preview cold case';
        app.PreviewButton_3.Visible = 1;
end
app.UITable3.ColumnEditable = true(size(app.UITable3.ColumnName'));
app.UITable3.ColumnEditable(1:2) = 0;
app.UITable4.ColumnEditable = true(size(app.UITable4.ColumnName'));

            app.IDListBox_2.Items=app.name_item;
            app.IDListBox_2.ItemsData=(1:N_item)';
            app.IDListBox_2.Visible=1;
            app.ShowIDButton_2.Visible=1;
            app.VisibilitySlider_2.Visible=1;
            app.VisibilitySlider_2Label.Visible=1;
            
        end
        
        function load_environment(app,fullFileName)

            load(fullFileName);

            addpath Function\
            addpath texture\
            if exist('orbit','var')
                disable_confirm_save(app)
                app.InputSetLamp.Color=[1 0 0];
                app.CentralBodyDropDown.Value=env.centralbody;
                app.SolarConstEditField.Value=env.c_s;                                             %Solar flux [W/m^2]
                app.PlanetIRfluxEditField.Value=env.c_ir;                                          %Ir planet flux [W/m^2]
                app.AlbedoCoefEditField.Value=env.c_a;                                             %Albedo coefficient []
                app.PlanetRadiusEditField.Value=env.Rp;                                            %Planet radius [Km]
                app.GravitationalConstantEditField.Value=env.mu;                                   %Gravitational constant [Km^3/s^2]
                app.Switch.Value=orbit.par_orb;
                if strcmp(orbit.par_orb,'Basic')
                    app.AltitudeEditField.Value=orbit.z;                                       %[Km] quota [km]
                    app.BetaAngleEditField.Value=orbit.beta_angle;                             %[deg] Beta angle
                    %orbit.inclination=beta_angle;
                elseif strcmp(orbit.par_orb,'Classic')
                    app.EccentricityEditField.Value=orbit.eccentricity;
                    app.SMAEditField.Value=orbit.SA;
                    app.InclinationEditField.Value=orbit.inclination;
                    app.RAANEditField.Value=orbit.LAN;
                    app.ArgumentperiapsidEditField.Value=orbit.AP;
                end
                Datevector=datestr([sim_data.year,sim_data.month,sim_data.day,0,0,0]);
                Datevector_=datetime(Datevector);
                app.SimulationdateDatePicker.Value=Datevector_;
                app.HourdecimalEditField.Value=sim_data.ethr;
                app.OrbitsEditField.Value=sim_data.n_orbit;
                app.TimeStepEditField.Value=sim_data.step;
                plot_mundi(r_dc,env,2,1,app.UIAxes,rsun)
                app.InputSetLamp.Color=[0 1 0];
                T_str=tempo(period);
                set(app.OrbitalPeriodTextArea,'Value', sprintf('\nThe period of the orbit is %f s:\nDays=%f\nH=%f\nMin=%f\nSec=%f\n',period,T_str(1),T_str(2),T_str(3),T_str(4)))
                
                app.CasestudiesSwitch.Value=num2str(val_cases);
                
                if isfield(attitude,'info')
                    app.SpinCheckBox.Value=1;
                    app.PointingDropDown.Enable=0;
                    app.FaceDropDown.Enable=0;
                    app.PointingDropDown_2.Enable=0;
                    app.FaceDropDown_2.Enable=0;
                else
                    app.SpinCheckBox.Value=0;
                    app.PointingDropDown.Enable=1;
                    app.FaceDropDown.Enable=1;
                    app.PointingDropDown_2.Enable=1;
                    app.FaceDropDown_2.Enable=1;
                    app.PointingDropDown.Value=attitude.info1;
                    app.PointingDropDown_2.Value=attitude.info2;
                    
                    if isequal(attitude.nadir,1)
                        if strcmp(attitude.info1,'Nadir')
                            app.FaceDropDown.Value='-Y';
                        elseif strcmp(attitude.info2,'Nadir')
                            app.FaceDropDown_2.Value='-Y';
                        end
                    elseif isequal(attitude.nadir,2)
                        if strcmp(attitude.info1,'Nadir')
                            app.FaceDropDown.Value='+X';
                        elseif strcmp(attitude.info2,'Nadir')
                            app.FaceDropDown_2.Value='+X';
                        end
                    elseif isequal(attitude.nadir,3)
                        if strcmp(attitude.info1,'Nadir')
                            app.FaceDropDown.Value='+Y';
                        elseif strcmp(attitude.info2,'Nadir')
                            app.FaceDropDown_2.Value='+Y';
                        end
                    elseif isequal(attitude.nadir,4)
                        if strcmp(attitude.info1,'Nadir')
                            app.FaceDropDown.Value='-X';
                        elseif strcmp(attitude.info2,'Nadir')
                            app.FaceDropDown_2.Value='-X';
                        end
                    elseif isequal(attitude.nadir,5)
                        if strcmp(attitude.info1,'Nadir')
                            app.FaceDropDown.Value='-Z';
                        elseif strcmp(attitude.info2,'Nadir')
                            app.FaceDropDown_2.Value='-Z';
                        end
                    elseif isequal(attitude.nadir,6)
                        if strcmp(attitude.info1,'Nadir')
                            app.FaceDropDown.Value='+Z';
                        elseif strcmp(attitude.info2,'Nadir')
                            app.FaceDropDown_2.Value='+Z';
                        end
                    end
                    if isequal(attitude.vel,1)
                        if strcmp(attitude.info1,'Velocity')
                            app.FaceDropDown.Value='-Y';
                        elseif strcmp(attitude.info2,'Velocity')
                            app.FaceDropDown_2.Value='-Y';
                        end
                    elseif isequal(attitude.vel,2)
                        if strcmp(attitude.info1,'Velocity')
                            app.FaceDropDown.Value='+X';
                        elseif strcmp(attitude.info2,'Velocity')
                            app.FaceDropDown_2.Value='+X';
                        end
                    elseif isequal(attitude.vel,3)
                        if strcmp(attitude.info1,'Velocity')
                            app.FaceDropDown.Value='+Y';
                        elseif strcmp(attitude.info2,'Velocity')
                            app.FaceDropDown_2.Value='+Y';
                        end
                    elseif isequal(attitude.vel,4)
                        if strcmp(attitude.info1,'Velocity')
                            app.FaceDropDown.Value='-X';
                        elseif strcmp(attitude.info2,'Velocity')
                            app.FaceDropDown_2.Value='-X';
                        end
                    elseif isequal(attitude.vel,5)
                        if strcmp(attitude.info1,'Velocity')
                            app.FaceDropDown.Value='-Z';
                        elseif strcmp(attitude.info2,'Velocity')
                            app.FaceDropDown_2.Value='-Z';
                        end
                    elseif isequal(attitude.vel,6)
                        if strcmp(attitude.info1,'Velocity')
                            app.FaceDropDown.Value='+Z';
                        elseif strcmp(attitude.info2,'Velocity')
                            app.FaceDropDown_2.Value='+Z';
                        end
                    end
                end
                app.WorstCasePanel.Title='Worst Case';
                app.WorstCasePanel_2.Title='Worst Case';
                app.WorstCasePanel_2.Visible=0;
                %app.AttitudePanel.Position=[325,329,266,142];
                if isequal(val_cases,2)
                    app.WorstCasePanel.Title='Hot Case';
                    app.WorstCasePanel_2.Title='Cold Case';
                    app.WorstCasePanel_2.Visible=1;
                    %app.AttitudePanel.Position=[325,329,525,142];
                    if isfield(attitude,'info')
                        app.SpinCheckBox_2.Value=1;
                        app.PointingDropDown_3.Enable=0;
                        app.FaceDropDown_3.Enable=0;
                        app.PointingDropDown_4.Enable=0;
                        app.FaceDropDown_4.Enable=0;
                    else
                        
                        app.SpinCheckBox_2.Value=0;
                        app.PointingDropDown_3.Enable=1;
                        app.FaceDropDown_3.Enable=1;
                        app.PointingDropDown_4.Enable=1;
                        app.FaceDropDown_4.Enable=1;
                        app.PointingDropDown_3.Value=attitude.info1_cold;
                        app.PointingDropDown_4.Value=attitude.info2_cold;
                        
                        if isequal(attitude.nadir_cold,1)
                            if strcmp(attitude.info1_cold,'Nadir')
                                app.FaceDropDown_3.Value='-Y';
                            elseif strcmp(attitude.info2_cold,'Nadir')
                                app.FaceDropDown_4.Value='-Y';
                            end
                        elseif isequal(attitude.nadir_cold,2)
                            if strcmp(attitude.info1_cold,'Nadir')
                                app.FaceDropDown_3.Value='+X';
                            elseif strcmp(attitude.info2_cold,'Nadir')
                                app.FaceDropDown_4.Value='+X';
                            end
                        elseif isequal(attitude.nadir_cold,3)
                            if strcmp(attitude.info1_cold,'Nadir')
                                app.FaceDropDown_3.Value='+Y';
                            elseif strcmp(attitude.info2_cold,'Nadir')
                                app.FaceDropDown_4.Value='+Y';
                            end
                        elseif isequal(attitude.nadir_cold,4)
                            if strcmp(attitude.info1_cold,'Nadir')
                                app.FaceDropDown_3.Value='-X';
                            elseif strcmp(attitude.info2_cold,'Nadir')
                                app.FaceDropDown_4.Value='-X';
                            end
                        elseif isequal(attitude.nadir_cold,5)
                            if strcmp(attitude.info1_cold,'Nadir')
                                app.FaceDropDown_3.Value='-Z';
                            elseif strcmp(attitude.info2_cold,'Nadir')
                                app.FaceDropDown_4.Value='-Z';
                            end
                        elseif isequal(attitude.nadir_cold,6)
                            if strcmp(attitude.info1_cold,'Nadir')
                                app.FaceDropDown_3.Value='+Z';
                            elseif strcmp(attitude.info2_cold,'Nadir')
                                app.FaceDropDown_4.Value='+Z';
                            end
                        end
                        if isequal(attitude.vel_cold,1)
                            if strcmp(attitude.info1_cold,'Velocity')
                                app.FaceDropDown_3.Value='-Y';
                            elseif strcmp(attitude.info2_cold,'Velocity')
                                app.FaceDropDown_4.Value='-Y';
                            end
                        elseif isequal(attitude.vel_cold,2)
                            if strcmp(attitude.info1_cold,'Velocity')
                                app.FaceDropDown_3.Value='+X';
                            elseif strcmp(attitude.info2_cold,'Velocity')
                                app.FaceDropDown_4.Value='+X';
                            end
                        elseif isequal(attitude.vel_cold,3)
                            if strcmp(attitude.info1_cold,'Velocity')
                                app.FaceDropDown_3.Value='+Y';
                            elseif strcmp(attitude.info2_cold,'Velocity')
                                app.FaceDropDown_4.Value='+Y';
                            end
                        elseif isequal(attitude.vel_cold,4)
                            if strcmp(attitude.info1_cold,'Velocity')
                                app.FaceDropDown_3.Value='-X';
                            elseif strcmp(attitude.info2_cold,'Velocity')
                                app.FaceDropDown_4.Value='-X';
                            end
                        elseif isequal(attitude.vel_cold,5)
                            if strcmp(attitude.info1_cold,'Velocity')
                                app.FaceDropDown_3.Value='-Z';
                            elseif strcmp(attitude.info2_cold,'Velocity')
                                app.FaceDropDown_4.Value='-Z';
                            end
                        elseif isequal(attitude.vel_cold,6)
                            if strcmp(attitude.info1_cold,'Velocity')
                                app.FaceDropDown_3.Value='+Z';
                            elseif strcmp(attitude.info2_cold,'Velocity')
                                app.FaceDropDown_4.Value='+Z';
                            end
                        end
                    end
                end

                if isequal (app.InputSetLamp.Color,[0 1 0]) && isequal (app.Lamp.Color,[0 1 0]) && isequal (app.Lamp_2.Color,[0 1 0])
                    app.ConfirmSaveButton.Enable='on';
                end

                app.CasestudiesSwitch.Value = string(val_cases);

            else
                fig3 = uifigure;
                uialert(fig3,'Wrong file or not selected','Error');
                app.InputSetLamp.Color=[1 0 0];
            end
            
        end
        
        function load_GMM(app, fullFileName)

            load(fullFileName);

            addpath Function\

            if exist("sat","var")

                disable_confirm_save(app)

                app.sat = sat;
                app.name_item = names;
                app.SolarPanelsSpinner.Value = app.sat.geom.Nsp;
                app.BoardsSpinner.Value = app.sat.geom.Nb;
                app.ParallelepipedsSpinner.Value = app.sat.geom.NP;
                app.CylindersSpinner.Value = app.sat.geom.Nc;
    
                app.UITable1_ex.Data = Table_Structure_Geom ;
                app.UITable2_ex.Data = Table_Structure_Prop ;
                app.UITable_2.Data = Table_SolarP_Geom ;
                app.UITable2_3.Data = Table_SolarP_Prop ;
                app.UITable_3.Data = Table_Board_Geom ;
                app.UITable2_4.Data = Table_Board_Prop ;
                app.UITable_4.Data = Table_Parall_Geom ;
                app.UITable2_5.Data = Table_Parall_PropM ;
                app.UITable2_7.Data = Table_Parall_PropO ;
                app.UITable_5.Data = Table_Cyl_Geom ;
                app.UITable2_6.Data = Table_Cyl_Prop ;
    
                if ~exist("Vf","var")
                    % sat-temporay.mat is loaded
                    app.UIAxes2_2.Visible = "off";
                    cla(app.UIAxes2_2)
                    app.VisibilitySlider_3.Visible = "off";
                    app.VisibilitySlider_3Label.Visible = "off";
                    app.IDListBox_3.Visible = "off";
                    app.ShowIDButton_3.Visible = "off";
                    app.CalculateViewFactorButton.Visible = "off";

                    if app.SolarPanelsSpinner.Value > 0
                        app.okLamp_3.Color=[0 1 0];
                    end
                    if app.BoardsSpinner.Value > 0
                        app.okLamp_4.Color=[0 1 0];
                    end
                    if app.ParallelepipedsSpinner.Value > 0
                        app.okLamp_5.Color=[0 1 0];
                    end
                    if app.CylindersSpinner.Value > 0
                        app.okLamp_6.Color=[0 1 0];
                    end


                else
                    % sat-input.mat is loaded
                    if isfile("GMM_temporary.mat")
                        delete GMM_temporary.mat
                    end
                    app.okLamp_3.Color=[0 1 0];
                    app.okLamp_4.Color=[0 1 0];
                    app.okLamp_5.Color=[0 1 0];
                    app.okLamp_6.Color=[0 1 0];
                    app.Lamp.Color=[0 1 0];
        
                    app.UIAxes2_2.Visible=1;
                    cla(app.UIAxes2_2);
                    plot_GMM4(app.sat,app.UIAxes2_2);
                    app.VisibilitySlider_3.Visible=1;
                    app.VisibilitySlider_3Label.Visible=1;
        
                    id=1:1:length(app.name_item);
                    app.IDListBox_3.Items=app.name_item; %all input, GMM
                    app.IDListBox_3.ItemsData=id; %all input, GMM
                    app.IDListBox_2.Items=app.name_item; %all input, TMM
                    app.IDListBox_2.ItemsData=id; %all input, TMM
        
                    app.IDListBox_3.Visible=1;
                    app.ShowIDButton_3.Visible=1;
        
                    app.CalculateViewFactorButton.Visible=1;

                    app.GenerateTMMButton.Enable='on';

                    app.TMMandDissipationMenu.Enable="on";
    
                end                
            else
                fig3 = uifigure;
                uialert(fig3,'Wrong file or not selected','Error');
                app.Lamp.Color=[1 0 0];
            end
            
        end
        
        function load_TMM_dissipation(app,fullFileName)

            load(fullFileName);

            addpath Function\

            if exist("G_c","var")
            
                disable_confirm_save(app)
                generate_TMM(app)

                if exist("data_c","var")
    
                    app.UITable5.Data = Table_Additional_Conduction;
                    app.UITable3.Data = Table_item_dissipation;
                    app.UITable4.Data = Table_nodal_dissipation;

                    if isequal (app.InputSetLamp.Color,[0 1 0]) && isequal (app.Lamp.Color,[0 1 0]) && isequal (app.Lamp_2.Color,[0 1 0])
                        app.ConfirmSaveButton.Enable='on';
                    end
    
                end
            else
                fig3 = uifigure;
                uialert(fig3,'Wrong file or not selected','Error');
                app.Lamp_2.Color=[1 0 0];
                app.Lamp_3.Color=[1 0 0];
            end
            
        end
        
        function run_simulation_post_processing(app,val_cases,env,v_dc,attitude)

            switch val_cases
                case 1
                    app.WorstcasePanel_3.Title='Worst Case';
                    app.WorstcasePanel_3.Visible=1;
                    app.WorstcasePanel_4.Visible=0;
                case 2
                    app.WorstcasePanel_3.Title='Hot Case';
                    app.WorstcasePanel_4.Title='Cold Case';
                    app.WorstcasePanel_3.Visible=1;
                    app.WorstcasePanel_4.Visible=1;                    
            end

            % Node Trees
            tree=app.Tree;
            tree_2=app.Tree_2;
            if isempty(app.ext)==0
                tree.Children.delete;
                tree_2.Children.delete;
            end
            name_face = {'Face_YM_1_','Face_XP_2_','Face_YP_3_','Face_XM_4_','Face_ZM_5_','Face_ZP_6_'};
            for i = 1:1:6
                app.ext(i).face = uitreenode(tree,'Text',name_face{i},'NodeData',[]);
                if isequal(val_cases,2)
                    app.ext(i).face_c = uitreenode(tree_2,'Text',name_face{i},'NodeData',[]);
                end
            end
            
            N_item = 1 + app.sat.geom.Nsp + app.sat.geom.Nb + app.sat.geom.NP + app.sat.geom.Nc;
            for i=(1 + 1):1:N_item
                %txt=strcat("IntFace_",num2str(i));
                txt=app.name_item(i);
                app.int(i).face=uitreenode(tree,'Text',txt,'NodeData',[]);
                if isequal(val_cases,2)
                    app.int(i).face_c=uitreenode(tree_2,'Text',txt,'NodeData',[]);
                end
            end

            N_str_face = length(app.sat.geom.ext.face); % number of faces of the structure (equal to 6)
            ind_coord_ext(1:N_str_face)=1;
            ind_coord_int(1:N_item)=1;
            for i=1:app.sat.node.total_node
                for face_i=1:1:N_str_face % 
                    if sum(ismember(app.sat.node.globe(i).face,face_i))==1
                        txt_=strcat("Node",num2str(i));
                        p1 = uitreenode(app.ext(face_i).face,'Text',txt_,'NodeData',app.sat.node.globe(i).Temperature_t);
                        app.Res_ext.face(face_i).coord(ind_coord_ext(face_i),:)=app.sat.node.globe(i).node;
                        app.Res_ext.face(face_i).temp(ind_coord_ext(face_i),:)=app.sat.node.globe(i).Temperature_t';
                        if isequal(val_cases,2)
                            p1 = uitreenode(app.ext(face_i).face_c,'Text',txt_,'NodeData',app.sat.node.globe(i).Temperature_t_cold);
                            app.Res_ext_c.face(face_i).coord(ind_coord_ext(face_i),:)=app.sat.node.globe(i).node;
                            app.Res_ext_c.face(face_i).temp(ind_coord_ext(face_i),:)=app.sat.node.globe(i).Temperature_t_cold';
                        end
                        ind_coord_ext(face_i)=ind_coord_ext(face_i)+1;
                    end
                end
                for j=(1 + 1):1:N_item % starting from boards item (internal items)
                    if sum(ismember(app.sat.node.globe(i).ID_item,j))==1
                        txt_=strcat("Node",num2str(i));
                        p1 = uitreenode(app.int(j).face,'Text',txt_,'NodeData',app.sat.node.globe(i).Temperature_t);
                        app.Res_int.face(j).coord(ind_coord_int(j),:)=app.sat.node.globe(i).node;
                        app.Res_int.face(j).temp(ind_coord_int(j),:)=app.sat.node.globe(i).Temperature_t';
                        if isequal(val_cases,2)
                            p1 = uitreenode(app.int(j).face_c,'Text',txt_,'NodeData',app.sat.node.globe(i).Temperature_t_cold);
                            app.Res_int_c.face(j).coord(ind_coord_int(j),:)=app.sat.node.globe(i).node;
                            app.Res_int_c.face(j).temp(ind_coord_int(j),:)=app.sat.node.globe(i).Temperature_t_cold';
                        end
                        ind_coord_int(j)=ind_coord_int(j)+1;
                    end
                end
            end

            % app.Tree.Visible='on';
            app.PlotTemperatureButton.Visible='on';
            app.UIAxes4.Visible='on';

            % Heat Map
            app.ix=round(length(app.sat.node.analysis.time_vect)/2); % removed second round()
            [app.handles, ~] = plot_GMM4_heatmap(app.sat,app.UIAxes6,'External',app.ix,1);
            if val_cases == 2
                [app.handles_c, ~] = plot_GMM4_heatmap(app.sat,app.UIAxes6_2,'External',app.ix,2);
            end

            % Plot Mundi            
            [app.mundi_pl]=plot_mundi(app.sat.node.analysis.Orbit_data,env,2,1,app.UIAxes5,app.sat.node.analysis.sun.vect,app.sat,v_dc,attitude,1);
            % Sliders
            app.TimeminSlider.MajorTicks=[0 app.sat.node.analysis.time_vect(end)/2/60 app.sat.node.analysis.time_vect(end)/60];
            app.TimeminSlider.Limits=[0 app.sat.node.analysis.time_vect(end)/60];
            app.TimeminSlider.Value=app.sat.node.analysis.time_vect(app.ix)/60; %set time slider
            app.TimesEditField.Value=app.sat.node.analysis.time_vect(app.ix); %report Time value in sec
            app.TimeminSlider.MinorTicks=linspace(0, app.sat.node.analysis.time_vect(end)/60,round((app.sat.node.analysis.time_vect(end)/600)));%step of 10 min
            app.SatellitepositionPanel.Visible='on';
            app.HeatMapTemperatureCPanel.Visible='on';
            app.TimeControlPanel.Visible='on';
            app.TrasparencyButtonGroup.Visible='on';
            if isequal(val_cases,2)
                app.HeatMapTab.Title='Heat Map-Hot Case';
                app.HeatMapTab_2.Title='Heat Map-Cold Case';
                % Plot Mundi
                % [app.mundi_pl_c]=plot_mundi(app.sat.node.analysis.Orbit_data,env,2,1,app.UIAxes5_2,app.sat.node.analysis.sun.vect);
                [app.mundi_pl_c]=plot_mundi(app.sat.node.analysis.Orbit_data,env,2,1,app.UIAxes5_2,app.sat.node.analysis.sun.vect,app.sat,v_dc,attitude,2);
                % Sliders
                app.TimeminSlider_2.MajorTicks=[0 app.sat.node.analysis.time_vect(end)/2/60 app.sat.node.analysis.time_vect(end)/60];
                app.TimeminSlider_2.Limits=[0 app.sat.node.analysis.time_vect(end)/60];
                app.TimeminSlider_2.Value=app.sat.node.analysis.time_vect(app.ix)/60; %set time slider
                app.TimesEditField_2.Value=app.sat.node.analysis.time_vect(app.ix); %report Time value in sec
                app.TimeminSlider_2.MinorTicks=linspace(0, app.sat.node.analysis.time_vect(end)/60,round((app.sat.node.analysis.time_vect(end)/600)));%step of 10 min
                app.SatellitepositionPanel_2.Visible='on';
                app.HeatMapTemperatureCPanel_2.Visible='on';
                app.TimeControlPanel_2.Visible='on';
                app.TrasparencyButtonGroup_2.Visible='on';
            end
            
            %% Design Optimization Tab

            app.OpticalpropertiesPanel_2.Visible=1;
            app.ConductancelinksThermalstrapsPanel.Visible=1;
            app.HeatersPanel.Visible=1;
            app.OptimizationalgorithmsettingPanel.Visible=1;
            app.ConfirmStartOptimizationButton.Visible=1;
            app.DecisionmakeralgorithmsettingPanel.Visible=1;
            app.Lamp_7.Visible=1;
            % app.OptimizationMenu.Enable=1; % load menu optimization

            if val_cases == 2
                app.ItemwithheaterPanel_2.Visible=1;
                app.UITable10_2.Visible=0;
                app.ItemwithheaterPanel_2.Title='Item with heater (cold case)';
                app.ItemwithheaterPanel.Title='Item with heater (hot case)';
            else
                app.ItemwithheaterPanel_2.Visible=0;
                app.UITable10_2.Visible=0;
                app.ItemwithheaterPanel_2.Title='';
                app.ItemwithheaterPanel.Title='Item with heater';
            end
            
            % optical properties tree
            tree_node_del = app.Tree2.Children;
            tree_node_del.delete; % deleting childrens to avoid duplicate nodes
            name_prop = {'Aplha', 'Eps Internal', 'Eps External'};
            name_face_optical = {'Face Y- (1)','Face X+ (2)','Face Y+ (3)','Face X- (4)','Face Z- (5)','Face Z+ (6)'};
            for i = 1:1:length(name_prop)
                temp_tree_node = uitreenode(app.Tree2,'Text',name_prop{i},'NodeData',[]);
                for j = 1:1:length(name_face_optical)
                    temp_node = uitreenode(temp_tree_node,'Text',name_face_optical{j},'NodeData',(i-1)*length(name_face_optical) + j); % second layer of tree nodes
                end
            end

            % item trees for conductance links and heaters
            name_item_tree = [app.name_item];
            item_trees = {app.Tree3, app.Tree3_2, app.Tree3_3, app.Tree3_4};
            for i = 1:1:length(item_trees)
                current_tree = item_trees{i};
                tree_node_del = current_tree.Children;
                tree_node_del.delete; % deleting childrens to avoid duplicate nodes
                for j = 1:1:N_item
                    temp_node = uitreenode(current_tree,'Text',"(" + string(j) + ") " + name_item_tree(j),'NodeData',j);     
                end
            end

            % decision maker table
            objective_names = ["Temperature gap - Hot/Default case";"Temperature gap - Cold case";"Heater power - Hot/Default case";"Heater power - Cold case";"Conductance link material volume"];
            tdata=table(objective_names, ones(length(objective_names),1),'VariableNames',{'Objective','Relative_weight'});
            app.UITable13.Data = tdata;

            %% MCRT Reflection Optimization Tab

            app.RunSimulationButtonRef.Visible=1;
            app.RunSimulationButtonRef.Enable=1;
            app.PreparationLabelRef.Visible=1;
            app.LampRef.Visible=1;
            app.EditField.Visible=1;
            app.EditFieldLabel.Visible=1;

            
        end
        
        function disable_confirm_save(app)

            app.ConfirmSaveButton.Enable = "off"; % ConfirmSaveButton            
            app.RunSimulationButton.Enable = "off"; % RunSimulationButton
            app.Lamp_3.Color=[1 0 0]; % ready to run lamp

            %% Res struct
            app.Res_ext=[];
            app.Res_ext_c=[];
            app.Res_int=[];
            app.Res_int_c=[];

            %% Optimization tab
            app.OpticalpropertiesPanel_2.Visible=0;
            app.ConductancelinksThermalstrapsPanel.Visible=0;
            app.HeatersPanel.Visible=0;
            app.OptimizationalgorithmsettingPanel.Visible=0;
            app.ConfirmStartOptimizationButton.Visible=0;
            app.ConfirmStartOptimizationButton.Text="Confirm & Start Optimization";
            app.DecisionmakeralgorithmsettingPanel.Visible=0;
            app.Lamp_7.Visible=0;
            % app.OptimizationMenu.Enable=0; % load menu optimization
            app.UIAxes8.Visible=0;
            cla(app.UIAxes8);
            app.Lamp_5.Color = [1 0 0];
            app.Lamp_6.Color = [1 0 0];
            app.Lamp_7.Color = [1 0 0];
            % Results
            app.AllsolutionsPanel.Visible = 0;
            cla(app.UIAxes9)
            app.UIAxes9.Visible = 0;
            app.ExportAllsolutionstabulardatatospreadsheetButton.Visible=0;            
            % Robustness
            app.RobustnessanalysissettingsPanel.Visible=0;
            app.Lamp_8.Color = [1 0 0]; 
            app.StartRobustnessAnalysisButton.Text="Start Robustness Analysis";
            cla(app.UIAxes10)
            app.UIAxes10.Visible=0;
            cla(app.UIAxes11)
            app.UIAxes11.Visible=0;

            %% Run and graph tab
            app.WorstcasePanel_3.Visible=0;
            app.WorstcasePanel_4.Visible=0;                      
            app.PlotTemperatureButton.Visible="off";
            app.UIAxes4.Visible="off";
            cla(app.UIAxes4)
            app.Lamp_4.Visible="off";
            app.PreparationLabel.Visible="off";
            % - hot/default case
            app.SatellitepositionPanel.Visible="off";
            app.HeatMapTemperatureCPanel.Visible="off";
            app.TimeControlPanel.Visible="off";
            app.TrasparencyButtonGroup.Visible="off";
            % - cold case
            app.SatellitepositionPanel_2.Visible="off";
            app.HeatMapTemperatureCPanel_2.Visible="off";
            app.TimeControlPanel_2.Visible="off";
            app.TrasparencyButtonGroup_2.Visible="off";

            %% MCRT Reflection
            app.RunSimulationButtonRef.Visible=1;
            app.RunSimulationButtonRef.Enable=0;
            app.RunSimulationButtonRef.Text="Run Simulation";
            app.PreparationLabelRef.Visible=0;
            app.LampRef.Visible=0;
            app.EditField.Visible=0;
            app.EditFieldLabel.Visible=0;
            cla(app.UIAxesRef_1)
            app.UIAxesRef_1.Visible=0;
            app.WorstcasePanelRef_1.Visible=0;
            app.WorstcasePanelRef_2.Visible=0;
            app.PlotTemperatureButtonRef_1.Visible=0;
            % - hot/default case
            app.SatellitepositionPanelRef_1.Visible="off";
            app.HeatMapTemperatureCPanelRef_1.Visible="off";
            app.TimeControlPanelRef_1.Visible="off";
            app.TrasparencyButtonGroupRef_1.Visible="off";
            % - cold case
            app.SatellitepositionPanelRef_2.Visible="off";
            app.HeatMapTemperatureCPanelRef_2.Visible="off";
            app.TimeControlPanelRef_2.Visible="off";
            app.TrasparencyButtonGroupRef_2.Visible="off";

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % structure table 1 (geometry)
            n_item = 1; % there can only be 1 "box-like" structure
            colorname=repmat(["y";"m";"c";"r";"g";"b";"w";"k"],n_item,1);
            item_name=repmat("Structure",n_item,1);
            cnames = categorical(colorname);
            id_item=linspace(1,n_item,n_item)';
            nnodes=ones(n_item,3)*3;
            centrecoord=ones(n_item,3)*50;
            size_item=ones(n_item,3)*100;
            tdata=table(id_item,nnodes(:,1),nnodes(:,2),nnodes(:,3),centrecoord(:,1),...
                centrecoord(:,2),centrecoord(:,3),size_item(:,1),size_item(:,2),size_item(:,3)...
                ,cnames(1:n_item,1),item_name,...
                'VariableNames',{'Item #','Nx','Ny','Nz','X_centre',...
                'Y_centre','Z_centre','Lx','Ly','Lz','Color','Structure_name'});
            app.UITable1_ex.Data=tdata;

            % structure table 2 (properties)
            Datainit_2=zeros(6,11);
            app.UITable2_ex.Data=Datainit_2;
            app.UITable2_ex.Data(:,1)=linspace(1,6,6);
            app.UITable2_ex.Data(:,2)=293.15;
            app.UITable2_ex.Data(:,3)=0.88;
            app.UITable2_ex.Data(:,4)=0.88;
            app.UITable2_ex.Data(:,5)=0.88;
            app.UITable2_ex.Data(:,6)=173;
            app.UITable2_ex.Data(:,7)=2810;
            app.UITable2_ex.Data(:,8)=900;
            app.UITable2_ex.Data(:,9)=4;
            app.UITable2_ex.Data(:,10)=-40;
            app.UITable2_ex.Data(:,11)=50;

            % other items not present
            app.sat.geom.Nsp=0;
            app.sat.geom.Nb=0;
            app.sat.geom.NP=0;
            app.sat.geom.Nc=0;

        end

        % Value changed function: CentralBodyDropDown
        function CentralBodyDropDownValueChanged(app, event)
            value = app.CentralBodyDropDown.Value;
            addpath Database\
            load planet.mat planet
            index=0;
            for i=1:length(planet)
                if strcmp(planet(i).name,value)
                    index=i;
                elseif strcmp('user',value)
                    index=0;
                end
            end
            if index>0
                app.SolarConstEditField.Value=planet(index).solconst;
                app.AlbedoCoefEditField.Value=planet(index).albedo;
                app.PlanetRadiusEditField.Value=planet(index).radius;
                app.PlanetIRfluxEditField.Value=planet(index).IR;
                app.GravitationalConstantEditField.Value=planet(index).grav;
                app.SolarConstEditField.Editable='on';
                app.AlbedoCoefEditField.Editable='on';
                app.PlanetRadiusEditField.Editable='off';
                app.PlanetIRfluxEditField.Editable='on';
                app.GravitationalConstantEditField.Editable='off';
            else
                app.SolarConstEditField.Editable='on';
                app.AlbedoCoefEditField.Editable='on';
                app.PlanetRadiusEditField.Editable='on';
                app.PlanetIRfluxEditField.Editable='on';
                app.GravitationalConstantEditField.Editable='on';
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Classic')
                app.AltitudeEditField.Enable='off';
                app.BetaAngleEditField.Enable='off';
                app.KmLabel_3.Enable='off';
                app.degLabel_4.Enable='off';
                app.EccentricityEditField.Enable='on';
                app.InclinationEditField.Enable='on';
                app.ArgumentperiapsidEditField.Enable='on';
                app.SMAEditField.Enable='on';
                app.RAANEditField.Enable='on';
                app.Label_2.Enable='on';
                app.KmLabel_2.Enable='on';
                app.degLabel.Enable='on';
                app.degLabel_2.Enable='on';
                app.degLabel_3.Enable='on';
            else
                app.AltitudeEditField.Enable='on';
                app.BetaAngleEditField.Enable='on';
                app.KmLabel_3.Enable='on';
                app.degLabel_4.Enable='on';
                app.EccentricityEditField.Enable='off';
                app.InclinationEditField.Enable='off';
                app.ArgumentperiapsidEditField.Enable='off';
                app.SMAEditField.Enable='off';
                app.RAANEditField.Enable='off';
                app.Label_2.Enable='off';
                app.KmLabel_2.Enable='off';
                app.degLabel.Enable='off';
                app.degLabel_2.Enable='off';
                app.degLabel_3.Enable='off';
            end
            
        end

        % Button pushed function: GenerateplotandSaveButton
        function GenerateplotandSaveButtonPushed(app, event)
            addpath Function\
            addpath texture\
            %environment struct
            env=struct;
            env.centralbody=app.CentralBodyDropDown.Value;
            env.c_s=app.SolarConstEditField.Value;                                             %Solar flux [W/m^2]
            env.c_ir=app.PlanetIRfluxEditField.Value;                                          %Ir planet flux [W/m^2]
            env.c_a=app.AlbedoCoefEditField.Value;                                             %Albedo coefficient []
            env.Rp=app.PlanetRadiusEditField.Value;                                            %Planet radius [Km]
            env.mu=app.GravitationalConstantEditField.Value;                                   %Gravitational constant [Km^3/s^2]
            %orbit struct
            orbit=struct;
            orbit.par_orb=app.Switch.Value;
            if strcmp(orbit.par_orb,'Basic')
                orbit.z=app.AltitudeEditField.Value;                                       %[Km] quota [km]
                orbit.beta_angle=app.BetaAngleEditField.Value;                             %[deg] Beta angle
                %orbit.inclination=beta_angle;
            elseif strcmp(orbit.par_orb,'Classic')
                orbit.eccentricity=app.EccentricityEditField.Value;
                orbit.SA=app.SMAEditField.Value;
                orbit.inclination=app.InclinationEditField.Value;
                orbit.LAN=app.RAANEditField.Value;
                orbit.AP=app.ArgumentperiapsidEditField.Value;
            end
            %sim data struct
            sim_data=struct;
            lll=datevec(app.SimulationdateDatePicker.Value);
            sim_data.month=lll(2);
            sim_data.day=lll(3);
            sim_data.year=lll(1);
            sim_data.ethr=app.HourdecimalEditField.Value;
            sim_data.etmin=0;
            sim_data.etsec=0;
            sim_data.day = sim_data.day + sim_data.ethr / 24 + sim_data.etmin / 1440 ...
                + sim_data.etsec / 86400;
            sim_data.jdat = julian(sim_data.month, sim_data.day, sim_data.year);
            sim_data.n_orbit=app.OrbitsEditField.Value;
            sim_data.step=app.TimeStepEditField.Value;
            [r_dc,period,rasc, decl, rsun, sun_vers,v_dc] =Orbit_analysis(env,orbit,sim_data);

            %attitude
            attitude=struct;
            value = app.CasestudiesSwitch.Value;
            val=str2double(value);
            value_sp = app.SpinCheckBox.Value;
            
            switch val
                case 1
                    if isequal(value_sp,1)
                        attitude.info='spin';
                    else
                        
                        if strcmp(app.PointingDropDown.Value,app.PointingDropDown_2.Value)
                            fig = uifigure;
                            uialert(fig,'Pointing configuration not valid (pointing1 ==pointing2)','Error');
                        elseif strcmp(app.FaceDropDown.Value,app.FaceDropDown_2.Value)
                            fig2 = uifigure;
                            uialert(fig2,'Pointing configuration not valid (Face1 ==Face2)','Error');
                        else
                            if strcmp(app.PointingDropDown.Value,'Nadir')
                                attitude.info1='Nadir';
                                if strcmp(app.FaceDropDown.Value,'+X') && strcmp(app.FaceDropDown_2.Value,'-X')==0
                                    attitude.nadir=2;
                                elseif strcmp(app.FaceDropDown.Value,'-X') && strcmp(app.FaceDropDown_2.Value,'+X')==0
                                    attitude.nadir=4;
                                elseif strcmp(app.FaceDropDown.Value,'+Y') && strcmp(app.FaceDropDown_2.Value,'-Y')==0
                                    attitude.nadir=3;
                                elseif strcmp(app.FaceDropDown.Value,'-Y') && strcmp(app.FaceDropDown_2.Value,'+Y')==0
                                    attitude.nadir=1;
                                elseif strcmp(app.FaceDropDown.Value,'+Z') && strcmp(app.FaceDropDown_2.Value,'-Z')==0
                                    attitude.nadir=6;
                                elseif strcmp(app.FaceDropDown.Value,'-Z') && strcmp(app.FaceDropDown_2.Value,'+Z')==0
                                    attitude.nadir=5;
                                else
                                    fig3 = uifigure;
                                    uialert(fig3,'Pointing configuration not valid','Error');
                                end
                            elseif strcmp(app.PointingDropDown.Value,'Velocity')
                                attitude.info1='Velocity';
                                if strcmp(app.FaceDropDown.Value,'+X') && strcmp(app.FaceDropDown_2.Value,'-X')==0
                                    attitude.vel=2;
                                elseif strcmp(app.FaceDropDown.Value,'-X') && strcmp(app.FaceDropDown_2.Value,'+X')==0
                                    attitude.vel=4;
                                elseif strcmp(app.FaceDropDown.Value,'+Y') && strcmp(app.FaceDropDown_2.Value,'-Y')==0
                                    attitude.vel=3;
                                elseif strcmp(app.FaceDropDown.Value,'-Y') && strcmp(app.FaceDropDown_2.Value,'+Y')==0
                                    attitude.vel=1;
                                elseif strcmp(app.FaceDropDown.Value,'+Z') && strcmp(app.FaceDropDown_2.Value,'-Z')==0
                                    attitude.vel=6;
                                elseif strcmp(app.FaceDropDown.Value,'-Z') && strcmp(app.FaceDropDown_2.Value,'+Z')==0
                                    attitude.vel=5;
                                else
                                    fig3 = uifigure;
                                    uialert(fig3,'Pointing configuration not valid','Error');
                                end
                            end
                            
                            if strcmp(app.PointingDropDown_2.Value,'Nadir')
                                attitude.info2='Nadir';
                                if strcmp(app.FaceDropDown_2.Value,'+X') && strcmp(app.FaceDropDown.Value,'-X')==0
                                    attitude.nadir=2;
                                elseif strcmp(app.FaceDropDown_2.Value,'-X') && strcmp(app.FaceDropDown.Value,'+X')==0
                                    attitude.nadir=4;
                                elseif strcmp(app.FaceDropDown_2.Value,'+Y') && strcmp(app.FaceDropDown.Value,'-Y')==0
                                    attitude.nadir=3;
                                elseif strcmp(app.FaceDropDown_2.Value,'-Y') && strcmp(app.FaceDropDown.Value,'+Y')==0
                                    attitude.nadir=1;
                                elseif strcmp(app.FaceDropDown_2.Value,'+Z') && strcmp(app.FaceDropDown.Value,'-Z')==0
                                    attitude.nadir=6;
                                elseif strcmp(app.FaceDropDown_2.Value,'-Z') && strcmp(app.FaceDropDown.Value,'+Z')==0
                                    attitude.nadir=5;
                                else
                                    fig3 = uifigure;
                                    uialert(fig3,'Pointing configuration not valid','Error');
                                end
                            elseif strcmp(app.PointingDropDown_2.Value,'Velocity')
                                attitude.info2='Velocity';
                                if strcmp(app.FaceDropDown_2.Value,'+X') && strcmp(app.FaceDropDown.Value,'-X')==0
                                    attitude.vel=2;
                                elseif strcmp(app.FaceDropDown_2.Value,'-X') && strcmp(app.FaceDropDown.Value,'+X')==0
                                    attitude.vel=4;
                                elseif strcmp(app.FaceDropDown_2.Value,'+Y') && strcmp(app.FaceDropDown.Value,'-Y')==0
                                    attitude.vel=3;
                                elseif strcmp(app.FaceDropDown_2.Value,'-Y') && strcmp(app.FaceDropDown.Value,'+Y')==0
                                    attitude.vel=1;
                                elseif strcmp(app.FaceDropDown_2.Value,'+Z') && strcmp(app.FaceDropDown.Value,'-Z')==0
                                    attitude.vel=6;
                                elseif strcmp(app.FaceDropDown_2.Value,'-Z') && strcmp(app.FaceDropDown.Value,'+Z')==0
                                    attitude.vel=5;
                                else
                                    fig3 = uifigure;
                                    uialert(fig3,'Pointing configuration not valid','Error');
                                end
                            end
                        end
                    end
                case 2
                    value_sp_2=app.SpinCheckBox_2.Value;
                    if isequal(value_sp,1)
                        attitude.info='spin';
                    else
                        if strcmp(app.PointingDropDown.Value,app.PointingDropDown_2.Value)
                            fig = uifigure;
                            uialert(fig,'Pointing configuration not valid (pointing1 ==pointing2)','Error');
                        elseif strcmp(app.FaceDropDown.Value,app.FaceDropDown_2.Value)
                            fig2 = uifigure;
                            uialert(fig2,'Pointing configuration not valid (Face1 ==Face2)','Error');
                        else
                            if strcmp(app.PointingDropDown.Value,'Nadir')
                                attitude.info1='Nadir';
                                if strcmp(app.FaceDropDown.Value,'+X') && strcmp(app.FaceDropDown_2.Value,'-X')==0
                                    attitude.nadir=2;
                                elseif strcmp(app.FaceDropDown.Value,'-X') && strcmp(app.FaceDropDown_2.Value,'+X')==0
                                    attitude.nadir=4;
                                elseif strcmp(app.FaceDropDown.Value,'+Y') && strcmp(app.FaceDropDown_2.Value,'-Y')==0
                                    attitude.nadir=3;
                                elseif strcmp(app.FaceDropDown.Value,'-Y') && strcmp(app.FaceDropDown_2.Value,'+Y')==0
                                    attitude.nadir=1;
                                elseif strcmp(app.FaceDropDown.Value,'+Z') && strcmp(app.FaceDropDown_2.Value,'-Z')==0
                                    attitude.nadir=6;
                                elseif strcmp(app.FaceDropDown.Value,'-Z') && strcmp(app.FaceDropDown_2.Value,'+Z')==0
                                    attitude.nadir=5;
                                else
                                    fig3 = uifigure;
                                    uialert(fig3,'Pointing configuration not valid','Error');
                                end
                            elseif strcmp(app.PointingDropDown.Value,'Velocity')
                                attitude.info1='Velocity';
                                if strcmp(app.FaceDropDown.Value,'+X') && strcmp(app.FaceDropDown_2.Value,'-X')==0
                                    attitude.vel=2;
                                elseif strcmp(app.FaceDropDown.Value,'-X') && strcmp(app.FaceDropDown_2.Value,'+X')==0
                                    attitude.vel=4;
                                elseif strcmp(app.FaceDropDown.Value,'+Y') && strcmp(app.FaceDropDown_2.Value,'-Y')==0
                                    attitude.vel=3;
                                elseif strcmp(app.FaceDropDown.Value,'-Y') && strcmp(app.FaceDropDown_2.Value,'+Y')==0
                                    attitude.vel=1;
                                elseif strcmp(app.FaceDropDown.Value,'+Z') && strcmp(app.FaceDropDown_2.Value,'-Z')==0
                                    attitude.vel=6;
                                elseif strcmp(app.FaceDropDown.Value,'-Z') && strcmp(app.FaceDropDown_2.Value,'+Z')==0
                                    attitude.vel=5;
                                else
                                    fig3 = uifigure;
                                    uialert(fig3,'Pointing configuration not valid','Error');
                                end
                            end
                            
                            if strcmp(app.PointingDropDown_2.Value,'Nadir')
                                attitude.info2='Nadir';
                                if strcmp(app.FaceDropDown_2.Value,'+X') && strcmp(app.FaceDropDown.Value,'-X')==0
                                    attitude.nadir=2;
                                elseif strcmp(app.FaceDropDown_2.Value,'-X') && strcmp(app.FaceDropDown.Value,'+X')==0
                                    attitude.nadir=4;
                                elseif strcmp(app.FaceDropDown_2.Value,'+Y') && strcmp(app.FaceDropDown.Value,'-Y')==0
                                    attitude.nadir=3;
                                elseif strcmp(app.FaceDropDown_2.Value,'-Y') && strcmp(app.FaceDropDown.Value,'+Y')==0
                                    attitude.nadir=1;
                                elseif strcmp(app.FaceDropDown_2.Value,'+Z') && strcmp(app.FaceDropDown.Value,'-Z')==0
                                    attitude.nadir=6;
                                elseif strcmp(app.FaceDropDown_2.Value,'-Z') && strcmp(app.FaceDropDown.Value,'+Z')==0
                                    attitude.nadir=5;
                                else
                                    fig3 = uifigure;
                                    uialert(fig3,'Pointing configuration not valid','Error');
                                end
                            elseif strcmp(app.PointingDropDown_2.Value,'Velocity')
                                attitude.info2='Velocity';
                                if strcmp(app.FaceDropDown_2.Value,'+X') && strcmp(app.FaceDropDown.Value,'-X')==0
                                    attitude.vel=2;
                                elseif strcmp(app.FaceDropDown_2.Value,'-X') && strcmp(app.FaceDropDown.Value,'+X')==0
                                    attitude.vel=4;
                                elseif strcmp(app.FaceDropDown_2.Value,'+Y') && strcmp(app.FaceDropDown.Value,'-Y')==0
                                    attitude.vel=3;
                                elseif strcmp(app.FaceDropDown_2.Value,'-Y') && strcmp(app.FaceDropDown.Value,'+Y')==0
                                    attitude.vel=1;
                                elseif strcmp(app.FaceDropDown_2.Value,'+Z') && strcmp(app.FaceDropDown.Value,'-Z')==0
                                    attitude.vel=6;
                                elseif strcmp(app.FaceDropDown_2.Value,'-Z') && strcmp(app.FaceDropDown.Value,'+Z')==0
                                    attitude.vel=5;
                                else
                                    fig3 = uifigure;
                                    uialert(fig3,'Pointing configuration not valid','Error');
                                end
                            end
                        end
                    end
                    
                    if isequal(value_sp_2,1)
                        attitude.info_cold='spin';
                    else
                        if strcmp(app.PointingDropDown_3.Value,app.PointingDropDown_4.Value)
                            fig = uifigure;
                            uialert(fig,'Pointing configuration not valid (pointing1 ==pointing2)','Error');
                        elseif strcmp(app.FaceDropDown_3.Value,app.FaceDropDown_4.Value)
                            fig2 = uifigure;
                            uialert(fig2,'Pointing configuration not valid (Face1 ==Face2)','Error');
                        else
                            if strcmp(app.PointingDropDown_3.Value,'Nadir')
                                attitude.info1_cold='Nadir';
                                if strcmp(app.FaceDropDown_3.Value,'+X') && strcmp(app.FaceDropDown_4.Value,'-X')==0
                                    attitude.nadir_cold=2;
                                elseif strcmp(app.FaceDropDown_3.Value,'-X') && strcmp(app.FaceDropDown_4.Value,'+X')==0
                                    attitude.nadir_cold=4;
                                elseif strcmp(app.FaceDropDown_3.Value,'+Y') && strcmp(app.FaceDropDown_4.Value,'-Y')==0
                                    attitude.nadir_cold=3;
                                elseif strcmp(app.FaceDropDown_3.Value,'-Y') && strcmp(app.FaceDropDown_4.Value,'+Y')==0
                                    attitude.nadir_cold=1;
                                elseif strcmp(app.FaceDropDown_3.Value,'+Z') && strcmp(app.FaceDropDown_4.Value,'-Z')==0
                                    attitude.nadir_cold=6;
                                elseif strcmp(app.FaceDropDown_3.Value,'-Z') && strcmp(app.FaceDropDown_4.Value,'+Z')==0
                                    attitude.nadir_cold=5;
                                else
                                    fig3 = uifigure;
                                    uialert(fig3,'Pointing configuration not valid','Error');
                                end
                            elseif strcmp(app.PointingDropDown_3.Value,'Velocity')
                                attitude.info1_cold='Velocity';
                                if strcmp(app.FaceDropDown_3.Value,'+X') && strcmp(app.FaceDropDown_4.Value,'-X')==0
                                    attitude.vel_cold=2;
                                elseif strcmp(app.FaceDropDown_3.Value,'-X') && strcmp(app.FaceDropDown_4.Value,'+X')==0
                                    attitude.vel_cold=4;
                                elseif strcmp(app.FaceDropDown_3.Value,'+Y') && strcmp(app.FaceDropDown_4.Value,'-Y')==0
                                    attitude.vel_cold=3;
                                elseif strcmp(app.FaceDropDown_3.Value,'-Y') && strcmp(app.FaceDropDown_4.Value,'+Y')==0
                                    attitude.vel_cold=1;
                                elseif strcmp(app.FaceDropDown_3.Value,'+Z') && strcmp(app.FaceDropDown_4.Value,'-Z')==0
                                    attitude.vel_cold=6;
                                elseif strcmp(app.FaceDropDown_3.Value,'-Z') && strcmp(app.FaceDropDown_4.Value,'+Z')==0
                                    attitude.vel_cold=5;
                                else
                                    fig3 = uifigure;
                                    uialert(fig3,'Pointing configuration not valid','Error');
                                end
                            end
                            
                            if strcmp(app.PointingDropDown_4.Value,'Nadir')
                                attitude.info2_cold='Nadir';
                                if strcmp(app.FaceDropDown_4.Value,'+X') && strcmp(app.FaceDropDown_3.Value,'-X')==0
                                    attitude.nadir_cold=2;
                                elseif strcmp(app.FaceDropDown_4.Value,'-X') && strcmp(app.FaceDropDown_3.Value,'+X')==0
                                    attitude.nadir_cold=4;
                                elseif strcmp(app.FaceDropDown_4.Value,'+Y') && strcmp(app.FaceDropDown_3.Value,'-Y')==0
                                    attitude.nadir_cold=3;
                                elseif strcmp(app.FaceDropDown_4.Value,'-Y') && strcmp(app.FaceDropDown_3.Value,'+Y')==0
                                    attitude.nadir_cold=1;
                                elseif strcmp(app.FaceDropDown_4.Value,'+Z') && strcmp(app.FaceDropDown_3.Value,'-Z')==0
                                    attitude.nadir_cold=6;
                                elseif strcmp(app.FaceDropDown_4.Value,'-Z') && strcmp(app.FaceDropDown_3.Value,'+Z')==0
                                    attitude.nadir_cold=5;
                                else
                                    fig3 = uifigure;
                                    uialert(fig3,'Pointing configuration not valid','Error');
                                end
                            elseif strcmp(app.PointingDropDown_4.Value,'Velocity')
                                attitude.info2_cold='Velocity';
                                if strcmp(app.FaceDropDown_4.Value,'+X') && strcmp(app.FaceDropDown_3.Value,'-X')==0
                                    attitude.vel_cold=2;
                                elseif strcmp(app.FaceDropDown_4.Value,'-X') && strcmp(app.FaceDropDown_3.Value,'+X')==0
                                    attitude.vel_cold=4;
                                elseif strcmp(app.FaceDropDown_4.Value,'+Y') && strcmp(app.FaceDropDown_3.Value,'-Y')==0
                                    attitude.vel_cold=3;
                                elseif strcmp(app.FaceDropDown_4.Value,'-Y') && strcmp(app.FaceDropDown_3.Value,'+Y')==0
                                    attitude.vel_cold=1;
                                elseif strcmp(app.FaceDropDown_4.Value,'+Z') && strcmp(app.FaceDropDown_3.Value,'-Z')==0
                                    attitude.vel_cold=6;
                                elseif strcmp(app.FaceDropDown_4.Value,'-Z') && strcmp(app.FaceDropDown_3.Value,'+Z')==0
                                    attitude.vel_cold=5;
                                else
                                    fig3 = uifigure;
                                    uialert(fig3,'Pointing configuration not valid','Error');
                                end
                            end
                        end
                    end
            end
            val_cases=val;

            % Plot Mundi
            plot_mundi(r_dc,env,2,1,app.UIAxes,rsun) % without satellite orientation plot
            % [~]=plot_mundi(r_dc,env,2,1,app.UIAxes,rsun,app.sat,v_dc,attitude,val_cases);
            
            save 'environment.mat' orbit sim_data env r_dc period rasc decl rsun sun_vers attitude v_dc val_cases
            app.InputSetLamp.Color=[0 1 0];            
            T_str=tempo(period);
            set(app.OrbitalPeriodTextArea,'Value', sprintf('\nThe period of the orbit is %d s:\nDays=%f\nH=%f\nMin=%f\nSec=%f\n',period,T_str(1),T_str(2),T_str(3),T_str(4)))
           app.T_orbit = period;
        end

        % Value changed function: OrbitalPeriodTextArea
        function OrbitalPeriodTextAreaValueChanged(app, event)
            
            
        end

        % Button pushed function: GenerateTMMButton
        function GenerateTMMButtonPushed(app, event)

            generate_TMM(app)
            
        end

        % Button pushed function: ConfirmSaveButton
        function ConfirmSaveButtonPushed(app, event)
            addpath Function\
            load GMM_complete.mat

            disable_confirm_save(app)

            app.ConfirmSaveButton.Enable="on"; % ConfirmSaveButton
            app.Lamp_3.Color=[0 1 0];
            app.RunSimulationButton.Enable="on";

            app.sat=sat;
            value = app.CasestudiesSwitch.Value;
            val=str2double(value);
            diss_mat_tot=table2array(app.UITable3.Data(:,[1 3:end]));
            data_c=app.UITable5.Data;
            [app.sat]=Q_dissipation3(app.sat,diss_mat_tot,1);
            diss_nod=app.UITable4.Data;
            if isequal(val,2)
                [app.sat]=Q_dissipation3(app.sat,diss_mat_tot,2);
            end
            if any(diss_nod)
                for i=1:length(diss_nod(:,1))
                    if isequal(val,2)
                        if diss_nod(i,2)>0
                            app.sat.node.Q_diss(diss_nod(i,1),1)=...
                                app.sat.node.Q_diss(diss_nod(i,1),1)+diss_nod(i,2);
                        end
                        if diss_nod(i,3)>0
                            app.sat.node.Q_diss_cold(diss_nod(i,1),1)=...
                                app.sat.node.Q_diss_cold(diss_nod(i,1),1)+diss_nod(i,3);
                        end
                        
                    else
                        if diss_nod(i,2)>0
                            app.sat.node.Q_diss(diss_nod(i,1),1)=...
                                app.sat.node.Q_diss(diss_nod(i,1),1)+diss_nod(i,2);
                        end
                    end
                    
                end
            end
            sigma=5.67e-8;
            [G_c,G0_Irr,G_hc] = TMM2(app.sat,sigma,Vf_G,C,eps_for_Ge);
            if any(data_c)
                for i=1:length(data_c(:,1))
                    k_int=data_c(i,3);
                    a_int=data_c(i,4);
                    l_int=data_c(i,5)/2;
                    G_int=k_int*a_int/l_int;
                    G_c(data_c(i,1),data_c(i,2))=(2*1/G_int)^-1;
                    G_c(data_c(i,2),data_c(i,1))=G_c(data_c(i,1),data_c(i,2));
                end
            end
            sat=app.sat;
            val_cases=val;
            Table_Additional_Conduction = app.UITable5.Data;
            Table_item_dissipation = app.UITable3.Data;
            Table_nodal_dissipation = app.UITable4.Data;
            save TMM.mat G_c G0_Irr G_hc data_c diss_nod ...
                diss_mat_tot sat C Vf Vf_G val_cases eps_for_Ge ...
                Table_Additional_Conduction Table_item_dissipation Table_nodal_dissipation


            % again:
            app.ConfirmSaveButton.Enable="on"; % ConfirmSaveButton
            app.Lamp_3.Color=[0 1 0];
            app.RunSimulationButton.Enable="on";
            % app.OptimizationMenu.Enable=1; % load menu optimization
            %merge all input
            names =  app.name_item;
            clearvars -except val_cases names          
            load('environment.mat');
            load('GMM_complete.mat')
            load('TMM.mat');
            save all_input.mat            
            
        end

        % Button pushed function: Add_dissipationButton
        function Add_dissipationButtonPushed(app, event)
            m=zeros(1,size(app.UITable4.ColumnName,1));
            app.UITable4.Data(end+1,:)=m;
        end

        % Button pushed function: Del_dissipationButton
        function Del_dissipationButtonPushed(app, event)
            app.UITable4.Data(end,:) = [];
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            load GMM_complete.mat
            cla(app.UIAxes3);
            plot_GMM4(app.sat,app.UIAxes3);
            hold(app.UIAxes3,'on');
            diss_mat_tot=table2array(app.UITable3.Data(:,[1 3:end]));
            [app.sat]=Q_dissipation3(app.sat,diss_mat_tot,1);
            diss_nod=app.UITable4.Data;
            for i=1:length(app.sat.node.Q_diss(:,1))
                if app.sat.node.Q_diss(i,1)>0
                    plot3(app.UIAxes3,app.sat.node.globe(i).node(1),...
                        app.sat.node.globe(i).node(2),app.sat.node.globe(i).node(3),...
                        'Color','r','Marker','o','MarkerSize',5,'LineWidth',3)
                end
            end
            if any(diss_nod)
                for i=1:length(diss_nod(:,1))
                    if diss_nod(i,2)>0
                        app.sat.node.Q_diss(diss_nod(i,1),1)=...
                            app.sat.node.Q_diss(diss_nod(i,1),1)+diss_nod(i,2);
                        plot3(app.UIAxes3,app.sat.node.globe(diss_nod(i,1)).node(1),...
                            app.sat.node.globe(diss_nod(i,1)).node(2),...
                            app.sat.node.globe(diss_nod(i,1)).node(3),...
                            'Color','r','Marker','<','MarkerSize',10,"LineWidth",3)
                    end
                end
            end
            hold(app.UIAxes3,'off');
        end

        % Button pushed function: Add_conductionButton
        function Add_conductionButtonPushed(app, event)
            app.UITable5.Data(end+1,:)=[0 0 0 0 0];
        end

        % Button pushed function: Del_conductionButton
        function Del_conductionButtonPushed(app, event)
            app.UITable5.Data(end,:) = [];
        end

        % Button pushed function: PreviewButton_2
        function PreviewButton_2Pushed(app, event)
            load GMM_complete.mat
            cla(app.UIAxes3);
            plot_GMM4(app.sat,app.UIAxes3);
            hold(app.UIAxes3,'on');
            data=app.UITable5.Data;
            for i=1:length(data(:,1))
                v=[app.sat.node.globe(data(i,1)).node' app.sat.node.globe(data(i,2)).node'];
                plot3(app.UIAxes3,v(1,:),v(2,:),v(3,:),'-o','linewidth',3,'color','b');
                %                 xyz = vertcat(sat.node.globe(data(i,1)).coord,sat.node.globe(data(i,2)).coord);
                %                 line(app.UIAxes3,'XData',xyz(:,1),'YData',xyz(:,2),'ZData',xyz(:,3))
            end
            hold(app.UIAxes3,'off');
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            addpath Function\
            load all_input.mat
            app.sat=sat;
            app.Lamp_4.Color=[1 0 0];
            %app.RunsimulationGauge.Visible='on';
            %app.RunsimulationGauge.Value=0;
            app.Lamp_4.Visible='on';
            app.PreparationLabel.Visible='on';
            pause(0.0001);
            sigma=5.67e-8;
            app.PreparationLabel.Text='Preparation (Environment analysis)';
            pause(0.001);

%             [app.sat a] = Environment_analysis(app.sat,r_dc,v_dc,attitude,env,sun_vers,rsun,1);

            [app.sat,a] = Environment_analysis3(app.sat,r_dc,v_dc,attitude,env,rsun,1);            
            if isequal(val_cases,2)
                app.PreparationLabel.Text='Preparation (Environment analysis-case:cold)';
                pause(0.00001)
                [app.sat,a] = Environment_analysis3(app.sat,r_dc,v_dc,attitude,env,rsun,2);
            end
            %app.RunsimulationGauge.Value=10;
            app.PreparationLabel.Text='Preparation (Q_source definition)';
            pause(0.001);
            Source_term=struct;
%             [Source_term,app.sat]=Q_source(Source_term,app.sat,env,r_dc,v_dc,attitude,rsun,a,1);
            [Source_term,app.sat]=Q_source3(Source_term,app.sat,env,r_dc,v_dc,attitude,rsun,a,1,Vf_G);
            if isequal(val_cases,2)
                app.PreparationLabel.Text='Preparation (Q_source definition-case:cold)';
                pause(0.00001)
                [Source_term,app.sat]=Q_source3(Source_term,app.sat,env,r_dc,v_dc,attitude,rsun,a,2,Vf_G);
            end

            Source_term.all.Qa=repmat(Source_term.Qa(1:end-1,:),sim_data.n_orbit,1);
            Source_term.all.Qs=repmat(Source_term.Qs(1:end-1,:),sim_data.n_orbit,1);
            Source_term.all.Qir=repmat(Source_term.Qir(1:end-1,:),sim_data.n_orbit,1);

            app.sat.node.analysis.all.epsAf_earth=...
                repmat(app.sat.node.analysis.epsAf_earth(:,1:end-1)',sim_data.n_orbit,1)';
            app.sat.node.analysis.all.epsAf_space=...
                repmat(app.sat.node.analysis.epsAf_space(:,1:end-1)',sim_data.n_orbit,1)';
            Q_0=Source_term.all.Qs+Source_term.all.Qa+...
                Source_term.all.Qir;
            Q0_st=diag(mean(Source_term.Qa+Source_term.Qir+Source_term.Qs))+...
                diag(app.sat.node.Q_diss);
            app.PreparationLabel.Text='Run Simulation...';
            pause(0.001);
            G_c=G_c-diag(sum(G_c,2));

            [T_st]=Steady_comp(app.sat,Q0_st,G_c,G0_Irr,1);
            T_0=T_st;
            dt=r_dc(2,4)-r_dc(1,4);
            Tempo=zeros((length(r_dc)-1)*sim_data.n_orbit,1);
            [T,Tempo]=transient(app.sat,T_0,dt,r_dc,sim_data,Tempo,G0_Irr,...
                G_c,G_hc,Q_0,1);
            T=T';
            T=T-273.15;
            if isequal(val_cases,2)
                Source_term.all.Qa_cold=repmat(Source_term.Qa_cold(1:end-1,:),sim_data.n_orbit,1);
                Source_term.all.Qs_cold=repmat(Source_term.Qs_cold(1:end-1,:),sim_data.n_orbit,1);
                Source_term.all.Qir_cold=repmat(Source_term.Qir_cold(1:end-1,:),sim_data.n_orbit,1);
                app.sat.node.analysis.all.epsAf_earth_cold=...
                    repmat(app.sat.node.analysis.epsAf_earth_cold(:,1:end-1)',sim_data.n_orbit,1)';
                app.sat.node.analysis.all.epsAf_space_cold=...
                    repmat(app.sat.node.analysis.epsAf_space_cold(:,1:end-1)',sim_data.n_orbit,1)';
                Q_0_cold=Source_term.all.Qs_cold+Source_term.all.Qa_cold+...
                    Source_term.all.Qir_cold;
                Q0_st_cold=diag(mean(Source_term.Qa_cold+Source_term.Qir_cold...
                    +Source_term.Qs_cold))+diag(app.sat.node.Q_diss_cold);
                [T_st_cold]=Steady_comp(app.sat,Q0_st_cold,G_c,G0_Irr,2);
                T_0=T_st_cold;
                [T_cold,Tempo]=transient(app.sat,T_0,dt,r_dc,sim_data,Tempo,G0_Irr,...
                    G_c,G_hc,Q_0_cold,2);
                T_cold=T_cold';
                T_cold=T_cold-273.15;
            end
            app.Lamp_4.Color=[0 1 0];
            app.PreparationLabel.Text='Simulation completed!';

            for i=1:app.sat.node.total_node
                app.sat.node.globe(i).Temperature_t=T(:,i);
                if isequal(val_cases,2)
                    app.sat.node.globe(i).Temperature_t_cold=T_cold(:,i);
                end
            end

            app.sat.node.analysis.Vf=Vf;
            app.sat.node.analysis.Vf_G=Vf_G;
            app.sat.node.analysis.Orbit_data=r_dc;
            app.sat.node.analysis.Source_term=Source_term;
            app.sat.node.analysis.period=r_dc(end,4);
            app.sat.node.analysis.ecl_day.ecl=r_dc((r_dc(:,5)==1),1:4);
            app.sat.node.analysis.ecl_day.day=r_dc((r_dc(:,5)==0),1:4);
            app.sat.node.analysis.sun.vect=rsun;
            app.sat.node.analysis.sun.vers=sun_vers;
            app.sat.node.analysis.time_vect=Tempo;
            app.sat.node.analysis.attitude_evo_angle=a;
            sat=app.sat;
            names = app.name_item;

            save transient_result.mat sat env orbit sim_data val_cases names r_dc v_dc attitude rsun Vf Vf_G G_c G_hc diss_mat_tot

            %% Plot and visualization
            
            run_simulation_post_processing(app,val_cases,env,v_dc,attitude)

        end

        % Menu selected function: EnvironmentMenu
        function EnvironmentMenuSelected(app, event)
            
            [baseFileName, folder] = uigetfile('*.mat');
            if baseFileName==0
                % user pressed cancel
                return
            end
            fullFileName = fullfile(folder, baseFileName);
            
            load_environment(app, fullFileName)
        end

        % Menu selected function: GMMMenu
        function GMMMenuSelected(app, event)
            
            [baseFileName, folder] = uigetfile('*.mat');
            if baseFileName==0
                % user pressed cancel
                return
            end
            fullFileName = fullfile(folder, baseFileName);

            load_GMM(app, fullFileName)           
        end

        % Menu selected function: TMMandDissipationMenu
        function TMMandDissipationMenuSelected(app, event)
            
            [baseFileName, folder] = uigetfile('*.mat');
            if baseFileName==0
                % user pressed cancel
                return
            end
            fullFileName = fullfile(folder, baseFileName);
            
            load_TMM_dissipation(app,fullFileName)
        end

        % Menu selected function: ResultsMenu
        function ResultsMenuSelected(app, event)
            
        end

        % Button pushed function: PlotTemperatureButton
        function PlotTemperatureButtonPushed(app, event)
            value = app.CasestudiesSwitch.Value;
            val_cases=str2double(value);
            selectedNode = app.Tree.SelectedNodes;
            gr=struct;
            face_pl=struct;
            tplot=[];
            name=[];
            for i=1:length(selectedNode)
                if isempty(selectedNode(i).NodeData)
                    for j=1:length(selectedNode(i).Children)
                        face_pl(i).gr(j).pl=plot(app.UIAxes4,app.sat.node.analysis.time_vect,selectedNode(i).Children(j).NodeData);
                        hold(app.UIAxes4,'on')
                        grid(app.UIAxes4,'on')
                        tplot=[tplot, face_pl(i).gr(j).pl];
                        name=[name, convertCharsToStrings(selectedNode(i).Children(j).Text)];
                    end
                else
                    gr(i).pl=plot(app.UIAxes4,app.sat.node.analysis.time_vect,selectedNode(i).NodeData);
                    hold(app.UIAxes4,'on')
                    grid(app.UIAxes4,'on')
                    tplot=[tplot, gr(i).pl];
                    name=[name, convertCharsToStrings(selectedNode(i).Text)];
                end
            end
            if isequal(val_cases,2)
                selectedNode_c=app.Tree_2.SelectedNodes;
                gr_c=struct;
                face_pl_c=struct;

                for i=1:length(selectedNode_c)
                    if isempty(selectedNode_c(i).NodeData)
                        for j=1:length(selectedNode_c(i).Children)
                            face_pl_c(i).gr(j).pl=plot(app.UIAxes4,app.sat.node.analysis.time_vect,selectedNode_c(i).Children(j).NodeData);
                            hold(app.UIAxes4,'on')
                            grid(app.UIAxes4,'on')
                            tplot=[tplot, face_pl_c(i).gr(j).pl];
                            name=[name, convertCharsToStrings(selectedNode_c(i).Children(j).Text)+" "+"cold"];
                        end
                    else
                        gr_c(i).pl=plot(app.UIAxes4,app.sat.node.analysis.time_vect,selectedNode_c(i).NodeData);
                        hold(app.UIAxes4,'on')
                        grid(app.UIAxes4,'on')
                        tplot=[tplot, gr_c(i).pl];
                        name=[name, convertCharsToStrings(selectedNode_c(i).Text)+" "+"cold"];
                    end
                end
            end
            legend(app.UIAxes4,tplot,name);
            hold(app.UIAxes4,'off')
        end

        % Value changed function: TimeminSlider
        function TimeminSliderValueChanged(app, event)
            value = app.TimeminSlider.Value;
            [~,app.ix]=min(abs(app.sat.node.analysis.time_vect-value*60));

            % Heat Map
            [app.handles, ~] = plot_GMM4_heatmap(app.sat,app.UIAxes6,app.TrasparencyButtonGroup.SelectedObject.Text,app.ix,1);

            app.TimesEditField.Value=app.sat.node.analysis.time_vect(app.ix,1);
            kk=app.TimesEditField.Value-floor((app.TimesEditField.Value)/app.sat.node.analysis.period)*app.sat.node.analysis.period;
            r=app.sat.node.analysis.Orbit_data;
            [~,kk]=min(abs(kk-r(:,4)));
            C1 = repmat([0,0,0],numel(r(r(:,5)==1,1)),1);
            C2 = repmat([0,0.70,1],numel(r(r(:,5)==0,1)),1);
            S1 = repmat(5,numel(r(r(:,5)==1,1)),1);
            S2 = repmat(5,numel(r(r(:,5)==0,1)),1);
            if isequal(r(kk,5),1)
                app.mundi_pl.ecl.CData=C1;
                app.mundi_pl.sun.CData=C2;
                app.mundi_pl.ecl.SizeData=S1;
                app.mundi_pl.sun.SizeData=S2;
                app.mundi_pl.ecl.CData(app.sat.node.analysis.ecl_day.ecl(:,4)==r(kk,4),:)=[0.86 0 0.78];
                app.mundi_pl.ecl.SizeData(1,app.sat.node.analysis.ecl_day.ecl(:,4)==r(kk,4))=100;                
            else
                app.mundi_pl.ecl.CData=C1;
                app.mundi_pl.sun.CData=C2;
                app.mundi_pl.ecl.SizeData=S1;
                app.mundi_pl.sun.SizeData=S2;
                app.mundi_pl.sun.CData(app.sat.node.analysis.ecl_day.day(:,4)==r(kk,4),:)=[0.86 0 0.78];
                app.mundi_pl.sun.SizeData(1,app.sat.node.analysis.ecl_day.day(:,4)==r(kk,4))=100;
            end
        end

        % Selection changed function: TrasparencyButtonGroup
        function TrasparencyButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrasparencyButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'External')
                for i=1:length(app.handles)
                    app.handles(i).FaceAlpha=1;
                end
            elseif strcmp(selectedButton.Text,'Both')
                for i=1:length(app.handles)
                    app.handles(i).FaceAlpha=0.3;
                end
            elseif strcmp(selectedButton.Text,'Internal')
                for i=1:length(app.handles)
                    app.handles(i).FaceAlpha=0;
                end
            end
        end

        % Menu selected function: AllInputMenu
        function AllInputMenuSelected(app, event)

            [baseFileName, folder] = uigetfile('*.mat');
            if baseFileName==0
                % user pressed cancel
                return
            end
            fullFileName = fullfile(folder, baseFileName);

            disable_confirm_save(app)

            % ENVIRONMENT
            load_environment(app,fullFileName)

            % GMM
            load_GMM(app,fullFileName)

            % TMM and dissipation
            load_TMM_dissipation(app,fullFileName)

            app.RunSimulationButton.Enable = "on"; % RunSimulationButton
            app.Lamp_3.Color=[0 1 0]; % ready to run lamp

            %% MCRT Reflection Optimization Tab

            app.RunSimulationButtonRef.Visible=1;
            app.RunSimulationButtonRef.Enable=1;
            app.PreparationLabelRef.Visible=1;
            app.LampRef.Visible=1;
            app.EditField.Visible=1;
            app.EditFieldLabel.Visible=1;
            
        end

        % Menu selected function: TransientMenu
        function TransientMenuSelected(app, event)
            [baseFileName, folder] = uigetfile('*.mat');
            if baseFileName==0
                % user pressed cancel
                return
            end
            fullFileName = fullfile(folder, baseFileName);
            load(fullFileName);

            addpath Function\
            addpath texture\
            if exist("sat","var") && isfield(sat.node,"analysis")
                app.sat = sat;
                app.name_item = names;                         
                run_simulation_post_processing(app,val_cases,env,v_dc,attitude)
                app.TabGroup.SelectedTab = app.AnalysisPostprocessingTab;
            else
                fig3 = uifigure;
                uialert(fig3,'Wrong file or not selected','Error');
                app.Lamp.Color=[1 0 0];
            end            

        end

        % Menu selected function: OptimizationMenu
        function OptimizationMenuSelected(app, event)
            [baseFileName, folder] = uigetfile('*.mat');
            if baseFileName==0
                % user pressed cancel
                return
            end
            fullFileName = fullfile(folder, baseFileName);
            data = load(fullFileName);
            fullFileName2 = fullfile(folder, 'input_rob.mat');
            data2 = load(fullFileName2);
            U=data2.U;
            alpha=data.alpha;
            epsint=data.epsint;
            epsext=data.epsext;
            x_epoch1=data.x_epoch1;
            rob_in=data.rob_in;
            idx=data.idx;
            %f_epoch1=data.f_epoch1;
            heat=data.heat;
            id_node_int=data.id_node_int;
            id_node_ext=data.id_node_ext;
            A_straps=data.A_straps;            
            app.sat=data.sat;
            app.PressStarttooptimiseLabel.Text='Optimization is completed';
            app.PressStarttooptimiseLamp.Color=[0, 1, 0];
            app.UITable6.Data(:,1)=idx;
            k=0;
            if isempty(nonzeros(heat.case1))
                check(7,1)=0;
            else
                check(7,1)=1;
            end
            if isempty(nonzeros(heat.case2))
                check(8,1)=0;
            else
                check(8,1)=1;
            end
            nstrap=size(A_straps,2)-sum(all(isnan(A_straps)));
            app.UITable6.ColumnName=cell(18+3*nstrap+...
                check(7,1)+check(8,1),1);
            app.UITable6.ColumnName{1}='ID';
            for i=1:6
                k=k+1;
                app.UITable6.ColumnName{1+k}=strcat(app.sat.geom.ext.face(i).name,' ','(abs)');
                app.UITable6.Data(:,1+k)=alpha(:,i);
                k=k+1;
                app.UITable6.ColumnName{1+k}=strcat(app.sat.geom.ext.face(i).name,' ','(eps_int)');
                app.UITable6.Data(:,1+k)=epsint(:,i);
                k=k+1;
                app.UITable6.ColumnName{1+k}=strcat(app.sat.geom.ext.face(i).name,' ','(eps_ext)');
                app.UITable6.Data(:,1+k)=epsext(:,i);
            end
            it=18+1;
            if nstrap>0
                for i=1:nstrap
                    it=it+1;
                    app.UITable6.ColumnName{it}=strcat('idnode_int_strap_',num2str(i));
                    app.UITable6.Data(:,it)=id_node_int(:,i);
                    it=it+1;
                    app.UITable6.ColumnName{it}=strcat('idnode_ext_strap_',num2str(i));
                    app.UITable6.Data(:,it)=id_node_ext(:,i);
                    it=it+1;
                    app.UITable6.ColumnName{it}=strcat('Area_strap_',num2str(i),'[cm^2]');
                    app.UITable6.Data(:,it)=A_straps(:,i)*10^4;
                end
            end
            heat_sum_h=[];
            if check(7,1)>0
                it=it+1;
                if isequal(val_cases,1)
                    app.UITable6.ColumnName{it}='total_heat[W]';
                else
                    app.UITable6.ColumnName{it}='total_heat_hotcase[W]';
                end
                heat_=heat.case1(:,2:end)';
                heat_h=heat_(idx,:);
                heat_sum_h=sum(heat_h,2)';
                app.UITable6.Data(:,it)=heat_sum_h;
                boards=app.sat.node.int.name;
                tdata=table(boards,...
                    'VariableNames',{'Boards'});
                app.UITable7.Data=tdata;
                heat_h=heat_h';
                heat_table=array2table(heat_h);
                app.UITable7.Data(:,2:1+size(heat_h,2))=...
                    heat_table;
                app.UITable7.ColumnName=cell(1+size(heat_h,2),1);
                app.UITable7.ColumnName(1)={'Boards'};
                idx_=num2cell(idx);
                app.UITable7.ColumnName(2:end)=idx_;
                app.UITable7.Visible=1;
                app.UITable7.Position=[9,9,522,148];
                app.HeatersconfigurationoverviewPanel.Visible=1;
            end
            heat_sum_c=[];
            if check(8,1)>0
                it=it+1;
                app.UITable6.ColumnName{it}='total_heat_coldcase[W]';
                heat_=heat.case2(:,2:end)';
                heat_c=heat_(idx,:);
                heat_sum_c=sum(heat_c,2)';
                app.UITable6.Data(:,it)=heat_sum_c;
                boards=app.sat.node.int.name;
                tdata=table(boards,...
                    'VariableNames',{'Boards'});
                app.UITable7_2.Data=tdata;
                heat_c=heat_c';
                heat_table=array2table(heat_c);
                app.UITable7_2.Data(:,2:1+size(heat_c,2))=...
                    heat_table;
                app.UITable7_2.ColumnName=cell(1+size(heat_c,2),1);
                app.UITable7_2.ColumnName(1)={'Boards'};
                idx_=num2cell(idx);
                app.UITable7_2.ColumnName(2:end)=idx_;
                if check(7,1)>1
                    app.UITable7_2.Position=[284,6,243,151];
                    app.UITable7.Position=[9,9,256,148];
                else
                    app.UITable7_2.Position=[9,9,522,148];
                end
                app.UITable7_2.Visible=1;
                app.HeatersconfigurationoverviewPanel.Visible=1;
            end
            
            it=it+1;            
            app.UITable6.ColumnName{it}='Utility';
            app.UITable6.Data(:,it)=U;
            app.UITable6.ColumnSortable=true;
            app.UITable6.Visible=true;
            [~,index]=max(U);
            plot(app.UIAxes7,idx,U, 'p','markerFaceColor','k');     
            hold(app.UIAxes7,'on')
            plot(app.UIAxes7,idx(index),U(index,1),...               
                'p','markerFaceColor','r','MarkerEdgeColor','r')
            app.UIAxes7.Visible=true;
            app.RobustnessanalysisPanel.Visible=1;
            app.OptimizationoverviewPanel.Visible=1;
            if isequal(rob_in,1)
                std_value=data.std_value;
                [~,in]=sort(std_value);
                plot(app.UIAxes7,idx(in(1:5)),U(in(1:5),1), 'p','markerFaceColor','g')
                hold(app.UIAxes7,'off')
                app.PressStarttooptimiseLabel_2.Text='R. Analysis completed!';
                app.PressStarttooptimiseLamp_2.Color=[0, 1, 0];
            end
            
            
        end

        % Button pushed function: ShowIDButton_2
        function ShowIDButton_2Pushed(app, event)
            value=app.IDListBox_2.Value;
            delete(findobj(app.UIAxes3,'type','text'));
            proptextfun_sel(app.sat,app.UIAxes3,value);
        end

        % Value changed function: VisibilitySlider_2
        function VisibilitySlider_2ValueChanged(app, event)
            addpath Function\
            value = app.VisibilitySlider_2.Value;
%             alt_GMM_PLOT(app.sat,app.UIAxes2,value)
%             value = app.VisibilitySlider_3.Value;
%             cla(app.UIAxes2_2);
            plot_GMM4(app.sat,app.UIAxes3,value);
        end

        % Value changed function: CasestudiesSwitch
        function CasestudiesSwitchValueChanged(app, event)

            app.InputSetLamp.Color = [1 0 0];
            disable_confirm_save(app)
            value = app.CasestudiesSwitch.Value;
            val=str2double(value);
            switch val
                case 1
                    app.WorstCasePanel_2.Visible=0;
                case 2
                    app.WorstCasePanel_2.Visible=1;
            end           
            
            %             value = app.CasestudiesSwitch.Value;
%             val=str2double(value);
%             switch val
%                 case 1
%                     app.WorstCasePanel.Title='Worst Case';
%                     app.WorstCasePanel_2.Title='Worst Case';
%                     app.WorstCasePanel_2.Visible=0;
%                     app.AttitudePanel.Position=[325,329,266,142];
%                     if isequal(app.DissipationsPanel.Visible,1)
%                         tab_sup=app.UITable3.Data; % ##### TODO: fix using tables ####
%                         tab_nod=app.UITable4.Data;
%                         app.UITable3.ColumnEditable=logical([0 1]);
%                         tab_sup_t=zeros(size(tab_sup,1),2);
%                         tab_nod_t=zeros(size(tab_nod,1),2);
%                         tab_sup_t(:,1:2)=tab_sup(:,1:2);
%                         tab_nod_t(:,1:2)=tab_nod(:,1:2);
%                         app.UITable3.Data=tab_sup_t; % ##### TODO: fix using tables ####
%                         app.UITable4.Data=tab_nod_t;
%                         app.UITable3.ColumnName=...
%                             {'ID_Face','Power [W]'};
%                         app.UITable4.ColumnName=...
%                             {'ID_node','Power [W]'};
%                     end
%                     
%                 case 2
%                     app.WorstCasePanel.Title='Hot Case';
%                     app.WorstCasePanel_2.Title='Cold Case';
%                     app.WorstCasePanel_2.Visible=1;
%                     app.AttitudePanel.Position=[325,329,525,142];
%                     if isequal(app.DissipationsPanel.Visible,1)
%                         tab_sup=app.UITable3.Data; % ##### TODO: fix using tables ####
%                         tab_nod=app.UITable4.Data;
%                         tab_sup_t=zeros(size(tab_sup,1),3);
%                         tab_nod_t=zeros(size(tab_nod,1),3);
%                         tab_sup_t(:,1:2)=tab_sup(:,1:2);
%                         tab_nod_t(:,1:2)=tab_nod(:,1:2);
%                         app.UITable3.Data=tab_sup_t; % ##### TODO: fix using tables ####
%                         app.UITable4.Data=tab_nod_t;
%                         app.UITable3.ColumnEditable=logical([0 1 1]);
%                         app.UITable3.ColumnName=...
%                             {'ID_Face','Power_HOT [W]','Power_COLD [W]'};
%                         app.UITable4.ColumnName=...
%                             {'ID_node','Power_HOT [W]','Power_COLD [W]'};
%                     end
%                     
%             end
        end

        % Value changed function: SpinCheckBox
        function SpinCheckBoxValueChanged(app, event)
            value = app.SpinCheckBox.Value;
            switch value
                case 1
                    app.PointingDropDown.Enable=0;
                    app.FaceDropDown.Enable=0;
                    app.PointingDropDown_2.Enable=0;
                    app.FaceDropDown_2.Enable=0;
                case 0
                    app.PointingDropDown.Enable=1;
                    app.FaceDropDown.Enable=1;
                    app.PointingDropDown_2.Enable=1;
                    app.FaceDropDown_2.Enable=1;
                    
            end
        end

        % Value changed function: SpinCheckBox_2
        function SpinCheckBox_2ValueChanged(app, event)
            value = app.SpinCheckBox_2.Value;
            switch value
                case 1
                    app.PointingDropDown_3.Enable=0;
                    app.FaceDropDown_3.Enable=0;
                    app.PointingDropDown_4.Enable=0;
                    app.FaceDropDown_4.Enable=0;
                case 0
                    app.PointingDropDown_3.Enable=1;
                    app.FaceDropDown_3.Enable=1;
                    app.PointingDropDown_4.Enable=1;
                    app.FaceDropDown_4.Enable=1;
            end
        end

        % Button pushed function: PreviewButton_3
        function PreviewButton_3Pushed(app, event)
            load GMM_complete.mat
            cla(app.UIAxes3);
            plot_GMM4(app.sat,app.UIAxes3);
            hold(app.UIAxes3,'on');
            diss_mat_tot=table2array(app.UITable3.Data(:,[1 3:end]));
            [app.sat]=Q_dissipation3(app.sat,diss_mat_tot,2);
            diss_nod=app.UITable4.Data;
            for i=1:length(app.sat.node.Q_diss_cold(:,1))
                if app.sat.node.Q_diss_cold(i,1)>0
                    plot3(app.UIAxes3,app.sat.node.globe(i).node(1),...
                        app.sat.node.globe(i).node(2),app.sat.node.globe(i).node(3),...
                        'Color','b','Marker','o','MarkerSize',5,'LineWidth',3)
                end
            end
            if any(diss_nod)
                for i=1:length(diss_nod(:,1))
                    if diss_nod(i,3)>0
                        app.sat.node.Q_diss_cold(diss_nod(i,1),1)=...
                            app.sat.node.Q_diss_cold(diss_nod(i,1),1)+diss_nod(i,3);
                        plot3(app.UIAxes3,app.sat.node.globe(diss_nod(i,1)).node(1),...
                            app.sat.node.globe(diss_nod(i,1)).node(2),...
                            app.sat.node.globe(diss_nod(i,1)).node(3),...
                            'Color','b','Marker','<','MarkerSize',10,"LineWidth",3)
                    end
                end
            end
            hold(app.UIAxes3,'off');
        end

        % Selection changed function: TrasparencyButtonGroup_2
        function TrasparencyButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.TrasparencyButtonGroup_2.SelectedObject;
            
            if strcmp(selectedButton.Text,'External')
                for i=1:length(app.handles_c)
                    app.handles_c(i).FaceAlpha=1;
                    
                end
            elseif strcmp(selectedButton.Text,'Both')
                for i=1:length(app.handles_c)
                    app.handles_c(i).FaceAlpha=0.3;
                end
            elseif strcmp(selectedButton.Text,'Internal')
                for i=1:length(app.handles_c)
                    app.handles_c(i).FaceAlpha=0;
                end
            end
        end

        % Value changed function: TimeminSlider_2
        function TimeminSlider_2ValueChanged(app, event)
            value = app.TimeminSlider_2.Value;
            [~,app.ix]=min(abs(app.sat.node.analysis.time_vect-value*60));
    
            % Heat Map
            [app.handles_c, ~] = plot_GMM4_heatmap(app.sat,app.UIAxes6_2,app.TrasparencyButtonGroup_2.SelectedObject.Text,app.ix,2);            
            
            app.TimesEditField_2.Value=app.sat.node.analysis.time_vect(app.ix,1);
            kk=app.TimesEditField_2.Value-floor((app.TimesEditField_2.Value)/app.sat.node.analysis.period)*app.sat.node.analysis.period;
            r=app.sat.node.analysis.Orbit_data;
            [~,kk]=min(abs(kk-r(:,4)));
            C1 = repmat([0,0,0],numel(r(r(:,5)==1,1)),1);
            C2 = repmat([0,0.70,1],numel(r(r(:,5)==0,1)),1);
            S1 = repmat(5,numel(r(r(:,5)==1,1)),1);
            S2 = repmat(5,numel(r(r(:,5)==0,1)),1);
            if isequal(r(kk,5),1)
                app.mundi_pl_c.ecl.CData=C1;
                app.mundi_pl_c.sun.CData=C2;
                app.mundi_pl_c.ecl.SizeData=S1;
                app.mundi_pl_c.sun.SizeData=S2;
                app.mundi_pl_c.ecl.CData(app.sat.node.analysis.ecl_day.ecl(:,4)==r(kk,4),:)=[0.86 0 0.78];
                app.mundi_pl_c.ecl.SizeData(1,app.sat.node.analysis.ecl_day.ecl(:,4)==r(kk,4))=100;
                
            else
                app.mundi_pl_c.ecl.CData=C1;
                app.mundi_pl_c.sun.CData=C2;
                app.mundi_pl_c.ecl.SizeData=S1;
                app.mundi_pl_c.sun.SizeData=S2;
                app.mundi_pl_c.sun.CData(app.sat.node.analysis.ecl_day.day(:,4)==r(kk,4),:)=[0.86 0 0.78];
                app.mundi_pl_c.sun.SizeData(1,app.sat.node.analysis.ecl_day.day(:,4)==r(kk,4))=100;
            end

        end

        % Button pushed function: PreviewandUpdateGMMButton
        function PreviewandUpdateGMMButtonPushed(app, event)

            addpath Database\
            addpath Function\           

            app.sat = [];

            try
                %% Structure
                Geom_ex = app.UITable1_ex.Data;
                Prop_ex = app.UITable2_ex.Data;
    
                app.sat.geom.ext.size=table2array([Geom_ex(:,8),Geom_ex(:,9),Geom_ex(:,10)]);
                app.sat.geom.ext.center=table2array([Geom_ex(:,5),Geom_ex(:,6),Geom_ex(:,7)]);
                app.sat.geom.ext.nodes=table2array([Geom_ex(:,2),Geom_ex(:,3),Geom_ex(:,4)]);
                app.sat.geom.ext.angles=[0,0,0]; % structure can not be rotated
                app.sat.geom.ext.color=table2array([Geom_ex(:,11)]);
                app.sat.geom.ext.item_name=table2array([Geom_ex(:,12)]);
    
                app.sat.geom.ext.th=Prop_ex(:,9)';
                
                                   %C  %rho %k
                app.sat.prop.ext.mech=[Prop_ex(:,8),Prop_ex(:,7),Prop_ex(:,6)];
                                  %abs %eps1 (internal) %eps2 (external)
                app.sat.prop.ext.opt=[Prop_ex(:,3),Prop_ex(:,4),Prop_ex(:,5)];
                app.sat.prop.ext.T_op_min = Prop_ex(:,10)';
                app.sat.prop.ext.T_op_max = Prop_ex(:,11)';
    
                %% Solar Panels
                app.sat.geom.Nsp=app.SolarPanelsSpinner.Value;
                if app.sat.geom.Nsp==0
                    app.sat.geom.sp.item_name=[];
                else
                    Geom_sp = app.UITable_2.Data;
                    Prop_sp= app.UITable2_3.Data;  
                    for i=1:1:app.sat.geom.Nsp            
                    app.sat.geom.sp(i).sizes=table2array([Geom_sp(i,7),Geom_sp(i,8)]);
                    app.sat.geom.sp(i).center=table2array([Geom_sp(i,4),Geom_sp(i,5),Geom_sp(i,6)]);
                    app.sat.geom.sp(i).nodes=table2array([Geom_sp(i,2),Geom_sp(i,3)]);
                    app.sat.geom.sp(i).angles=table2array([Geom_sp(i,9),Geom_sp(i,10),Geom_sp(i,11)]);
                    app.sat.geom.sp(i).color=table2array([Geom_sp(i,12)]);
                    app.sat.geom.sp(i).item_name=table2array([Geom_sp(i,13)]);
                    app.sat.geom.sp(i).th=Prop_sp(i,8)';
                    
                                         %C  %rho %k
                    app.sat.prop.sp(i).mech=[Prop_sp(i,7),Prop_sp(i,6),Prop_sp(i,5)];
                                         
                                        %abs %eps2 (external)
                    app.sat.prop.sp(i).opt=[Prop_sp(i,3),Prop_sp(i,4)];
                    app.sat.prop.sp(i).T_op_min = Prop_sp(i,9)';
                    app.sat.prop.sp(i).T_op_max = Prop_sp(i,10)';   
        
                    end
                end
                
    
                %% Boards
                app.sat.geom.Nb=app.BoardsSpinner.Value;
                if app.sat.geom.Nb==0
                    app.sat.geom.board.item_name={};
                else
                    Geom_b = app.UITable_3.Data;
                    Prop_b= app.UITable2_4.Data;  
                    for i=1:1:app.sat.geom.Nb           
                    app.sat.geom.board(i).sizes=table2array([Geom_b(i,7),Geom_b(i,8)]);
                    app.sat.geom.board(i).center=table2array([Geom_b(i,4),Geom_b(i,5),Geom_b(i,6)]);
                    app.sat.geom.board(i).nodes=table2array([Geom_b(i,2),Geom_b(i,3)]);
                    app.sat.geom.board(i).angles=table2array([Geom_b(i,9),Geom_b(i,10),Geom_b(i,11)]);
                    app.sat.geom.board(i).color=table2array([Geom_b(i,12)]);
                    app.sat.geom.board(i).item_name=table2array([Geom_b(i,13)]);
                    app.sat.geom.board(i).th=Prop_b(i,8)';
                    
                                         %C  %rho %k
                    app.sat.prop.board(i).mech=[Prop_b(i,7),Prop_b(i,6),Prop_b(i,5)];
                                         
                                        %abs %eps2 (external)
                    app.sat.prop.board(i).opt=[Prop_b(i,3),Prop_b(i,4)];
                    app.sat.prop.board(i).T_op_min = Prop_b(i,9)';
                    app.sat.prop.board(i).T_op_max = Prop_b(i,10)';   
        
                    end
                end
    
                %% Parallelepiped
                app.sat.geom.NP=app.ParallelepipedsSpinner.Value;
                if app.sat.geom.NP==0
                    app.sat.geom.parall.item_name={};
                else
                    Geom_P = app.UITable_4.Data;
                    Prop_m_P = app.UITable2_5.Data; 
                    Prop_o_P = app.UITable2_7.Data;
                    for i=1:1:app.sat.geom.NP               
        
                    app.sat.geom.parall(i).sizes=table2array([Geom_P(i,8),Geom_P(i,9),Geom_P(i,10)]);
                    app.sat.geom.parall(i).center=table2array([Geom_P(i,5),Geom_P(i,6),Geom_P(i,7)]);
                    app.sat.geom.parall(i).nodes=table2array([Geom_P(i,2),Geom_P(i,3),Geom_P(i,4)]);
                    app.sat.geom.parall(i).angles=table2array([Geom_P(i,11),Geom_P(i,12),Geom_P(i,13)]);
                    app.sat.geom.parall(i).color=table2array([Geom_P(i,14)]);
                    app.sat.geom.parall(i).item_name=table2array([Geom_P(i,15)]);
                    app.sat.geom.parall(i).th=Prop_m_P(i,6)';
                    
                                         %C  %rho %k
                    app.sat.prop.parall(i).mech=[Prop_m_P(i,5),Prop_m_P(i,4),Prop_m_P(i,3)];
                                         
                                        %abs %eps2 (external)
                    app.sat.prop.parall(i).opt=[Prop_o_P(i,2),Prop_o_P(i,3);...
                                                Prop_o_P(i,4),Prop_o_P(i,5);...
                                                Prop_o_P(i,6),Prop_o_P(i,7);...
                                                Prop_o_P(i,8),Prop_o_P(i,9);...
                                                Prop_o_P(i,10),Prop_o_P(i,11);...
                                                Prop_o_P(i,12),Prop_o_P(i,13)];
                    app.sat.prop.parall(i).T_op_min = Prop_m_P(i,7)';
                    app.sat.prop.parall(i).T_op_max = Prop_m_P(i,8)';   
                    end
                end
    
                %% Cylinders
                app.sat.geom.Nc=app.CylindersSpinner.Value;
                if app.sat.geom.Nc==0
                    app.sat.geom.cyl.item_name={};
                else
                    Geom_c = app.UITable_5.Data;
                    Prop_c = app.UITable2_6.Data;
                    for i=1:1:app.sat.geom.Nc
        
                    app.sat.geom.cyl(i).R=table2array(Geom_c(i,2));
                    app.sat.geom.cyl(i).L=table2array(Geom_c(i,3));
                    app.sat.geom.cyl(i).Nr=table2array(Geom_c(i,7));
                    app.sat.geom.cyl(i).Nt=table2array(Geom_c(i,8));
                    app.sat.geom.cyl(i).Nz=table2array(Geom_c(i,9));
                    app.sat.geom.cyl(i).angles=table2array([Geom_c(i,10),Geom_c(i,11),Geom_c(i,12)]);
                    app.sat.geom.cyl(i).center=table2array([Geom_c(i,4),Geom_c(i,5),Geom_c(i,6)]);
                    app.sat.geom.cyl(i).color=table2array([Geom_c(i,13)]);
                    app.sat.geom.cyl(i).item_name=table2array([Geom_c(i,14)]);  
             
                                          %C  %rho %k
                    app.sat.prop.cyl(i).mech=[Prop_c(i,11),Prop_c(i,10),Prop_c(i,9)];
                                       
                                        %abs %eps2
                    app.sat.prop.cyl(i).opt=[Prop_c(i,3),Prop_c(i,4);...
                                             Prop_c(i,5),Prop_c(i,6);...
                                             Prop_c(i,7),Prop_c(i,8)];
                    app.sat.prop.cyl(i).T_op_min = Prop_c(i,12)';
                    app.sat.prop.cyl(i).T_op_max = Prop_c(i,13)';  
        
                    end
                end
            catch                
                msgbox("Cannot update GMM, some Items may not be initialized");
                app.Lamp.Color=[1 0 0];
                return
            end

            %% GMM (no view factors)

            [app.sat,~] = GMM4(app.sat); % suppressed C output
            [app.sat] = mech_prop(app.sat);
            [app.sat,~] = surf_global(app.sat); % suppressed surf_for_MCRT output
            [app.sat,~] = opt_prop(app.sat); % suppressed eps_for_Ge output  
            
            app.name_item=app.sat.geom.ext.item_name;
            for i=1:1:app.sat.geom.Nsp 
                app.name_item=[app.name_item; app.sat.geom.sp(i).item_name];
            end
            for i=1:1:app.sat.geom.Nb
                app.name_item=[app.name_item;app.sat.geom.board(i).item_name];
            end
            for i=1:1:app.sat.geom.NP
                app.name_item=[app.name_item;app.sat.geom.parall(i).item_name];
            end
            for i=1:1:app.sat.geom.Nc 
                app.name_item=[app.name_item;app.sat.geom.cyl(i).item_name];
            end

            %% Preview

            %graph.cla
            app.UIAxes2_2.Visible=1;
            cla(app.UIAxes2_2);
            %[app.sat] = Preview_GMM(app.sat);
            plot_GMM4(app.sat,app.UIAxes2_2);
            app.VisibilitySlider_3.Visible=1;
            app.VisibilitySlider_3Label.Visible=1;

            id=1:1:length(app.name_item);
            app.IDListBox_3.Items=app.name_item; %all input, GMM
            app.IDListBox_3.ItemsData=id; %all input, GMM
            app.IDListBox_2.Items=app.name_item; %all input, TMM
            app.IDListBox_2.ItemsData=id; %all input, TMM

            app.IDListBox_3.Visible=1;
            app.ShowIDButton_3.Visible=1;

            app.CalculateViewFactorButton.Visible=1;

            app.Lamp_3.Color=[1 0 0]; % Ready to run           

            Table_Structure_Geom = app.UITable1_ex.Data;
            Table_Structure_Prop = app.UITable2_ex.Data;
            Table_SolarP_Geom = app.UITable_2.Data;
            Table_SolarP_Prop = app.UITable2_3.Data;
            Table_Board_Geom = app.UITable_3.Data;
            Table_Board_Prop = app.UITable2_4.Data;
            Table_Parall_Geom = app.UITable_4.Data;
            Table_Parall_PropM = app.UITable2_5.Data;
            Table_Parall_PropO = app.UITable2_7.Data;
            Table_Cyl_Geom = app.UITable_5.Data;
            Table_Cyl_Prop = app.UITable2_6.Data;

            sat=app.sat;
            names = app.name_item;

            app.Lamp.Color=[1 0 0]; % GMM
            app.Lamp_2.Color=[1 0 0]; % TMM
            app.GenerateTMMButton.Enable="off"; % Generate TMM
            % TMM
            app.IDListBox_2.Visible=0;
            app.ShowIDButton_2.Visible=0;
            app.VisibilitySlider_2.Visible=0;
            app.VisibilitySlider_2Label.Visible=0;
            app.UIAxes3.Visible=0;
            cla(app.UIAxes3)

            disable_confirm_save(app)

            save GMM_temporary.mat sat ...
                Table_Structure_Geom Table_Structure_Prop Table_SolarP_Geom Table_SolarP_Prop ...
                Table_Board_Geom Table_Board_Prop Table_Parall_Geom Table_Parall_Geom ...
                Table_Parall_PropM Table_Parall_PropO Table_Cyl_Geom Table_Cyl_Prop ...
                names

        end

        % Value changed function: VisibilitySlider_3
        function VisibilitySlider_3ValueChanged(app, event)
            addpath Function\
            value = app.VisibilitySlider_3.Value;
            cla(app.UIAxes2_2);
            plot_GMM4(app.sat,app.UIAxes2_2,value);                       
        end

        % Button pushed function: ShowIDButton_3
        function ShowIDButton_3Pushed(app, event)
            value=app.IDListBox_3.Value; %senza "_2" GMM
            delete(findobj(app.UIAxes2_2,'type','text'));
            proptextfun_sel(app.sat,app.UIAxes2_2,value);
        end

        % Button pushed function: SolarPanelsInitializationButton
        function SolarPanelsInitializationButtonPushed(app, event)
            Nsp=app.SolarPanelsSpinner.Value;
            app.sat.geom.Nsp=Nsp;
            %solar panels geometry 
            if isempty(app.UITable_2.Data)
                colorname=repmat(["y";"m";"c";"r";"g";"b";"w";"k"],Nsp,1);
%                 normal_type=repmat(["+X";"+Y";"+Z"],n_board,1);
                board_name=repmat("name_solar_panel",Nsp,1);
                cnames = categorical(colorname);
%                 normal_names = categorical(normal_type);
                angles = zeros(Nsp,3);
                id_boards=linspace(1,Nsp,Nsp)';
                nnodes=ones(Nsp,2)*3;
                centrecoord=ones(Nsp,3)*50;
                size_board=ones(Nsp,2)*50;
                tdata=table(id_boards,nnodes(:,1),nnodes(:,2),centrecoord(:,1),...
                    centrecoord(:,2),centrecoord(:,3),size_board(:,1),size_board(:,2)...
                    ,angles(:,1),angles(:,2),angles(:,3),cnames(1:Nsp,1),board_name,...
                    'VariableNames',{'ID_boards','Nx','Ny','X_centre',...
                    'Y_centre','Z_centre','Lx','Ly','alpha','beta','gamma','Color','Item_name'});
                app.UITable_2.Data=tdata;
                                
                % properties
                Datainit_3=zeros(Nsp,10);
                app.UITable2_3.Data=Datainit_3;
                app.UITable2_3.Data(:,1)=linspace(1,Nsp,Nsp);
                app.UITable2_3.Data(:,2)=293.15;
                app.UITable2_3.Data(:,3)=0.88;
                app.UITable2_3.Data(:,4)=0.88;
                app.UITable2_3.Data(:,5)=173;
                app.UITable2_3.Data(:,6)=2810;
                app.UITable2_3.Data(:,7)=900;
                app.UITable2_3.Data(:,8)=2.5;
                app.UITable2_3.Data(:,9)=-40;
                app.UITable2_3.Data(:,10)=50;
%                 if strcmp(app.PreviewGMMButton.Enable,'off')
%                     app.PreviewGMMButton.Enable='on';
%                     app.SaveandGenerateGMMButton.Enable='on';
%                 end
            else
                %initialisation
                old_number=size(app.UITable_2.Data,1);
                new_num=Nsp-old_number;
                %geometrical internal data
                colorname=repmat(["y";"m";"c";"r";"g";"b";"w";"k"],new_num,1);
%                 normal_type=repmat(["+X";"+Y";"+Z"],new_num,1);
                board_name=repmat("name_board",new_num,1);
                cnames = categorical(colorname);
%                 normal_names = categorical(normal_type);
                id_boards=linspace(old_number+1,Nsp,new_num)';
                angles = zeros(new_num,3);
                nnodes=ones(new_num,2)*3;
                centrecoord=ones(new_num,3)*50;
                size_board=ones(new_num,2)*50;
%                 tdata2=table(id_boards,nnodes(:,1),nnodes(:,2),centrecoord(:,1),...
%                     centrecoord(:,2),centrecoord(:,3),size_board(:,1),size_board(:,2)...
%                     ,normal_names(1:new_num,1),cnames(1:new_num,1),board_name,...
%                     'VariableNames',{'id_boards','Nx','Ny','X_centre',...
%                     'Y_centre','Z_centre','Lx','Ly','Normal_vector','Color','Board_name'});
                tdata2=table(id_boards,nnodes(:,1),nnodes(:,2),centrecoord(:,1),...
                centrecoord(:,2),centrecoord(:,3),size_board(:,1),size_board(:,2)...
                ,angles(:,1),angles(:,2),angles(:,3),cnames(1:new_num,1),board_name,...
                'VariableNames',{'ID_boards','Nx','Ny','X_centre',...
                'Y_centre','Z_centre','Lx','Ly','alpha','beta','gamma','Color','Item_name'});
                tdata=app.UITable_2.Data;
                app.UITable_2.Data=[tdata; tdata2];
                % properties
                Datainit_3=zeros(new_num,10);
                app.UITable2_3.Data=[app.UITable2_3.Data;Datainit_3];
                app.UITable2_3.Data(old_number+1:old_number+new_num,1)=...
                    linspace(old_number+1,old_number+new_num,new_num);
                app.UITable2_3.Data(old_number+1:old_number+new_num,2)=293.15;
                app.UITable2_3.Data(old_number+1:old_number+new_num,3)=0.88;
                app.UITable2_3.Data(old_number+1:old_number+new_num,4)=0.88;
                app.UITable2_3.Data(old_number+1:old_number+new_num,5)=173;
                app.UITable2_3.Data(old_number+1:old_number+new_num,6)=2810;
                app.UITable2_3.Data(old_number+1:old_number+new_num,7)=900;
                app.UITable2_3.Data(old_number+1:old_number+new_num,8)=2.5;
                app.UITable2_3.Data(old_number+1:old_number+new_num,9)=-40;
                app.UITable2_3.Data(old_number+1:old_number+new_num,10)=50;
            end

            app.okLamp_3.Color=[0 1 0];

        end

        % Button pushed function: BoardsInitializationButton
        function BoardsInitializationButtonPushed(app, event)
            Nb=app.BoardsSpinner.Value;
            app.sat.geom.Nb=Nb;
            %solar panels geometry 
            if isempty(app.UITable_3.Data)
                colorname=repmat(["y";"m";"c";"r";"g";"b";"w";"k"],Nb,1);
%                 normal_type=repmat(["+X";"+Y";"+Z"],n_board,1);
                board_name=repmat("name_board",Nb,1);
                cnames = categorical(colorname);
%                 normal_names = categorical(normal_type);
                angles = zeros(Nb,3);
                id_boards=linspace(1,Nb,Nb)';
                nnodes=ones(Nb,2)*3;
                centrecoord=ones(Nb,3)*50;
                size_board=ones(Nb,2)*50;
                tdata=table(id_boards,nnodes(:,1),nnodes(:,2),centrecoord(:,1),...
                    centrecoord(:,2),centrecoord(:,3),size_board(:,1),size_board(:,2)...
                    ,angles(:,1),angles(:,2),angles(:,3),cnames(1:Nb,1),board_name,...
                    'VariableNames',{'ID_boards','Nx','Ny','X_centre',...
                    'Y_centre','Z_centre','Lx','Ly','alpha','beta','gamma','Color','Item_name'});
                app.UITable_3.Data=tdata;
                
                % properties
                Datainit_3=zeros(Nb,10);
                app.UITable2_4.Data=Datainit_3;
                app.UITable2_4.Data(:,1)=linspace(1,Nb,Nb);
                app.UITable2_4.Data(:,2)=293.15;
                app.UITable2_4.Data(:,3)=0.88;
                app.UITable2_4.Data(:,4)=0.88;
                app.UITable2_4.Data(:,5)=173;
                app.UITable2_4.Data(:,6)=2810;
                app.UITable2_4.Data(:,7)=900;
                app.UITable2_4.Data(:,8)=2.5;
                app.UITable2_4.Data(:,9)=-40;
                app.UITable2_4.Data(:,10)=50;
%                 if strcmp(app.PreviewGMMButton.Enable,'off')
%                     app.PreviewGMMButton.Enable='on';
%                     app.SaveandGenerateGMMButton.Enable='on';
%                 end
            else
                %initialisation
                old_number=size(app.UITable_3.Data,1);
                new_num=Nb-old_number;
                %geometrical internal data
                colorname=repmat(["y";"m";"c";"r";"g";"b";"w";"k"],new_num,1);
%                 normal_type=repmat(["+X";"+Y";"+Z"],new_num,1);
                board_name=repmat("name_board",new_num,1);
                cnames = categorical(colorname);
%                 normal_names = categorical(normal_type);
                id_boards=linspace(old_number+1,Nb,new_num)';
                angles = zeros(new_num,3);
                nnodes=ones(new_num,2)*3;
                centrecoord=ones(new_num,3)*50;
                size_board=ones(new_num,2)*50;
%                 
                tdata2=table(id_boards,nnodes(:,1),nnodes(:,2),centrecoord(:,1),...
                centrecoord(:,2),centrecoord(:,3),size_board(:,1),size_board(:,2)...
                ,angles(:,1),angles(:,2),angles(:,3),cnames(1:new_num,1),board_name,...
                'VariableNames',{'ID_boards','Nx','Ny','X_centre',...
                'Y_centre','Z_centre','Lx','Ly','alpha','beta','gamma','Color','Item_name'});
                tdata=app.UITable_3.Data;
                app.UITable_3.Data=[tdata; tdata2];
                % properties
                Datainit_3=zeros(new_num,10);
                app.UITable2_4.Data=[app.UITable2_4.Data;Datainit_3];
                app.UITable2_4.Data(old_number+1:old_number+new_num,1)=...
                    linspace(old_number+1,old_number+new_num,new_num);
                app.UITable2_4.Data(old_number+1:old_number+new_num,2)=293.15;
                app.UITable2_4.Data(old_number+1:old_number+new_num,3)=0.88;
                app.UITable2_4.Data(old_number+1:old_number+new_num,4)=0.88;
                app.UITable2_4.Data(old_number+1:old_number+new_num,5)=173;
                app.UITable2_4.Data(old_number+1:old_number+new_num,6)=2810;
                app.UITable2_4.Data(old_number+1:old_number+new_num,7)=900;
                app.UITable2_4.Data(old_number+1:old_number+new_num,8)=2.5;
                app.UITable2_4.Data(old_number+1:old_number+new_num,9)=-40;
                app.UITable2_4.Data(old_number+1:old_number+new_num,10)=50;
            end

            app.okLamp_4.Color=[0 1 0];

        end

        % Button pushed function: ParallelepipedsInitializationButton
        function ParallelepipedsInitializationButtonPushed(app, event)
            NP=app.ParallelepipedsSpinner.Value;
            app.sat.geom.NP=NP;
            %solar panels geometry 
            if isempty(app.UITable_4.Data)
                colorname=repmat(["y";"m";"c";"r";"g";"b";"w";"k"],NP,1);
%                 normal_type=repmat(["+X";"+Y";"+Z"],n_board,1);
                board_name=repmat("name_parallelepiped",NP,1);
                cnames = categorical(colorname);
%                 normal_names = categorical(normal_type);
                angles = zeros(NP,3);
                id_boards=linspace(1,NP,NP)';
                nnodes=ones(NP,3)*3;
                centrecoord=ones(NP,3)*50;
                size_board=ones(NP,3)*50;
                tdata=table(id_boards,nnodes(:,1),nnodes(:,2),nnodes(:,3),centrecoord(:,1),...
                    centrecoord(:,2),centrecoord(:,3),size_board(:,1),size_board(:,2),size_board(:,3)...
                    ,angles(:,1),angles(:,2),angles(:,3),cnames(1:NP,1),board_name,...
                    'VariableNames',{'ID_boards','Nx','Ny','Nz','X_centre',...
                    'Y_centre','Z_centre','Lx','Ly','Lz','alpha','beta','gamma','Color','Item_name'});
                app.UITable_4.Data=tdata;
                
                % properties
                Datainit_3=zeros(NP,8);
                app.UITable2_5.Data=Datainit_3;
                app.UITable2_5.Data(:,1)=linspace(1,NP,NP);
                app.UITable2_5.Data(:,2)=293.15;              
                app.UITable2_5.Data(:,3)=173;
                app.UITable2_5.Data(:,4)=2810;
                app.UITable2_5.Data(:,5)=900;
                app.UITable2_5.Data(:,6)=2.5;
                app.UITable2_5.Data(:,7)=-40;
                app.UITable2_5.Data(:,8)=50;

                Datainit_4=zeros(NP,13);
                app.UITable2_7.Data=Datainit_4;
                app.UITable2_7.Data(:,1)=linspace(1,NP,NP);
                app.UITable2_7.Data(:,[2,4,6,8,10,12])=0.88;
                app.UITable2_7.Data(:,[3,5,7,9,11,13])=0.88;
%                 if strcmp(app.PreviewGMMButton.Enable,'off')
%                     app.PreviewGMMButton.Enable='on';
%                     app.SaveandGenerateGMMButton.Enable='on';
%                 end
            else
                %initialisation
                old_number=size(app.UITable_4.Data,1);
                new_num=NP-old_number;
                %geometrical internal data
                colorname=repmat(["y";"m";"c";"r";"g";"b";"w";"k"],new_num,1);
%                 normal_type=repmat(["+X";"+Y";"+Z"],new_num,1);
                board_name=repmat("name_board",new_num,1);
                cnames = categorical(colorname);
%                 normal_names = categorical(normal_type);
                id_boards=linspace(old_number+1,NP,new_num)';
                angles = zeros(new_num,3);
                nnodes=ones(new_num,3)*3;
                centrecoord=ones(new_num,3)*50;
                size_board=ones(new_num,3)*50;
%                 
                tdata2=table(id_boards,nnodes(:,1),nnodes(:,2),nnodes(:,3),centrecoord(:,1),...
                centrecoord(:,2),centrecoord(:,3),size_board(:,1),size_board(:,2),size_board(:,3)...
                ,angles(:,1),angles(:,2),angles(:,3),cnames(1:new_num,1),board_name,...
                'VariableNames',{'ID_boards','Nx','Ny','Nz','X_centre',...
                'Y_centre','Z_centre','Lx','Ly','Lz','alpha','beta','gamma','Color','Item_name'});
                tdata=app.UITable_4.Data;
                app.UITable_4.Data=[tdata; tdata2];
                % properties
                Datainit_3=zeros(new_num,8);
                app.UITable2_5.Data=[app.UITable2_5.Data;Datainit_3];
                app.UITable2_5.Data(old_number+1:old_number+new_num,1)=...
                    linspace(old_number+1,old_number+new_num,new_num);
                app.UITable2_5.Data(old_number+1:old_number+new_num,2)=293.15;
                app.UITable2_5.Data(old_number+1:old_number+new_num,3)=173;
                app.UITable2_5.Data(old_number+1:old_number+new_num,4)=2810;
                app.UITable2_5.Data(old_number+1:old_number+new_num,5)=900;
                app.UITable2_5.Data(old_number+1:old_number+new_num,6)=2.5;
                app.UITable2_5.Data(old_number+1:old_number+new_num,7)=-40;
                app.UITable2_5.Data(old_number+1:old_number+new_num,8)=50;
                Datainit_4=zeros(new_num,13);
                app.UITable2_7.Data=[app.UITable2_7.Data;Datainit_4];
                app.UITable2_7.Data(old_number+1:old_number+new_num,1)=...
                    linspace(old_number+1,old_number+new_num,new_num);
                app.UITable2_7.Data(old_number+1:old_number+new_num,[2,4,6,8,10,12])=0.88;
                app.UITable2_7.Data(old_number+1:old_number+new_num,[3,5,7,9,11,13])=0.88;
            end

            app.okLamp_5.Color=[0 1 0];

        end

        % Button pushed function: CylindersInitializationButton
        function CylindersInitializationButtonPushed(app, event)
            Nc=app.CylindersSpinner.Value;
            app.sat.geom.Nc=Nc;
            %solar panels geometry 
            if isempty(app.UITable_5.Data)
                colorname=repmat(["y";"m";"c";"r";"g";"b";"w";"k"],Nc,1);
%                 normal_type=repmat(["+X";"+Y";"+Z"],n_board,1);
                board_name=repmat("name_cylinder",Nc,1);
                cnames = categorical(colorname);
%                 normal_names = categorical(normal_type);
                angles = zeros(Nc,3);
                id_boards=linspace(1,Nc,Nc)';
                nnodes=ones(Nc,3)*3;
                centrecoord=ones(Nc,3)*50;
                size_board=ones(Nc,3)*50;
                tdata=table(id_boards,size_board(:,1),size_board(:,2),centrecoord(:,1),centrecoord(:,2),centrecoord(:,3),...
                   nnodes(:,1),nnodes(:,2),nnodes(:,3),angles(:,1),angles(:,2),angles(:,3),cnames(1:Nc,1),board_name,...
                    'VariableNames',{'ID_Cylinder','Radius','Length','X_centre',...
                    'Y_centre','Z_centre','N_radius','N_circum','N_length','alpha','beta','gamma','Color','Item_name'});
                app.UITable_5.Data=tdata;
                
                % properties
                Datainit_3=zeros(Nc,14);
                app.UITable2_6.Data=Datainit_3;
                app.UITable2_6.Data(:,1)=linspace(1,Nc,Nc);
                app.UITable2_6.Data(:,2)=293.15;
                app.UITable2_6.Data(:,3)=0.88;
                app.UITable2_6.Data(:,4)=0.88;
                app.UITable2_6.Data(:,5)=0.88;
                app.UITable2_6.Data(:,6)=0.88;
                app.UITable2_6.Data(:,7)=0.88;
                app.UITable2_6.Data(:,8)=0.88;
                app.UITable2_6.Data(:,9)=173;
                app.UITable2_6.Data(:,10)=2810;
                app.UITable2_6.Data(:,11)=900;
                app.UITable2_6.Data(:,12)=2.5;
                app.UITable2_6.Data(:,13)=-40;
                app.UITable2_6.Data(:,14)=50;

%                 if strcmp(app.PreviewGMMButton.Enable,'off')
%                     app.PreviewGMMButton.Enable='on';
%                     app.SaveandGenerateGMMButton.Enable='on';
%                 end
            else
                %initialisation
                old_number=size(app.UITable_5.Data,1);
                new_num=Nc-old_number;
                %geometrical internal data
                colorname=repmat(["y";"m";"c";"r";"g";"b";"w";"k"],new_num,1);
%                 normal_type=repmat(["+X";"+Y";"+Z"],new_num,1);
                board_name=repmat("name_board",new_num,1);
                cnames = categorical(colorname);
%                 normal_names = categorical(normal_type);
                id_boards=linspace(old_number+1,Nc,new_num)';
                angles = zeros(new_num,3);
                nnodes=ones(new_num,3)*3;
                centrecoord=ones(new_num,3)*50;
                size_board=ones(new_num,2)*50;
%                 
                tdata2=table(id_boards,size_board(:,1),size_board(:,2),centrecoord(:,1),centrecoord(:,2),centrecoord(:,3),...
                   nnodes(:,1),nnodes(:,2),nnodes(:,3),angles(:,1),angles(:,2),angles(:,3),cnames(1:new_num,1),board_name,...
                    'VariableNames',{'ID_Cylinder','Radius','Length','X_centre',...
                    'Y_centre','Z_centre','N_radius','N_circum','N_length','alpha','beta','gamma','Color','Item_name'});
                tdata=app.UITable_5.Data;
                app.UITable_5.Data=[tdata; tdata2];
                % properties
                Datainit_3=zeros(new_num,14);
                app.UITable2_6.Data=[app.UITable2_6.Data;Datainit_3];
                app.UITable2_6.Data(old_number+1:old_number+new_num,1)=...
                    linspace(old_number+1,old_number+new_num,new_num);
                app.UITable2_6.Data(old_number+1:old_number+new_num,2)=293.15;
                app.UITable2_6.Data(old_number+1:old_number+new_num,3)=0.88;
                app.UITable2_6.Data(old_number+1:old_number+new_num,4)=0.88;
                app.UITable2_6.Data(old_number+1:old_number+new_num,5)=0.88;
                app.UITable2_6.Data(old_number+1:old_number+new_num,6)=0.88;               
                app.UITable2_6.Data(old_number+1:old_number+new_num,7)=0.88;
                app.UITable2_6.Data(old_number+1:old_number+new_num,8)=0.88;
                app.UITable2_6.Data(old_number+1:old_number+new_num,9)=173;
                app.UITable2_6.Data(old_number+1:old_number+new_num,10)=2810;
                app.UITable2_6.Data(old_number+1:old_number+new_num,11)=900;
                app.UITable2_6.Data(old_number+1:old_number+new_num,12)=2.5;
                app.UITable2_6.Data(old_number+1:old_number+new_num,13)=-40;
                app.UITable2_6.Data(old_number+1:old_number+new_num,14)=50;
            end

            app.okLamp_6.Color=[0 1 0];

        end

        % Button pushed function: CalculateViewFactorButton
        function CalculateViewFactorButtonPushed(app, event)
    	    
            addpath Function\

            id=1:1:length(app.name_item);
            app.IDListBox_3.Items=app.name_item; %all input, GMM
            app.IDListBox_3.ItemsData=id; %all input, GMM
            app.IDListBox_2.Items=app.name_item; %all input, TMM
            app.IDListBox_2.ItemsData=id; %all input, TMM

            app.IDListBox_3.Visible=1;
            app.ShowIDButton_3.Visible=1;
            app.VisibilitySlider_3.Visible=1;
            app.VisibilitySlider_3Label.Visible=1;

            %% Waitbar

            % ######## change processDataButtonHandle with button name #########

            % Store original button text
            originalButtonText = app.CalculateViewFactorButton.Text;
            % When this callback function ends, return the original button state (or not)
            % cleanup = onCleanup(@()set(app.CalculateViewFactorButton,'Text',originalButtonText,'Icon',''));
            cleanup = onCleanup(@()set(app.CalculateViewFactorButton,'Text','View Factor calculation completed','Icon',''));
            % Change button text to "Processing"
            app.CalculateViewFactorButton.Text = 'Preparing Surfaces for MC Ray Tracing...';
            % Put text on top of icon
            app.CalculateViewFactorButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.CalculateViewFactorButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar in the button
            app.CalculateViewFactorButton.Icon = wbar;
            drawnow()
            %% MC RAY TRACING

            app.UIAxes2_2.Visible=1;
            cla(app.UIAxes2_2);
            
            % generating Geometric Math Model
            [app.sat,C] = GMM4(app.sat);
            % calculating mechanical properties
            [app.sat] = mech_prop(app.sat);
            % update GMM plot (remove this line if not necessary)
            plot_GMM4(app.sat,app.UIAxes2_2);
            % generating surfaces for MC Ray Tracing
            [app.sat,surf_for_MCRT_int,surf_for_MCRT_ext] = surf_global(app.sat);
            % calculating optical properties
            [app.sat,eps_for_Ge] = opt_prop(app.sat);
%             % Monte Carlo Ray Tracing3
            [Vf_int,~] = MC_ray_tracing3(surf_for_MCRT_int,app.sat,app.CalculateViewFactorButton);
            if app.sat.geom.Nsp>0
              [Vf_ext,~] = MC_ray_tracing3(surf_for_MCRT_ext,app.sat,app.CalculateViewFactorButton);
              Vf=Vf_int+Vf_ext;
            else
             Vf=Vf_int;
            end
%             [Vf_int,~] = MC_ray_tracing(surf_for_MCRT_int,app.sat,app.CalculateViewFactorButton);
%             if app.sat.geom.Nsp>0
%                [Vf_ext,~] = MC_ray_tracing(surf_for_MCRT_ext,app.sat,app.CalculateViewFactorButton);
%                Vf=Vf_int+Vf_ext;            
%              else
%                  Vf=Vf_int;
%              end

            [Vf_G]=Gebhart2(Vf,eps_for_Ge,app.sat);
            app.Lamp.Color=[0 1 0];
            app.GenerateTMMButton.Enable='on';

            sat = app.sat; %#ok<ADPROPLC>
            % N_in=app.N_in;
            % N_int_in=app.N_int_in; 

            % table data
            Table_Structure_Geom = app.UITable1_ex.Data;
            Table_Structure_Prop = app.UITable2_ex.Data;
            Table_SolarP_Geom = app.UITable_2.Data;
            Table_SolarP_Prop = app.UITable2_3.Data;
            Table_Board_Geom = app.UITable_3.Data;
            Table_Board_Prop = app.UITable2_4.Data;
            Table_Parall_Geom = app.UITable_4.Data;
            Table_Parall_PropM = app.UITable2_5.Data;
            Table_Parall_PropO = app.UITable2_7.Data;
            Table_Cyl_Geom = app.UITable_5.Data;
            Table_Cyl_Prop = app.UITable2_6.Data;
            names = app.name_item;

            save GMM_complete.mat sat Vf Vf_G C eps_for_Ge ...
                Table_Structure_Geom Table_Structure_Prop Table_SolarP_Geom Table_SolarP_Prop ...
                Table_Board_Geom Table_Board_Prop Table_Parall_Geom Table_Parall_Geom ...
                Table_Parall_PropM Table_Parall_PropO Table_Cyl_Geom Table_Cyl_Prop ...
                names

            if isfile("GMM_temporary.mat")    
                delete GMM_temporary.mat
            end

            app.TMMandDissipationMenu.Enable="on";

        end

        % Button pushed function: DeleteItemButton
        function DeleteItemButtonPushed(app, event)
            app.UITable_2.Data(end,:) = [];
            app.UITable2_3.Data(end,:)=[];            
            app.SolarPanelsSpinner.Value=size(app.UITable2_3.Data,1);
        end

        % Button pushed function: DeleteItemButton_2
        function DeleteItemButton_2Pushed(app, event)
            app.UITable_3.Data(end,:)=[];
            app.UITable2_4.Data(end,:)=[];
            app.BoardsSpinner.Value=size(app.UITable2_4.Data,1);
        end

        % Button pushed function: DeleteItemButton_3
        function DeleteItemButton_3Pushed(app, event)
            app.UITable_4.Data(end,:)=[];
            app.UITable2_5.Data(end,:)=[];
            app.UITable2_7.Data(end,:)=[];
            app.ParallelepipedsSpinner.Value=size(app.UITable2_5.Data,1);
        end

        % Button pushed function: DeleteItemButton_4
        function DeleteItemButton_4Pushed(app, event)
           app.UITable_5.Data(end,:)=[];
           app.UITable2_6.Data(end,:)=[];
           app.CylindersSpinner.Value=size(app.UITable2_6.Data,1);
        end

        % Button pushed function: ConfirmpropertyselectionButton
        function ConfirmpropertyselectionButtonPushed(app, event)
           
            N_str_face = length(app.sat.geom.ext.face); % number of faces of the structure (equal to 6)

            optical_checkbox = zeros(N_str_face*3,1);
            optic_prop_sel = app.Tree2.CheckedNodes;
            id_checked_node = [];
            if ~isempty(optic_prop_sel)
                id_checked_node = [optic_prop_sel.NodeData];
                optical_checkbox(id_checked_node) = 1;
            end
            optical_checkbox = reshape(optical_checkbox,[],3); % 18x1 -> 6x3

            app.opt_st.face_alpha_opt = optical_checkbox(:,1); % checkbox
            app.opt_st.face_eps_int_opt = optical_checkbox(:,2); % checkbox
            app.opt_st.face_eps_ext_opt = optical_checkbox(:,3); % checkbox

            var_names = ["Alpha ", "Eps_int ", "Eps_ext "];
            face_names = ["Y-", "X+", "Y+", "X-", "Z-", "Z+"];
            var_name_table = string(zeros(length(id_checked_node),1));
            for i=1:1:length(id_checked_node)
                id1 = floor((id_checked_node(i)-1)/N_str_face) + 1;
                id2 = rem(id_checked_node(i),N_str_face);
                if id2 == 0
                    id2 = N_str_face;
                end
                var_name_table(i) = var_names(id1) + face_names(id2); % string concatenation
            end
            app.UITable8.Data = table(var_name_table,zeros(length(id_checked_node),1),ones(length(id_checked_node),1));
            app.UITable8.Visible = 1;
            app.UITable8.ColumnEditable = [false, true, true];
            app.Lamp_6.Color=[0 1 0];

        end

        % Button pushed function: ConfirmheaterselectionButton
        function ConfirmheaterselectionButtonPushed(app, event)

            N_item = length(unique([app.sat.node.globe.ID_item])); % number of item
            
            app.opt_st.item_heater_hot = zeros(1,N_item);
            h_hot_sel = app.Tree3_3.CheckedNodes;
            if ~isempty(h_hot_sel)
                id_h_hot = [h_hot_sel.NodeData];
                app.opt_st.item_heater_hot(id_h_hot) = 1; % checkbox
            else % empty selection
                id_h_hot = [];
            end

            app.opt_st.item_heater_cold = zeros(1,N_item);
            h_cold_sel = app.Tree3_4.CheckedNodes;
            if ~isempty(h_cold_sel)
                id_h_cold = [h_cold_sel.NodeData];            
                app.opt_st.item_heater_cold(id_h_cold) = 1; % checkbox
            else % empty selection
                id_h_cold = [];
            end

            if size(id_h_hot,2) > size(id_h_hot,1)
                id_h_hot = id_h_hot';
            end
            if size(id_h_cold,2) > size(id_h_cold,1)
                id_h_cold = id_h_cold';
            end

            name_item_array = [app.name_item];

            if app.ItemwithheaterPanel_2.Visible == 0 % val cases == 1
                app.UITable10.ColumnName = ["ID item", "Item name", "Power lower bound [W]", "Power upper bound [W]"];

                app.UITable10.Data = table(id_h_hot,name_item_array(id_h_hot),zeros(length(id_h_hot),1),4*ones(length(id_h_hot),1));
                app.UITable10.ColumnEditable = [false, false, true, true];
                app.UITable10.Visible = 1;

            else % val_cases == 2
                app.UITable10.ColumnName = ["ID item", "Item name", "Power lower bound (hot case) [W]", "Power upper bound (hot case) [W]"];
                app.UITable10_2.ColumnName = ["ID item", "Item name", "Power lower bound (cold case) [W]", "Power upper bound (cold case) [W]"];
                
                app.UITable10.Data = table(id_h_hot,name_item_array(id_h_hot),zeros(length(id_h_hot),1),4*ones(length(id_h_hot),1));
                app.UITable10_2.Data = table(id_h_cold,name_item_array(id_h_cold),zeros(length(id_h_cold),1),4*ones(length(id_h_cold),1));
                app.UITable10.ColumnEditable = [false, false, true, true];
                app.UITable10_2.ColumnEditable = [false, false, true, true];
                app.UITable10.Visible = 1;
                app.UITable10_2.Visible = 1;

            end            
            app.Lamp_5.Color=[0 1 0];
            drawnow

        end

        % Button pushed function: ConfirmStartOptimizationButton
        function ConfirmStartOptimizationButtonPushed(app, event)

            if all(app.Lamp_6.Color == [1 0 0]) || all(app.Lamp_5.Color == [1 0 0])
                msgbox("Please click on 'Confirm property selection' and 'Confirm heater selection' before starting the optimization")
                return
            end
            if ~exist("transient_result.mat","file")
                fig3 = uifigure;
                uialert(fig3,"Cannot find transient_result.mat file",'Error');
                return
            end

            app.Lamp_7.Color=[1 0 0];

            addpath Function
            addpath opt_function\
            addpath opt_function\MOPSO
            addpath opt_function\GODLIKE
            addpath opt_function\RSA
            addpath opt_function\NSGA-III
            addpath opt_function\MOEA-D
            addpath opt_function\RVEA
            addpath opt_function\MOGWO
            addpath results\trans\
            addpath results\          
            load transient_result.mat sat val_cases r_dc v_dc attitude env sim_data orbit rsun Vf Vf_G G_c G_hc diss_mat_tot
            app.sat = sat;
            N_str_face = length(app.sat.geom.ext.face); % number of faces of the structure (equal to 6)
            app.opt_st.N_item = length(unique([app.sat.node.globe.ID_item])); % number of item
            app.opt_st.rob = 0; % robustness analysis not done yet

            %% INPUTS

            % 1 = MOEA-D; 2 = RVEA; 3 = MOPSO; 4 = RSA; 5 = NGSA-III; 6 = NGSA-II; 7 = MOGWO;
            alg_name_menu = app.OptimizationalgorithmDropDown.Value;
            alg_name_list = ["MOEA-D", "RVEA", "MOPSO", "RSA", "NGSA-III", "NGSA-II", "MOGWO"];
            algorithm_id = find(alg_name_list == alg_name_menu);

            if app.PlotobjectivespaceduringoptimizationCheckBox.Value == 1
                opt_verbose = true; % show optimization progression messages
                opt_plot = true; % show optimization progression plots
            else
                opt_verbose = false; % show optimization progression messages
                opt_plot = false; % show optimization progression plots
            end
            if app.PerformpruningofsolutionduringtransientCheckBox.Value == 1
                app.opt_st.transient_pruning = true; % suggested: false if delta_t is high enough
            else
                app.opt_st.transient_pruning = false; % suggested: false if delta_t is high enough
            end
            if app.PerformtransientonlyonParetosolutionsCheckBox.Value
                app.opt_st.do_trans_only_pareto = true; % suggested: true
            else
                app.opt_st.do_trans_only_pareto = false; % suggested: true
            end
            if app.SavetransientresultsafteroptimizationCheckBox.Value
                app.opt_st.save_transients = true; % suggested: false
            else
                app.opt_st.save_transients = false; % suggested: false
            end

            max_fun_eval = app.MaxfunctionevaluationsEditField.Value; % suggested 5k to 50k
            population_size = app.PopulationsizeEditField.Value; % suggested 20 to 200
            app.opt_st.delta_t_sim = app.TimesteptransientsEditField.Value; % [s] suggested 5 to 60
            app.opt_st.n_orbit_sim = app.OrbitstransientEditField.Value; % suggested: 2 to 5
            app.opt_st.obj_weight = table2array(app.UITable13.Data(:,2)); % suggested: [1; 1; 1; 1; 1]
            if iscolumn(app.opt_st.obj_weight)
                app.opt_st.obj_weight = (app.opt_st.obj_weight)';
            end

            % ##### Structure optical properties: structure faces order: -Y +X +Y -X -Z +Z #####
            % optical properties lower bounds (lb) and upper bounds (ub)
            alpha_lb = zeros(1,N_str_face);
            eps_int_lb = zeros(1,N_str_face);
            eps_ext_lb = zeros(1,N_str_face);
            alpha_ub = ones(1,N_str_face);
            eps_int_ub = ones(1,N_str_face);
            eps_ext_ub = ones(1,N_str_face);            
            if ~isfield(app.opt_st,"face_alpha_opt")                
                app.opt_st.face_alpha_opt = zeros(1,N_str_face); % checkbox
                app.opt_st.face_eps_int_opt = zeros(1,N_str_face); % checkbox
                app.opt_st.face_eps_ext_opt = zeros(1,N_str_face); % checkbox
            else
                % reading from table
                lb_temp_optical = table2array(app.UITable8.Data(:,2));
                ub_temp_optical = table2array(app.UITable8.Data(:,3));
                N_alpha = sum(app.opt_st.face_alpha_opt);
                N_eps_int = sum(app.opt_st.face_eps_int_opt);
                N_eps_ext = sum(app.opt_st.face_eps_ext_opt);
                alpha_lb(app.opt_st.face_alpha_opt == 1) = lb_temp_optical(1:N_alpha);
                eps_int_lb(app.opt_st.face_eps_int_opt == 1) = lb_temp_optical((N_alpha + 1):(N_alpha + N_eps_int));
                eps_ext_lb(app.opt_st.face_eps_ext_opt == 1) = lb_temp_optical((N_alpha + N_eps_int + 1):(N_alpha + N_eps_int + N_eps_ext));
                alpha_ub(app.opt_st.face_alpha_opt == 1) = ub_temp_optical(1:N_alpha);
                eps_int_ub(app.opt_st.face_eps_int_opt == 1) = ub_temp_optical((N_alpha + 1):(N_alpha + N_eps_int));
                eps_ext_ub(app.opt_st.face_eps_ext_opt == 1) = ub_temp_optical((N_alpha + N_eps_int + 1):(N_alpha + N_eps_int + N_eps_ext));
            end

            % ##### Conductance link: one from each item in the "from" list to items in the "to" list ##### 
            app.opt_st.N_link = app.LinksSpinner.Value; % spinner, numer of conductance links
            if app.opt_st.N_link > 0
                i_from_sel = app.Tree3_2.CheckedNodes;
                id_from_sel = [i_from_sel.NodeData];
                i_to_sel = app.Tree3_2.CheckedNodes;
                id_to_sel = [i_to_sel.NodeData];
            elseif app.opt_st.N_link == 0
                id_from_sel = [];
                id_to_sel = [];
            end
            app.opt_st.item_from_link(id_from_sel) = 1; % checkbox
            app.opt_st.item_to_link(id_to_sel) = 1; % checkbox
            % Cross sectional area of the links [mm^2] lower bounds (lb) and upper bounds (ub)
            A_link_lb = app.Sectionarealowerboundmm2EditField.Value;
            A_link_ub = app.Sectionareaupperboundmm2EditField.Value;
            % Thermal conductivity of the links [W/m/K]
            app.opt_st.k_link = app.ConductivityWmKEditField.Value; % copper conductivity = 385 W/m/K

            % ##### Heaters #####
            % N_heater Heater Hot Power and N_heater Heater Cold Power [W] lower bounds (lb) and upper bounds (ub)
            heater_hot_lb = zeros(1,app.opt_st.N_item);            
            heater_hot_ub = 4*ones(1,app.opt_st.N_item);
            heater_cold_lb = zeros(1,app.opt_st.N_item);
            heater_cold_ub = 4*ones(1,app.opt_st.N_item);
            if ~isfield(app.opt_st,"item_heater_hot") || (all([app.opt_st.item_heater_hot, app.opt_st.item_heater_cold] == 0))
                app.opt_st.item_heater_hot = zeros(1,app.opt_st.N_item); % checkbox
                app.opt_st.item_heater_cold = zeros(1,app.opt_st.N_item); % checkbox
            else
                heater_hot_lb = table2array(app.UITable10.Data(:,3))';
                heater_hot_ub = table2array(app.UITable10.Data(:,4))';                
                if app.ItemwithheaterPanel_2.Visible == 1 % if val_cases == 2
                    heater_cold_lb = table2array(app.UITable10_2.Data(:,3))';
                    heater_cold_ub = table2array(app.UITable10_2.Data(:,4))';
                end
            end

            %% Setup for the optimization

            [app.opt_st, max_generation, app.sat, lb, ub] = setup_var3(...
                app.opt_st, max_fun_eval, population_size, app.sat,...
                alpha_lb, eps_int_lb, eps_ext_lb, A_link_lb, heater_hot_lb, heater_cold_lb,...
                alpha_ub, eps_int_ub, eps_ext_ub, A_link_ub, heater_hot_ub, heater_cold_ub, val_cases);


            %% Environmental Heat Source Preparation

            % Environmental analysis
            old_text = "Confirm & Start Optimization";
            app.ConfirmStartOptimizationButton.Text = 'Environmental analysis...';
            drawnow
            disp('Environmental analysis...');
            [app.sat,a] = Environment_analysis3(app.sat,r_dc,v_dc,attitude,env,rsun,1);
            if isequal(val_cases,2)
                [app.sat,a] = Environment_analysis3(app.sat,r_dc,v_dc,attitude,env,rsun,2);
            end
            
            % Q_source preparation
            app.ConfirmStartOptimizationButton.Text = 'Precomputation Q_source data...';
            drawnow
            disp('Precomputation Q_source data...');
            Source_term_partial_cold = [];
            [Source_term_partial,app.sat] = Q_source3_preprocessing(app.sat,env,r_dc,v_dc,attitude,rsun,a,1,Vf_G); % heat flux partial computation
            if isequal(val_cases,2)
                [Source_term_partial_cold,app.sat] = Q_source3_preprocessing(app.sat,env,r_dc,v_dc,attitude,rsun,a,2,Vf_G); % heat flux partial computation
            end
            
            % eps_for_Ge preparation
            for i = 1:1:app.sat.node.total_node
                app.sat.node.globe(i).n_face = length(app.sat.node.globe(i).face);
            end

            % matrix 6xN (where N = number of nodes);
            % A_node_face(i,j) = radiative area of node j belonging to face i 
            % Used to compute face-averaged structure temperatures:
            % A_node_face*T_st = 6x1 vector of temperature*area
            % (A_node_face*T_st)./A_ext_face = 6x1 vector of face-averaged structure temperatures
            % (A_node_face*T_st)./A_ext_face -> (A_node_face./A_ext_face)*T_st
            % Where A_ext_face is a 6x1 vector of the area of the structure faces
            A_node_face = zeros(N_str_face,app.sat.node.total_node); 
            
            for i=1:1:N_str_face % Hypothesis: structure is always first in sat.geom.surfaces
                for j = 1:1:length(app.sat.geom.surfaces(i).elem) % for every element (node) of surface j...
                    node_id = app.sat.geom.surfaces(i).elem(j).ID;
                    A_node_face(i,node_id) = app.sat.geom.surfaces(i).elem(j).area;
                end
            end
            A_ext_face = [app.sat.geom.surfaces.area];
            A_ext_face = A_ext_face(1:N_str_face); % selecting only structure surfaces
            if size(A_ext_face,2) > size(A_ext_face,1)
                A_ext_face = A_ext_face';
            end
            A_node_face_avg = A_node_face./A_ext_face;

            %% ###### OPTIMIZATION ALGORITHMS ######

            % algorithm_id: 1 = MOEA-D; 2 = RVEA; 3 = MOPSO; 4 = RSA; 5 = NGSA-III; 6 = NGSA-II; 7 = MOGWO;
            
            FitnessFcn = @(x) utility_techrep4(x,Source_term_partial,Source_term_partial_cold,app.sat,Vf,r_dc,G_c,diss_mat_tot,A_node_face_avg,val_cases,app.opt_st);
            
            N_test = 10;
            tic
            for i = 1:1:N_test
                x_test = rand(1,length(lb)).*(ub-lb) + lb;
                val_test = FitnessFcn(x_test);
            end
            time_test = toc;
            predicted_time_opt = (time_test/N_test)*max_fun_eval*1.5; % 50% margin
            app.ConfirmStartOptimizationButton.Text = ["Predicted time of the optimization is " + string(predicted_time_opt) + " s"; "Fitness function average time = " + string(time_test*1000/N_test) + " ms"];
            drawnow
            disp("Predicted time of the optimization is " + string(predicted_time_opt) + " s (fitness function average time = " + string(time_test/N_test) + " s)")            
            tic

            set(app.UIAxes8, 'Visible','on');
            cla(app.UIAxes8)
            view(app.UIAxes8,2)
            
            switch algorithm_id
                case 1
                    %% MOEA-D
                    alg_name = 'MOEA-D';
                    % options structure
                    options = struct;
                    options.nPop = population_size; % Population Size (Number of Sub-Problems)
                    options.nArchive= population_size;
                    options.MaxIt = max([round(max_fun_eval/population_size), 1]); % Maximum Number of Iterations
                    options.gamma = 0.5;
                    options.plot_results = opt_plot;
                    options.verbose = opt_verbose;
                    options.transient_pruning = app.opt_st.transient_pruning;
                    options.ax = app.UIAxes8;
                    % MOEA/D
                    disp('Running MOEA/D...')
                    app.ConfirmStartOptimizationButton.Text = [app.ConfirmStartOptimizationButton.Text; "Running MOEA/D..."];
                    drawnow
                    [x_pareto, fval_pareto, x_everything, fval_everything, ~] = moead(FitnessFcn, lb, ub, options); % suppressed pop_struct output
            
                case 2
                    %% RVEA
                    alg_name = 'RVEA';
                    % options structure
                    options = struct;
                    p1_v = [99 13  7  5  4  3  3  2  3];
                    p2_v = [ 0  0  0  0  1  2  2  2  2];
                    options.p1 = p1_v(app.opt_st.N_objectives-1);
                    options.p2 = p2_v(app.opt_st.N_objectives-1);
                    options.alpha = 2.0;
                    options.FE = 0;
                    options.MaxFE = max_fun_eval;
                    options.ref_vect_adapt_step = 10;
                    options.plot_results = opt_plot;
                    options.verbose = opt_verbose;
                    options.transient_pruning = app.opt_st.transient_pruning;
                    options.ax = app.UIAxes8;
                    % RVEA
                    disp('Running RVEA...')
                    app.ConfirmStartOptimizationButton.Text = [app.ConfirmStartOptimizationButton.Text; "Running RVEA..."];
                    drawnow
                    [x_pareto, fval_pareto, x_everything, fval_everything] = RVEA(FitnessFcn, lb, ub, options);
            
                case 3
                    %% MOPSO
                    alg_name = 'MOPSO';
                    % MOPSO Objective Struct
                    options.fun = FitnessFcn;
                    options.var_min = lb;
                    options.var_max = ub;
                    options.nVar = length(lb);        
                    % MOPSO Parameters
                    options.Np = population_size;        % Population size
                    options.Nr = population_size;        % Repository size
                    options.maxgen = max_generation;    % Maximum number of generations
                    options.W = 0.4;         % Inertia weight
                    options.C1 = 2;          % Individual confidence factor
                    options.C2 = 2;          % Swarm confidence factor
                    options.ngrid = 10;      % Number of grids in each dimension
                    options.maxvel = 5;      % Maxmium vel in percentage
                    options.u_mut = 0.5;     % Uniform mutation percentage
                    options.verbose = opt_verbose;
                    options.plot_results = opt_plot;
                    options.transient_pruning = app.opt_st.transient_pruning;
                    options.ax = app.UIAxes8;
                    % MOPSO
                    disp('Running MOPSO...')
                    app.ConfirmStartOptimizationButton.Text = [app.ConfirmStartOptimizationButton.Text; "Running MOPSO..."];
                    drawnow
                    [x_pareto, fval_pareto, x_everything, fval_everything] = MOPSO(options,options);
            
                case 4
                    %% RSA        
                    alg_name = 'RSA';
                    % options structure
                    options = struct;
                    options.gamma = 2; % multiplication factor of initial archive size
                    options.HL = population_size; % hard limit of the archive
                    options.SL = population_size*2; % soft limit of the archive (suggested: 2 or 3 times HL)
                    options.Tmax = 100; % initial tempereature
                    options.Tmin = 0.000001; % final tempereature
                    options.alpha = 0.99; % factor of decrease in temperature, cooling rate (suggested: 0.99) (AMOSA uses 0.8)
                    options.iter = max([ceil((max_fun_eval - options.SL*options.gamma)./(log(options.Tmin/options.Tmax)/log(options.alpha))),1]); % iterations at each temperature
                    options.max_fe = max_fun_eval;
                    options.do_initial_local_search = false; % (suggested: false)
                    if app.opt_st.N_objectives >= 2 && app.opt_st.N_objectives <= 15
                        suggested_ref_points = [0 100 12 8 6 4 4 3 3 3 2 2 2 2 2];
                        options.ref_point_per_dimension = suggested_ref_points(app.opt_st.N_objectives); % number of reference point in each axis of the nobjs dimensions
                    end
                    % perturbation options
                    options.flag1 = 1; % (suggested: 1) if set to 1 polynomial mutation is performed after differential mutation
                    options.prob1 = 0.1; % (suggested: 0.1) probability of performin laplacian mutation after differential mutation
                    options.flag2 = 1; % (suggested: 1) if set to 1 polynomial mutation is performed after Simulated Binary Crossover (SBX) mutation
                    options.prob2 = 0.1; % (suggested: 0.1) probability of performin laplacian mutation after Simulated Binary Crossover (SBX) mutation
                    options.switch_factor = 0.5; % (suggested: 0.5) fraction of iteration where Simulated Binary Crossover (SBX) mutation is performed, in the other iteration differential mutation is used instead
                    % behaviour options
                    options.plot_results = opt_plot;
                    options.plot_rejected = false; % not editable
                    options.verbose = opt_verbose;
                    options.transient_pruning = app.opt_st.transient_pruning;
                    options.ax = app.UIAxes8;
                    % RSA
                    disp('Running RSA...')
                    app.ConfirmStartOptimizationButton.Text = [app.ConfirmStartOptimizationButton.Text; "Running RSA..."];
                    drawnow
                    [x_pareto, fval_pareto, x_everything, fval_everything] = RSA(FitnessFcn, lb, ub, options);
                    
                case 5
                    %% NGSA-III        
                    alg_name = 'NSGA-III';
                    % options structure
                    options = struct;
                    if app.opt_st.N_objectives >= 2 && app.opt_st.N_objectives <= 15
                        suggested_ref_points = [0 100 12 8 6 4 4 3 3 3 2 2 2 2 2];
                        options.nDivision = suggested_ref_points(app.opt_st.N_objectives); % number of reference point in each axis of the nobjs dimensions
                    end
                    options.nPop = population_size;  % Population Size
                    options.pCrossover = 0.5;       % Crossover Percentage
                    options.pMutation = 0.5;       % Mutation Percentage
                    options.mu = 0.02;     % Mutation Rate
                            nCrossover = 2*round(options.pCrossover*options.nPop/2); % Number of Parnets (Offsprings)
                    nMutation = round(options.pMutation*options.nPop);  % Number of Mutants
                    options.MaxIt = max([round(max_fun_eval/(nMutation + nCrossover)), 1]);
                    options.plot_results = opt_plot;
                    options.verbose = opt_verbose;
                    options.transient_pruning = app.opt_st.transient_pruning;
                    options.ax = app.UIAxes8;
                    % NSGA-III
                    disp('Running NSGA-III...')
                    app.ConfirmStartOptimizationButton.Text = [app.ConfirmStartOptimizationButton.Text; "Running NSGA-III..."];
                    drawnow
                    [x_pareto, fval_pareto, x_everything, fval_everything, ~] = nsga3(FitnessFcn, lb, ub, options); % suppressed pop_struct output
            
                case 6
                    %% NGSA-II (gamultiobj)
                    alg_name = 'Gamultiobj';
                    % options
                    PopulationSize = population_size;
                    varnum_gamultiobj = length(lb);
                    parallel_gamultiobj = false;
                    plot_fcn = @(options,state,flag) custom_gamultiobj_plot(options,state,flag,app.UIAxes8);
                    options = optimoptions('gamultiobj','Display','iter',...   
                        'Generations',max_generation,'PopulationSize',PopulationSize, ...
                        'Vectorized','off','PlotFcn',plot_fcn,'UseParallel',parallel_gamultiobj);
                    if opt_verbose && opt_plot
                        options = optimoptions('gamultiobj','Display','iter',...   
                            'Generations',max_generation,'PopulationSize',PopulationSize, ...
                            'Vectorized','off','PlotFcn',plot_fcn,'UseParallel',parallel_gamultiobj);
                    elseif opt_verbose
                         options = optimoptions('gamultiobj','Display','iter',...   
                            'Generations',max_generation,'PopulationSize',PopulationSize, ...
                            'Vectorized','off','UseParallel',parallel_gamultiobj);
                    elseif opt_plot
                         options = optimoptions('gamultiobj',...   
                            'Generations',max_generation,'PopulationSize',PopulationSize, ...
                            'Vectorized','off','PlotFcn',plot_fcn,'UseParallel',parallel_gamultiobj);
                    else
                         options = optimoptions('gamultiobj',...   
                            'Generations',max_generation,'PopulationSize',PopulationSize, ...
                            'Vectorized','off','UseParallel',parallel_gamultiobj);
                    end                    
                    % Gamultiobj
                    disp('Running Gamultiobj...');
                    app.ConfirmStartOptimizationButton.Text = [app.ConfirmStartOptimizationButton.Text; "Running Gamultiobj..."];
                    drawnow
                    [x_pareto,fval_pareto, ~, ~,  x_everything, fval_everything] = gamultiobj(FitnessFcn,varnum_gamultiobj,[],[],[],[],lb,ub,options);                    
                    
                case 7
                    %% MOGWO
                    alg_name = 'MOGWO';
                    % options structure
                    options = struct;
                    options.GreyWolves_num = population_size;
                    options.MaxIt = max([round(max_fun_eval/population_size), 1]); % Maximum Number of Iterations
                    options.Archive_size = population_size; % Repository Size
                    options.alpha=0.1; % Grid Inflation Parameter
                    options.nGrid=10; % Number of Grids per each Dimension
                    options.beta=4; % Leader Selection Pressure Parameter
                    options.gamma=2; % Extra (to be deleted) Repository Member Selection Pressure
                    options.plot_results = opt_plot;
                    options.verbose = opt_verbose;
                    options.transient_pruning = app.opt_st.transient_pruning;
                    options.ax = app.UIAxes8;
                    % MOGWO
                    disp('Running MOGWO...')
                    app.ConfirmStartOptimizationButton.Text = [app.ConfirmStartOptimizationButton.Text; "Running MOGWO..."];
                    drawnow
                    [x_pareto, fval_pareto, x_everything, fval_everything] = MOGWO(FitnessFcn, lb, ub, options);
                    
                otherwise
                    error("Wrong algorithm_id: algorithm_id must be an integer between 1 and 7")
            end
            
            time_opt = toc;
            disp("Optimization with " + alg_name + " completed in " + string(time_opt) + " seconds.");
            app.ConfirmStartOptimizationButton.Text = ["Optimization with " + alg_name + " completed in " + string(time_opt) + " seconds."; "Starting post-processing"];
            drawnow
            close all            
                        
            %% POST_PROCESSING

            % Converts the design vector in the form utilized by the other pre-existing
            % functions (select_trans, dvectortovalue, create_t_utility...).
            % Then selects the solutions of the Pareto front which satisfy the
            % temperature range constraint and finally compute a utility value based on
            % the distance from optimal temperatures.
            % Results are then saved.

            % Plot Pareto Solutions
            if app.opt_st.N_objectives == 2
                plot(app.UIAxes8,fval_pareto(:,1),fval_pareto(:,2),'ok')
            elseif app.opt_st.N_objectives > 2
                plot3(app.UIAxes8,fval_pareto(:,1),fval_pareto(:,2),fval_pareto(:,3),'ok')
            end
            title(app.UIAxes8,"Final Pareto Solutions");

            % Select solutions
            if app.opt_st.do_trans_only_pareto
                fval_transient = fval_pareto;
                x_transient = x_pareto;
            else
                fval_transient = fval_everything;
                x_transient = x_everything;
            end

            % Destructurize design variables
            [alpha_str,epsint_str,epsext_str,heat,id_node_from,id_node_to,A_link] = dvectortovalue3(x_transient,app.opt_st);            

            % Transients
            disp('Transient analysis...');
            [x_transient, fval_transient,alpha_str,epsint_str,epsext_str,...
                id_node_from,id_node_to,A_link,heat,Tmin_max_st,idx]=select_trans3(...
                x_transient,alpha_str,epsint_str,epsext_str,app.sat,Vf,Vf_G,v_dc,...
                attitude,app.opt_st,sim_data,env,orbit,diss_mat_tot,...
                G_c,G_hc,fval_transient, id_node_from,...
                id_node_to,A_link,heat,val_cases,app.ConfirmStartOptimizationButton);
            
            % Heater total power (for every solution)
            heat_sum_h=[];
            if app.opt_st.N_heater_hot > 0
                heat_h=heat.case1(:,2:end)';
                heat_sum_h=sum(heat_h,2)';

            end            
            heat_sum_c=[];
            if app.opt_st.N_heater_cold > 0
                heat_c=heat.case2(:,2:end)';
                heat_sum_c=sum(heat_c,2)';
            end
            
            % Conductance link total volume (for every solution)
            L_link = zeros(size(id_node_to,1),app.opt_st.N_link); % n_solution x N_link
            Vol_link = zeros(size(id_node_to,1),app.opt_st.N_link); % n_solution x N_link
            for i = 1:1:size(id_node_to,1)
                for j = 1:1:app.opt_st.N_link
                    L_link(i,j) = sum(abs(app.sat.node.globe(id_node_to(i,j)).node - app.sat.node.globe(id_node_from(i,j)).node))*10^-3; % [m]
                    Vol_link(i,j) = L_link(i,j)*A_link(i,j)*(10^-6);
                end
            end            
            Vol_link_sum = sum(Vol_link,2);
            

            if ~isempty(x_transient)
                disp('Computing utility value...');
                app.ConfirmStartOptimizationButton.Text = "Computing utility value...";
                drawnow
                [U,U_T_low_hot,U_T_high_hot,U_T_low_cold,U_T_high_cold,U_H_hot,U_H_cold,U_L,...
                    U_T_low_hot_f,U_T_high_hot_f,U_T_low_cold_f,U_T_high_cold_f,U_H_hot_f,U_H_cold_f,U_L_f]=create_t_utility(...
                    app.sat,app.opt_st,Tmin_max_st,heat_sum_h,heat_sum_c,Vol_link_sum,val_cases);              
    
                % app.ConfirmStartOptimizationButton.Text = old_text;
                app.ConfirmStartOptimizationButton.Text = ["Optimization successfully completed","Saving..."];
                drawnow

            else
                app.ConfirmStartOptimizationButton.Text = ["Optimization completed","No acceptable transient results found","Saving..."];
                drawnow
            end

            %% PLOT TABLE & AXES

            app.AllsolutionsPanel.Visible = 1;
            app.UIAxes9.Visible = 1;
            app.ExportAllsolutionstabulardatatospreadsheetButton.Visible=1;
            app.RobustnessanalysissettingsPanel.Visible=1;

            % ##### Result Table #####
            % ID
            col_n = "ID";
            tdata = num2cell((1:size(fval_transient,1))');
            % Non-dominated
            col_n = [col_n, "Non-dominated"];
            tdata = [tdata, num2cell(ismember(x_transient,x_pareto,'rows'))];
            % Utility
            col_n = [col_n, "Utility"];
            tdata = [tdata, num2cell(U)];
            % Objectives
            col_n = [col_n, "Objective " + string(1:sum(app.opt_st.objective_active == 1)) + ": " + app.opt_st.obj_name'];
            tdata = [tdata, num2cell(fval_transient)];
            % Optical Prop   
            if length(table2array(app.UITable8.Data(:,1))') > 1
                col_n = [col_n, table2array(app.UITable8.Data(:,1))'];
                tdata = [tdata, num2cell(alpha_str), num2cell(epsint_str), num2cell(epsext_str)];
            end
            % Links
            for i=1:1:app.opt_st.N_link
                col_n = [col_n, "Node start (Link "+string(i)+")"]; %#ok<AGROW> 
                tdata = [tdata, num2cell(id_node_from(:,i))]; %#ok<AGROW> 
                col_n = [col_n, "Node end (Link "+string(i)+")"]; %#ok<AGROW> 
                tdata = [tdata, num2cell(id_node_to(:,i))]; %#ok<AGROW> 
                col_n = [col_n, "Section (Link "+string(i)+") [mm^2]"]; %#ok<AGROW> 
                tdata = [tdata, num2cell(A_link(:,i))]; %#ok<AGROW> 
            end
            % Heater hot            
            heat_hot_names = app.name_item(app.opt_st.item_heater_hot == 1)';
            heat_cold_names = app.name_item(app.opt_st.item_heater_cold == 1)';
            if app.opt_st.N_heater_hot > 0
                if val_cases == 1
                    col_n = [col_n, "Heater avg power [W] (" + heat_hot_names + ")"];
                    tdata = [tdata, num2cell(heat.case1(:,2:end)')];
                else
                    col_n = [col_n, "Heater avg power (hot case) [W] (" + heat_hot_names + ")"];
                    tdata = [tdata, num2cell(heat.case1(:,2:end)')];
                end
            end
            if app.opt_st.N_heater_cold > 0
                col_n = [col_n, "Heater avg power (cold case) [W] (" + heat_cold_names + ")"];
                tdata = [tdata, num2cell(heat.case2(:,2:end)')];
            end
            % Utility (T)emperature
            if val_cases == 1
                col_n = [col_n, "Utility Low Temp", "Utility High Temp"];
                tdata = [tdata, num2cell(U_T_low_hot), num2cell(U_T_high_hot)];
            else
                col_n = [col_n, "Utility Low Temp (hot case)", "Utility High Temp (hot case)", "Utility Low Temp (cold case)", "Utility High Temp (cold case)"];
                tdata = [tdata, num2cell(U_T_low_hot), num2cell(U_T_high_hot), num2cell(U_T_low_cold), num2cell(U_T_high_cold)];
            end
            % Utility (H)eater
            if ~isempty(U_H_hot)
                if val_cases == 1
                    col_n = [col_n, "Utility Heater"];
                    tdata = [tdata, num2cell(U_H_hot)];
                else
                    col_n = [col_n, "Utility Heater (hot case)"];
                    tdata = [tdata, num2cell(U_H_hot)];
                end
            end
            if ~isempty(U_H_cold)
                col_n = [col_n, "Utility Heater (cold case)"];
                tdata = [tdata, num2cell(U_H_cold)];
            end
            % Utility (L)ink
            if ~isempty(U_L)
                col_n = [col_n, "Utility Link"];
                tdata = [tdata, num2cell(U_L)];
            end
            % Standard deviation Utility
            col_n = [col_n, "Standard deviation Utility"];
            tdata = [tdata, num2cell(zeros(size(U)))];
            % TABLE CREATION
            app.UITable12.Data = [];
            app.UITable12.Data = cell2table(tdata);
            app.UITable12.ColumnName = col_n;
            app.UITable12.ColumnSortable = true;

            % ##### Utility Plot #####
            cla(app.UIAxes9)
            plot(app.UIAxes9, 1:size(fval_transient,1), U, 'p','markerFaceColor','k')
            hold(app.UIAxes9,"on")
            [max_U, max_U_id] = max(U);
            plot(app.UIAxes9, max_U_id, max_U,'or','MarkerSize',10)
            grid(app.UIAxes9,"on")
            legend(app.UIAxes9,"All Solutions","Maximum Utility Solution","Location","southeast")
            text(app.UIAxes9, (1:size(fval_transient,1))-0.2, U-0.03, string(1:size(fval_transient,1)))
            xlim(app.UIAxes9, [0, size(fval_transient,1)+1])
            ylim(app.UIAxes9, [min(U)-0.2, max_U+0.1])
            
            % Saving
            opt_st = app.opt_st;
            sat = app.sat;
            names = app.name_item;
            disp('Saving results...');
            app.ConfirmStartOptimizationButton.Text = "Saving results";
            drawnow
            save optimization_result.mat A_link	A_node_face A_node_face_avg	alg_name alg_name_menu algorithm_id ...
                alpha_str attitude col_n diss_mat_tot env epsext_str epsint_str fval_everything fval_pareto fval_transient ...
           	    G_c G_hc heat heat_cold_names heat_hot_names id_from_sel id_node_from id_node_to id_to_sel idx L_link lb...
            	N_str_face names node_id opt_st orbit r_dc rsun sat sim_data Source_term_partial Source_term_partial_cold tdata time_opt ...
                Tmin_max_st U U_H_cold U_H_hot U_L U_T_high_cold U_T_high_hot U_T_low_cold U_T_low_hot ...
                U_T_low_hot_f U_T_high_hot_f U_T_low_cold_f U_T_high_cold_f U_H_hot_f U_H_cold_f U_L_f ...
                ub v_dc val_cases val_test Vf Vf_G Vol_link x_everything x_pareto x_test x_transient


            app.ConfirmStartOptimizationButton.Text = "Optimization and Saving completed!";
            drawnow
            app.Lamp_7.Color=[0 1 0];

            pause(1);

            app.TabGroup4.SelectedTab = app.ResultsTab; % go to results tab
           
        end

        % Value changed function: SolarPanelsSpinner
        function SolarPanelsSpinnerValueChanged(app, event)
            app.okLamp_3.Color=[1 0 0];
        end

        % Value changed function: BoardsSpinner
        function BoardsSpinnerValueChanged(app, event)
            app.okLamp_4.Color=[1 0 0];            
        end

        % Value changed function: ParallelepipedsSpinner
        function ParallelepipedsSpinnerValueChanged(app, event)
            app.okLamp_5.Color=[1 0 0];                  
        end

        % Value changed function: CylindersSpinner
        function CylindersSpinnerValueChanged(app, event)
            app.okLamp_6.Color=[1 0 0];             
        end

        % Value changed function: LinksSpinner
        function LinksSpinnerValueChanged(app, event)
            value = app.LinksSpinner.Value;
            if value > 0
                app.StartingitemgroupofthelinksPanel.Visible=1;
                app.EndingitemgroupofthelinksPanel.Visible=1;
                app.Sectionareaupperboundmm2EditField.Visible=1;
                app.Sectionareaupperboundmm2EditFieldLabel.Visible=1;
                app.Sectionarealowerboundmm2EditField.Visible=1;
                app.Sectionarealowerboundmm2EditFieldLabel.Visible=1;
                app.ConductivityWmKEditField.Visible=1;
                app.ConductivityWmKEditFieldLabel.Visible=1;                
            else
                app.StartingitemgroupofthelinksPanel.Visible=0;
                app.EndingitemgroupofthelinksPanel.Visible=0;
                app.Sectionareaupperboundmm2EditField.Visible=0;
                app.Sectionareaupperboundmm2EditFieldLabel.Visible=0;
                app.Sectionarealowerboundmm2EditField.Visible=0;
                app.Sectionarealowerboundmm2EditFieldLabel.Visible=0;
                app.ConductivityWmKEditField.Visible=0;
                app.ConductivityWmKEditFieldLabel.Visible=0;   
            end

        end

        % Button pushed function: RunSimulationButtonRef
        function RunSimulationButtonRefPushed(app, event)
            addpath Function\
            if ~exist("all_input.mat","file")
                    fig3 = uifigure;
                    uialert(fig3,"Cannot find all_input.mat file",'Error');
                    return
            end
            load all_input.mat

            app.LampRef.Color = [1 0 0];

            %% take varibles and files 

            ID_opt_solution = app.EditField.Value;
            if ID_opt_solution ~= 0
                if ~exist("optimization_result.mat","file")
                    fig3 = uifigure;
                    uialert(fig3,"Cannot find optimization_result.mat file",'Error');
                    return
                end
                load optimization_result.mat
                app.opt_st = opt_st;
                app.sat = sat;
                app.name_item = names;

            end

            %% create GMM
            
            % generating Geometric Math Model
            [app.sat,C] = GMM4(app.sat);
            % calculating mechanical properties
            [app.sat] = mech_prop(app.sat);
            % update GMM plot (remove this line if not necessary)
            plot_GMM4(app.sat,app.UIAxes2_2);
            % generating surfaces for MC Ray Tracing
            [app.sat,surf_for_MCRT_int,surf_for_MCRT_ext] = surf_global(app.sat);
            % calculating optical properties
            [app.sat,~] = opt_prop(app.sat);   

            %% optical properties from optimization

            if ID_opt_solution ~= 0
                % Reading optical properties input and saving them in the sat structure
                % Hypothesis: structure faces are the first 6 in sat.geom.surfaces
                for j=1:1:length(app.sat.geom.ext.face) % for every surface of the structure (ext)...
                    % only sat.geom.surfaces is modified, sat.node.globe is not updated
                    if app.opt_st.face_alpha_opt(j) == 1
                        app.sat.geom.surfaces(j).prop_opt(1) = alpha_str(ID_opt_solution,j);
                    end
                    if app.opt_st.face_eps_int_opt(j) == 1
                        app.sat.geom.surfaces(j).prop_opt(2) = epsint_str(ID_opt_solution,j);
                    end
                    if app.opt_st.face_eps_ext_opt(j) == 1
                        app.sat.geom.surfaces(j).prop_opt(3) = epsext_str(ID_opt_solution,j);
                    end
                end
            end

            %% MCRT reflection
            app.PreparationLabelRef.Text='MC Ray Tracing with Reflections';
            pause(0.001);

            % waitbar
            ButtonHandle = app.RunSimulationButtonRef;
            % Store original button text
            originalButtonText = ButtonHandle.Text;
            % When this callback function ends, return the original button state (or not)
            cleanup = onCleanup(@()set(ButtonHandle,'Text',originalButtonText,'Icon',''));
            % Change button text to "Processing"
            ButtonHandle.Text = 'Starting MC Ray Tracing with Reflections...';
            % Put text on top of icon
            ButtonHandle.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(ButtonHandle.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar in the button
            ButtonHandle.Icon = wbar;
            drawnow

            % Monte Carlo Ray Tracing
%             [Vf,~,~] = MC_RT_Reflection(surf_for_MCRT,app.sat,ButtonHandle);

            [Vf_int,~,~] = MC_RT_Reflection(surf_for_MCRT_int,app.sat,ButtonHandle);
            if app.sat.geom.Nsp>0
              [Vf_ext,~,~] = MC_RT_Reflection(surf_for_MCRT_ext,app.sat,ButtonHandle);
              Vf=Vf_int+Vf_ext;
            else
              Vf=Vf_int;
            end
            Vf_G = Vf;

            set(ButtonHandle,'Text','MC Ray Tracing with Reflections completed','Icon','');
            drawnow

            %% take diss_mat_tot from optimization

            if ID_opt_solution ~= 0
                % Assigning heater power to every item specified in the optimization, for
                % the hot/default case and for the cold case if val_cases == 2                
                cont = 1;
                for j = find(app.opt_st.item_heater_hot)
                    diss_mat_tot(j,2) = diss_mat_tot(j,2) + heat.case1(cont,ID_opt_solution+1); % kk+1 because first column of heat is the id of the item (legacy data format)
                    cont = cont + 1;
                end
                cont = 1;
                for j = find(app.opt_st.item_heater_cold)
                    diss_mat_tot(j,3) = diss_mat_tot(j,3) + heat.case2(cont,ID_opt_solution+1); % kk+1 because first column of heat is the id of the item (legacy data format)
                    cont = cont + 1;
                end
            end

            %% create TMM
            app.PreparationLabelRef.Text='Preparation (TMM and Dissipation)';
            pause(0.001);
            [app.sat]=Q_dissipation3(app.sat,diss_mat_tot,1);
            val=val_cases;
            if isequal(val,2)
                [app.sat]=Q_dissipation3(app.sat,diss_mat_tot,2);
            end
            if any(diss_nod)
                for i=1:length(diss_nod(:,1))
                    if isequal(val,2)
                        if diss_nod(i,2)>0
                            app.sat.node.Q_diss(diss_nod(i,1),1)=...
                                app.sat.node.Q_diss(diss_nod(i,1),1)+diss_nod(i,2);
                        end
                        if diss_nod(i,3)>0
                            app.sat.node.Q_diss_cold(diss_nod(i,1),1)=...
                                app.sat.node.Q_diss_cold(diss_nod(i,1),1)+diss_nod(i,3);
                        end
                        
                    else
                        if diss_nod(i,2)>0
                            app.sat.node.Q_diss(diss_nod(i,1),1)=...
                                app.sat.node.Q_diss(diss_nod(i,1),1)+diss_nod(i,2);
                        end
                    end
                    
                end
            end
            sigma=5.67e-8;
            [G_c,G0_Irr,G_hc] = TMM2(app.sat,sigma,Vf_G,C,eps_for_Ge);
            if any(data_c)
                for i=1:length(data_c(:,1))
                    k_int=data_c(i,3);
                    a_int=data_c(i,4);
                    l_int=data_c(i,5)/2;
                    G_int=k_int*a_int/l_int;
                    G_c(data_c(i,1),data_c(i,2))=(2*1/G_int)^-1;
                    G_c(data_c(i,2),data_c(i,1))=G_c(data_c(i,1),data_c(i,2));
                end
            end

            %% straps from optimization

            if ID_opt_solution ~= 0
                for j=1:1:app.opt_st.N_link
                    % taxicab distance for thermal links is more conservative than euclidean distance:
                    L_link = sum(abs(app.sat.node.globe(id_node_to(ID_opt_solution,j)).node - app.sat.node.globe(id_node_from(ID_opt_solution,j)).node))*10^-3; % [m]
                    if L_link ~= 0
                        G_link = app.opt_st.k_link*A_link(ID_opt_solution,j)*(10^(-6))/L_link;
                    else
                        G_link = 0;
                    end
                    G_c(id_node_from(ID_opt_solution,j),id_node_to(ID_opt_solution,j)) = G_c(id_node_from(ID_opt_solution,j),id_node_to(ID_opt_solution,j))+G_link;
                    G_c(id_node_to(ID_opt_solution,j),id_node_from(ID_opt_solution,j)) = G_c(id_node_to(ID_opt_solution,j),id_node_from(ID_opt_solution,j,1))+G_link;
                end
            end

            %% run simulation

            app.PreparationLabelRef.Text='Preparation (Environment analysis)';
            pause(0.001);

%             [app.sat a] = Environment_analysis(app.sat,r_dc,v_dc,attitude,env,sun_vers,rsun,1);

            [app.sat,a] = Environment_analysis3(app.sat,r_dc,v_dc,attitude,env,rsun,1);            
            if isequal(val_cases,2)
                app.PreparationLabelRef.Text='Preparation (Environment analysis-case:cold)';
                pause(0.00001)
                [app.sat,a] = Environment_analysis3(app.sat,r_dc,v_dc,attitude,env,rsun,2);
            end
            %app.RunsimulationGauge.Value=10;
            app.PreparationLabelRef.Text='Preparation (Q_source definition)';
            pause(0.001);
            Source_term=struct;
%             [Source_term,app.sat]=Q_source(Source_term,app.sat,env,r_dc,v_dc,attitude,rsun,a,1);
            [Source_term,app.sat]=Q_source3(Source_term,app.sat,env,r_dc,v_dc,attitude,rsun,a,1,Vf_G);
            if isequal(val_cases,2)
                app.PreparationLabelRef.Text='Preparation (Q_source definition-case:cold)';
                pause(0.00001)
                [Source_term,app.sat]=Q_source3(Source_term,app.sat,env,r_dc,v_dc,attitude,rsun,a,2,Vf_G);
            end

            Source_term.all.Qa=repmat(Source_term.Qa(1:end-1,:),sim_data.n_orbit,1);
            Source_term.all.Qs=repmat(Source_term.Qs(1:end-1,:),sim_data.n_orbit,1);
            Source_term.all.Qir=repmat(Source_term.Qir(1:end-1,:),sim_data.n_orbit,1);

            app.sat.node.analysis.all.epsAf_earth=...
                repmat(app.sat.node.analysis.epsAf_earth(:,1:end-1)',sim_data.n_orbit,1)';
            app.sat.node.analysis.all.epsAf_space=...
                repmat(app.sat.node.analysis.epsAf_space(:,1:end-1)',sim_data.n_orbit,1)';
            Q_0=Source_term.all.Qs+Source_term.all.Qa+...
                Source_term.all.Qir;
            Q0_st=diag(mean(Source_term.Qa+Source_term.Qir+Source_term.Qs))+...
                diag(app.sat.node.Q_diss);
            app.PreparationLabelRef.Text='Run Simulation...';
            pause(0.001);
            G_c=G_c-diag(sum(G_c,2));

            [T_st]=Steady_comp(app.sat,Q0_st,G_c,G0_Irr,1);
            T_0=T_st;
            dt=r_dc(2,4)-r_dc(1,4);
            Tempo=zeros((length(r_dc)-1)*sim_data.n_orbit,1);
            [T,Tempo]=transient(app.sat,T_0,dt,r_dc,sim_data,Tempo,G0_Irr,...
                G_c,G_hc,Q_0,1);
            T=T';
            T=T-273.15;
            if isequal(val_cases,2)
                Source_term.all.Qa_cold=repmat(Source_term.Qa_cold(1:end-1,:),sim_data.n_orbit,1);
                Source_term.all.Qs_cold=repmat(Source_term.Qs_cold(1:end-1,:),sim_data.n_orbit,1);
                Source_term.all.Qir_cold=repmat(Source_term.Qir_cold(1:end-1,:),sim_data.n_orbit,1);
                app.sat.node.analysis.all.epsAf_earth_cold=...
                    repmat(app.sat.node.analysis.epsAf_earth_cold(:,1:end-1)',sim_data.n_orbit,1)';
                app.sat.node.analysis.all.epsAf_space_cold=...
                    repmat(app.sat.node.analysis.epsAf_space_cold(:,1:end-1)',sim_data.n_orbit,1)';
                Q_0_cold=Source_term.all.Qs_cold+Source_term.all.Qa_cold+...
                    Source_term.all.Qir_cold;
                Q0_st_cold=diag(mean(Source_term.Qa_cold+Source_term.Qir_cold...
                    +Source_term.Qs_cold))+diag(app.sat.node.Q_diss_cold);
                [T_st_cold]=Steady_comp(app.sat,Q0_st_cold,G_c,G0_Irr,2);
                T_0=T_st_cold;
                [T_cold,Tempo]=transient(app.sat,T_0,dt,r_dc,sim_data,Tempo,G0_Irr,...
                    G_c,G_hc,Q_0_cold,2);
                T_cold=T_cold';
                T_cold=T_cold-273.15;
            end
            app.LampRef.Color=[0 1 0];
            app.PreparationLabel.Text='Simulation completed!';

            for i=1:app.sat.node.total_node
                app.sat.node.globe(i).Temperature_t=T(:,i);
                if isequal(val_cases,2)
                    app.sat.node.globe(i).Temperature_t_cold=T_cold(:,i);
                end
            end 
            app.sat.node.analysis.Vf=Vf;
            %app.sat.node.analysis.Vf_G=Vf_G;
            app.sat.node.analysis.Orbit_data=r_dc;
            app.sat.node.analysis.Source_term=Source_term;
            app.sat.node.analysis.period=r_dc(end,4);
            app.sat.node.analysis.ecl_day.ecl=r_dc((r_dc(:,5)==1),1:4);
            app.sat.node.analysis.ecl_day.day=r_dc((r_dc(:,5)==0),1:4);
            app.sat.node.analysis.sun.vect=rsun;
            app.sat.node.analysis.sun.vers=sun_vers;
            app.sat.node.analysis.time_vect=Tempo;
            app.sat.node.analysis.attitude_evo_angle=a;
            sat=app.sat;
            names = app.name_item;

            save MCRT_reflection.mat

            %% Plot and visualization
            
%           run_simulation_post_processing(app,val_cases,env,v_dc,attitude)

            switch val_cases
                case 1
                    app.WorstcasePanelRef_2.Title='Worst Case';
                    app.WorstcasePanelRef_2.Visible=1;
                    app.WorstcasePanelRef_1.Visible=0;
                case 2
                    app.WorstcasePanelRef_2.Title='Cold Case';
                    app.WorstcasePanelRef_1.Title='Hot Case';
                    app.WorstcasePanelRef_2.Visible=1;
                    app.WorstcasePanelRef_1.Visible=1;
                    
            end

            % Node Trees
            tree=app.TreeRef_2;
            tree_2=app.TreeRef_1;
            if isempty(app.ext)==0
                tree.Children.delete;
                tree_2.Children.delete;
            end
            name_face = {'Face_YM_1_','Face_XP_2_','Face_YP_3_','Face_XM_4_','Face_ZM_5_','Face_ZP_6_'};
            for i = 1:1:6
                app.ext(i).face = uitreenode(tree,'Text',name_face{i},'NodeData',[]);
                if isequal(val_cases,2)
                    app.ext(i).face_c = uitreenode(tree_2,'Text',name_face{i},'NodeData',[]);
                end
            end
            
            N_item = 1 + app.sat.geom.Nsp + app.sat.geom.Nb + app.sat.geom.NP + app.sat.geom.Nc;
            for i=(1 + 1):1:N_item
                %txt=strcat("IntFace_",num2str(i));
                txt=app.name_item(i);
                app.int(i).face=uitreenode(tree,'Text',txt,'NodeData',[]);
                if isequal(val_cases,2)
                    app.int(i).face_c=uitreenode(tree_2,'Text',txt,'NodeData',[]);
                end
            end

            N_str_face = length(app.sat.geom.ext.face); % number of faces of the structure (equal to 6)
            ind_coord_ext(1:N_str_face)=1;
            ind_coord_int(1:N_item)=1;
            for i=1:app.sat.node.total_node
                for face_i=1:1:N_str_face % 
                    if sum(ismember(app.sat.node.globe(i).face,face_i))==1
                        txt_=strcat("Node",num2str(i));
                        p1 = uitreenode(app.ext(face_i).face,'Text',txt_,'NodeData',app.sat.node.globe(i).Temperature_t);
                        app.Res_ext.face(face_i).coord(ind_coord_ext(face_i),:)=app.sat.node.globe(i).node;
                        app.Res_ext.face(face_i).temp(ind_coord_ext(face_i),:)=app.sat.node.globe(i).Temperature_t';
                        if isequal(val_cases,2)
                            p1 = uitreenode(app.ext(face_i).face_c,'Text',txt_,'NodeData',app.sat.node.globe(i).Temperature_t_cold);
                            app.Res_ext_c.face(face_i).coord(ind_coord_ext(face_i),:)=app.sat.node.globe(i).node;
                            app.Res_ext_c.face(face_i).temp(ind_coord_ext(face_i),:)=app.sat.node.globe(i).Temperature_t_cold';
                        end
                        ind_coord_ext(face_i)=ind_coord_ext(face_i)+1;
                    end
                end
                for j=(1 + 1):1:N_item % starting from boards item (internal items)
                    if sum(ismember(app.sat.node.globe(i).ID_item,j))==1
                        txt_=strcat("Node",num2str(i));
                        p1 = uitreenode(app.int(j).face,'Text',txt_,'NodeData',app.sat.node.globe(i).Temperature_t);
                        app.Res_int.face(j).coord(ind_coord_int(j),:)=app.sat.node.globe(i).node;
                        app.Res_int.face(j).temp(ind_coord_int(j),:)=app.sat.node.globe(i).Temperature_t';
                        if isequal(val_cases,2)
                            p1 = uitreenode(app.int(j).face_c,'Text',txt_,'NodeData',app.sat.node.globe(i).Temperature_t_cold);
                            app.Res_int_c.face(j).coord(ind_coord_int(j),:)=app.sat.node.globe(i).node;
                            app.Res_int_c.face(j).temp(ind_coord_int(j),:)=app.sat.node.globe(i).Temperature_t_cold';
                        end
                        ind_coord_int(j)=ind_coord_int(j)+1;
                    end
                end
            end

            % app.Tree.Visible='on';
            app.PlotTemperatureButtonRef_1.Visible='on';
            app.UIAxesRef_1.Visible='on';

            % Heat Map
            app.ix=round(length(app.sat.node.analysis.time_vect)/2); % removed second round()
            [app.handles, ~] = plot_GMM4_heatmap(app.sat,app.UIAxesRef_3,'External',app.ix,1);
            if val_cases == 2
                [app.handles_c, ~] = plot_GMM4_heatmap(app.sat,app.UIAxesRef_5,'External',app.ix,2);
            end

            % Plot Mundi            
            [app.mundi_pl]=plot_mundi(app.sat.node.analysis.Orbit_data,env,2,1,app.UIAxesRef_2,app.sat.node.analysis.sun.vect,app.sat,v_dc,attitude,1);
            % Sliders
            app.TimeminSliderRef_1.MajorTicks=[0 app.sat.node.analysis.time_vect(end)/2/60 app.sat.node.analysis.time_vect(end)/60];
            app.TimeminSliderRef_1.Limits=[0 app.sat.node.analysis.time_vect(end)/60];
            app.TimeminSliderRef_1.Value=app.sat.node.analysis.time_vect(app.ix)/60; %set time slider
            app.TimesEditFieldRef_1.Value=app.sat.node.analysis.time_vect(app.ix); %report Time value in sec
            app.TimeminSliderRef_1.MinorTicks=linspace(0, app.sat.node.analysis.time_vect(end)/60,round((app.sat.node.analysis.time_vect(end)/600)));%step of 10 min
            app.SatellitepositionPanelRef_1.Visible='on';
            app.HeatMapTemperatureCPanelRef_1.Visible='on';
            app.TimeControlPanelRef_1.Visible='on';
            app.TrasparencyButtonGroupRef_1.Visible='on';
            if isequal(val_cases,2)
                app.HeatMapTabRef_1.Title='Heat Map-Hot Case';
                app.HeatMapTabRef_2.Title='Heat Map-Cold Case';
                % Plot Mundi
                % [app.mundi_pl_c]=plot_mundi(app.sat.node.analysis.Orbit_data,env,2,1,app.UIAxes5_2,app.sat.node.analysis.sun.vect);
                [app.mundi_pl_c]=plot_mundi(app.sat.node.analysis.Orbit_data,env,2,1,app.UIAxesRef_4,app.sat.node.analysis.sun.vect,app.sat,v_dc,attitude,2);
                % Sliders
                app.TimeminSliderRef_2.MajorTicks=[0 app.sat.node.analysis.time_vect(end)/2/60 app.sat.node.analysis.time_vect(end)/60];
                app.TimeminSliderRef_2.Limits=[0 app.sat.node.analysis.time_vect(end)/60];
                app.TimeminSliderRef_2.Value=app.sat.node.analysis.time_vect(app.ix)/60; %set time slider
                app.TimesEditFieldRef_2.Value=app.sat.node.analysis.time_vect(app.ix); %report Time value in sec
                app.TimeminSliderRef_2.MinorTicks=linspace(0, app.sat.node.analysis.time_vect(end)/60,round((app.sat.node.analysis.time_vect(end)/600)));%step of 10 min
                app.SatellitepositionPanelRef_2.Visible='on';
                app.HeatMapTemperatureCPanelRef_2.Visible='on';
                app.TimeControlPanelRef_2.Visible='on';
                app.TrasparencyButtonGroupRef_2.Visible='on';
            end

            set(ButtonHandle,'Text',originalButtonText,'Icon','');

        end

        % Button pushed function: PlotTemperatureButtonRef_1
        function PlotTemperatureButtonRef_1Pushed(app, event)
            value = app.CasestudiesSwitch.Value;
            val_cases=str2double(value);
            selectedNode = app.TreeRef_2.SelectedNodes;
            gr=struct;
            face_pl=struct;
            tplot=[];
            name=[];
            for i=1:length(selectedNode)
                if isempty(selectedNode(i).NodeData)
                    for j=1:length(selectedNode(i).Children)
                        face_pl(i).gr(j).pl=plot(app.UIAxesRef_1,app.sat.node.analysis.time_vect,selectedNode(i).Children(j).NodeData);
                        hold(app.UIAxesRef_1,'on')
                        grid(app.UIAxesRef_1,'on')
                        tplot=[tplot, face_pl(i).gr(j).pl];
                        name=[name, convertCharsToStrings(selectedNode(i).Children(j).Text)];
                    end
                else
                    gr(i).pl=plot(app.UIAxesRef_1,app.sat.node.analysis.time_vect,selectedNode(i).NodeData);
                    hold(app.UIAxesRef_1,'on')
                    grid(app.UIAxesRef_1,'on')
                    tplot=[tplot, gr(i).pl];
                    name=[name, convertCharsToStrings(selectedNode(i).Text)];
                end
            end
            if isequal(val_cases,2)
                selectedNode_c=app.TreeRef_1.SelectedNodes;
                gr_c=struct;
                face_pl_c=struct;

                for i=1:length(selectedNode_c)
                    if isempty(selectedNode_c(i).NodeData)
                        for j=1:length(selectedNode_c(i).Children)
                            face_pl_c(i).gr(j).pl=plot(app.UIAxesRef_1,app.sat.node.analysis.time_vect,selectedNode_c(i).Children(j).NodeData);
                            hold(app.UIAxesRef_1,'on')
                            grid(app.UIAxesRef_1,'on')
                            tplot=[tplot, face_pl_c(i).gr(j).pl];
                            name=[name, convertCharsToStrings(selectedNode_c(i).Children(j).Text)+" "+"cold"];
                        end
                    else
                        gr_c(i).pl=plot(app.UIAxesRef_1,app.sat.node.analysis.time_vect,selectedNode_c(i).NodeData);
                        hold(app.UIAxesRef_1,'on')
                        grid(app.UIAxesRef_1,'on')
                        tplot=[tplot, gr_c(i).pl];
                        name=[name, convertCharsToStrings(selectedNode_c(i).Text)+" "+"cold"];
                    end
                end
            end
            legend(app.UIAxesRef_1,tplot,name);
            hold(app.UIAxesRef_1,'off')
        end

        % Value changed function: TimeminSliderRef_1
        function TimeminSliderRef_1ValueChanged(app, event)
            value = app.TimeminSliderRef_1.Value;
            
            [~,app.ix]=min(abs(app.sat.node.analysis.time_vect-value*60));

            % Heat Map
            [app.handles, ~] = plot_GMM4_heatmap(app.sat,app.UIAxesRef_3,app.TrasparencyButtonGroupRef_1.SelectedObject.Text,app.ix,1);

            app.TimesEditFieldRef_1.Value=app.sat.node.analysis.time_vect(app.ix,1);
            kk=app.TimesEditFieldRef_1.Value-floor((app.TimesEditFieldRef_1.Value)/app.sat.node.analysis.period)*app.sat.node.analysis.period;
            r=app.sat.node.analysis.Orbit_data;
            [~,kk]=min(abs(kk-r(:,4)));
            C1 = repmat([0,0,0],numel(r(r(:,5)==1,1)),1);
            C2 = repmat([0,0.70,1],numel(r(r(:,5)==0,1)),1);
            S1 = repmat(5,numel(r(r(:,5)==1,1)),1);
            S2 = repmat(5,numel(r(r(:,5)==0,1)),1);
            if isequal(r(kk,5),1)
                app.mundi_pl.ecl.CData=C1;
                app.mundi_pl.sun.CData=C2;
                app.mundi_pl.ecl.SizeData=S1;
                app.mundi_pl.sun.SizeData=S2;
                app.mundi_pl.ecl.CData(app.sat.node.analysis.ecl_day.ecl(:,4)==r(kk,4),:)=[0.86 0 0.78];
                app.mundi_pl.ecl.SizeData(1,app.sat.node.analysis.ecl_day.ecl(:,4)==r(kk,4))=100;                
            else
                app.mundi_pl.ecl.CData=C1;
                app.mundi_pl.sun.CData=C2;
                app.mundi_pl.ecl.SizeData=S1;
                app.mundi_pl.sun.SizeData=S2;
                app.mundi_pl.sun.CData(app.sat.node.analysis.ecl_day.day(:,4)==r(kk,4),:)=[0.86 0 0.78];
                app.mundi_pl.sun.SizeData(1,app.sat.node.analysis.ecl_day.day(:,4)==r(kk,4))=100;
            end
        end

        % Value changed function: TimeminSliderRef_2
        function TimeminSliderRef_2ValueChanged(app, event)
            value = app.TimeminSliderRef_2.Value;
            
            [~,app.ix]=min(abs(app.sat.node.analysis.time_vect-value*60));
    
            % Heat Map
            [app.handles_c, ~] = plot_GMM4_heatmap(app.sat,app.UIAxesRef_5,app.TrasparencyButtonGroupRef_2.SelectedObject.Text,app.ix,2);            
            
            app.TimesEditFieldRef_2.Value=app.sat.node.analysis.time_vect(app.ix,1);
            kk=app.TimesEditFieldRef_2.Value-floor((app.TimesEditFieldRef_2.Value)/app.sat.node.analysis.period)*app.sat.node.analysis.period;
            r=app.sat.node.analysis.Orbit_data;
            [~,kk]=min(abs(kk-r(:,4)));
            C1 = repmat([0,0,0],numel(r(r(:,5)==1,1)),1);
            C2 = repmat([0,0.70,1],numel(r(r(:,5)==0,1)),1);
            S1 = repmat(5,numel(r(r(:,5)==1,1)),1);
            S2 = repmat(5,numel(r(r(:,5)==0,1)),1);
            if isequal(r(kk,5),1)
                app.mundi_pl_c.ecl.CData=C1;
                app.mundi_pl_c.sun.CData=C2;
                app.mundi_pl_c.ecl.SizeData=S1;
                app.mundi_pl_c.sun.SizeData=S2;
                app.mundi_pl_c.ecl.CData(app.sat.node.analysis.ecl_day.ecl(:,4)==r(kk,4),:)=[0.86 0 0.78];
                app.mundi_pl_c.ecl.SizeData(1,app.sat.node.analysis.ecl_day.ecl(:,4)==r(kk,4))=100;
                
            else
                app.mundi_pl_c.ecl.CData=C1;
                app.mundi_pl_c.sun.CData=C2;
                app.mundi_pl_c.ecl.SizeData=S1;
                app.mundi_pl_c.sun.SizeData=S2;
                app.mundi_pl_c.sun.CData(app.sat.node.analysis.ecl_day.day(:,4)==r(kk,4),:)=[0.86 0 0.78];
                app.mundi_pl_c.sun.SizeData(1,app.sat.node.analysis.ecl_day.day(:,4)==r(kk,4))=100;
            end
        end

        % Selection changed function: TrasparencyButtonGroupRef_2
        function TrasparencyButtonGroupRef_2SelectionChanged(app, event)
            
            selectedButton = app.TrasparencyButtonGroupRef_2.SelectedObject;            
            
            if strcmp(selectedButton.Text,'External')
                for i=1:length(app.handles_c)
                    app.handles_c(i).FaceAlpha=1;
                    
                end
            elseif strcmp(selectedButton.Text,'Both')
                for i=1:length(app.handles_c)
                    app.handles_c(i).FaceAlpha=0.3;
                end
            elseif strcmp(selectedButton.Text,'Internal')
                for i=1:length(app.handles_c)
                    app.handles_c(i).FaceAlpha=0;
                end
            end
            
        end

        % Selection changed function: TrasparencyButtonGroupRef_1
        function TrasparencyButtonGroupRef_1SelectionChanged(app, event)
            selectedButton = app.TrasparencyButtonGroupRef_1.SelectedObject;            
            
            if strcmp(selectedButton.Text,'External')
                for i=1:length(app.handles)
                    app.handles(i).FaceAlpha=1;
                end
            elseif strcmp(selectedButton.Text,'Both')
                for i=1:length(app.handles)
                    app.handles(i).FaceAlpha=0.3;
                end
            elseif strcmp(selectedButton.Text,'Internal')
                for i=1:length(app.handles)
                    app.handles(i).FaceAlpha=0;
                end
            end
           
        end

        % Button pushed function: 
        % ExportAllsolutionstabulardatatospreadsheetButton
        function ExportAllsolutionstabulardatatospreadsheetButtonPushed(app, event)
            if exist("optimization_results.xlsx","file")
                delete("optimization_results.xlsx")
            end
            writetable(app.UITable12.Data,"optimization_results.xlsx","Sheet",1);
            writecell(app.UITable12.ColumnName',"optimization_results.xlsx","Sheet",1,"Range","A1");
            msgbox(["Optimization results saved in:";string(pwd) + "\optimization_results.xlsx"])
        end

        % Button pushed function: StartRobustnessAnalysisButton
        function StartRobustnessAnalysisButtonPushed(app, event)
            if ~exist("optimization_result.mat","file")
                    fig3 = uifigure;
                    uialert(fig3,"Cannot find optimization_result.mat file",'Error');
                    return
            end
            load optimization_result.mat        
            app.sat = sat;
            app.opt_st = opt_st;
            app.name_item = names;
            app.Lamp_8.Color = [1 0 0];

            % Input
            app.opt_st.n_rob_variations = app.PerturbationsforeverytransientsolutionEditField.Value;
            app.opt_st.rob_perturb = app.Maximumrelativevariationofoptimizationparameters01EditField.Value;

            % overwrite pruning setting
            old_transient_pruning = app.opt_st.transient_pruning;
            app.opt_st.transient_pruning = 0;

            U_rob_mat = zeros(length(U),app.opt_st.n_rob_variations);
            FitnessFcn = @(x) utility_techrep4(x,Source_term_partial,Source_term_partial_cold,app.sat,Vf,r_dc,G_c,diss_mat_tot,A_node_face_avg,val_cases,app.opt_st);            
            n_var = size(x_transient,2);

            %% PREPROCESSING

            ROB_STRUCT.sat = app.sat;
            ROB_STRUCT.sim_data = sim_data;

            ROB_STRUCT.Tmin_max_st=struct;
            ROB_STRUCT.T_op=app.opt_st.T_op_item;
            
            % ##### Simulation Data #####
            
            ROB_STRUCT.n_orbit = app.opt_st.n_orbit_sim;
            ROB_STRUCT.sim_data.n_orbit = ROB_STRUCT.n_orbit;
            ROB_STRUCT.time_step_input = app.opt_st.delta_t_sim;
            ROB_STRUCT.sim_data.step = ROB_STRUCT.time_step_input;
            % ROB_STRUCT.time = zeros(length(r_dc)*ROB_STRUCT.n_orbit,1);
            [ROB_STRUCT.r_dc, ~, ~, ~, ROB_STRUCT.rsun, ROB_STRUCT.sun_vers, ~] = Orbit_analysis(env,orbit,ROB_STRUCT.sim_data);
            ROB_STRUCT.time = zeros((length(ROB_STRUCT.r_dc)-1)*ROB_STRUCT.sim_data.n_orbit,1);
            ROB_STRUCT.dt=ROB_STRUCT.r_dc(2,4)-ROB_STRUCT.r_dc(1,4);
            
            % ##### Environmental Heat Source Preparation #####
            
            % Environmental analysis
            [ROB_STRUCT.sat,ROB_STRUCT.a] = Environment_analysis3(ROB_STRUCT.sat,ROB_STRUCT.r_dc,v_dc,attitude,env,ROB_STRUCT.rsun,1);
            if isequal(val_cases,2)
                [ROB_STRUCT.sat,ROB_STRUCT.a] = Environment_analysis3(ROB_STRUCT.sat,ROB_STRUCT.r_dc,v_dc,attitude,env,ROB_STRUCT.rsun,2);
            end
            
            % Q source preprocessing
            [ROB_STRUCT.Source_term_partial,ROB_STRUCT.sat] = Q_source3_preprocessing(ROB_STRUCT.sat,env,ROB_STRUCT.r_dc,v_dc,attitude,ROB_STRUCT.rsun,ROB_STRUCT.a,1,Vf_G); % heat flux partial computation
            if val_cases == 2
                [ROB_STRUCT.Source_term_partial_cold,~] = Q_source3_preprocessing(ROB_STRUCT.sat,env,ROB_STRUCT.r_dc,v_dc,attitude,ROB_STRUCT.rsun,ROB_STRUCT.a,2,Vf_G); % heat flux partial computation
            end

            % ##### Utility linear fits #####
            ROB_STRUCT.U_T_low_hot_f=U_T_low_hot_f;
            ROB_STRUCT.U_T_high_hot_f=U_T_high_hot_f;
            ROB_STRUCT.U_T_low_cold_f=U_T_low_cold_f;
            ROB_STRUCT.U_T_high_cold_f=U_T_high_cold_f;
            ROB_STRUCT.U_H_hot_f=U_H_hot_f;
            ROB_STRUCT.U_H_cold_f=U_H_cold_f;
            ROB_STRUCT.U_L_f=U_L_f;

            %% ROBUSTNESS ANALYSIS LOOP
            for i = 1:1:length(U)
                for j = 1:1:app.opt_st.n_rob_variations
                    app.StartRobustnessAnalysisButton.Text=["Running Robustness Analysis...","Solution "+string(i)+"/"+string(length(U)),"Perturbation "+string(j)+"/"+string(app.opt_st.n_rob_variations)];
                    drawnow                    
                    % Perturbation
                    if app.ApplyperturbationbasedonsolutionparametersButton.Value==1
                        x_rob = x_transient(i,:) + rand(1,n_var).*app.opt_st.rob_perturb.*x_transient(i,:).*ones(1,n_var).^randi(2,1,n_var);
                    elseif app.ApplyperturbationbasedonupperandlowerboundsButton.Value==1
                        x_rob = x_transient(i,:) + rand(1,n_var).*app.opt_st.rob_perturb.*(ub - lb).*ones(1,n_var).^randi(2,1,n_var);
                    end
                    % Regularize x_rob
                    x_rob(x_rob > ub) = ub(x_rob > ub);
                    x_rob(x_rob < lb) = lb(x_rob < lb);
                    % Fitness function
                    f_rob = FitnessFcn(x_rob);                    
                    % Destructurize design variables
                    [alpha_rob,epsint_rob,epsext_rob,heat_rob,id_node_from_rob,id_node_to_rob,A_link_rob] = dvectortovalue3(x_rob,app.opt_st);    
                    % Transient
                    [~, ~, ~, ~, ~,...
                        id_node_from_rob,id_node_to_rob,A_link_rob,heat_rob,Tmin_max_st_rob,idx_rob]=select_trans3(...
                        x_rob,alpha_rob,epsint_rob,epsext_rob,app.sat,Vf,Vf_G,v_dc,...
                        attitude,app.opt_st,sim_data,env,orbit,diss_mat_tot,...
                        G_c,G_hc,f_rob,id_node_from_rob,...
                        id_node_to_rob,A_link_rob,heat_rob,val_cases,[],ROB_STRUCT);                    
                    % Heater total power
                    heat_sum_h_rob=[];
                    if app.opt_st.N_heater_hot > 0
                        heat_h_rob=heat_rob.case1(:,2:end)';
                        heat_sum_h_rob=sum(heat_h_rob,2)'; %#ok<UDIM> 
                    end
                    heat_sum_c_rob=[];
                    if app.opt_st.N_heater_cold > 0
                        heat_c_rob=heat_rob.case2(:,2:end)';
                        heat_sum_c_rob=sum(heat_c_rob,2)'; %#ok<UDIM> 
                    end                    
                    % Conductance link total volume
                    L_link_rob = zeros(size(id_node_to_rob,1),app.opt_st.N_link); % n_solution x N_link
                    Vol_link_rob = zeros(size(id_node_to_rob,1),app.opt_st.N_link); % n_solution x N_link
                    for i2 = 1:1:size(id_node_to_rob,1)
                        for j2 = 1:1:app.opt_st.N_link
                            L_link_rob(i2,j2) = sum(abs(app.sat.node.globe(id_node_to_rob(i2,j2)).node - app.sat.node.globe(id_node_from_rob(i2,j2)).node))*10^-3; % [m]
                            Vol_link_rob(i2,j2) = L_link_rob(i2,j2)*A_link_rob(i2,j2)*(10^-6);
                        end
                    end        
                    Vol_link_sum_rob = sum(Vol_link_rob,2);
                    % Utility value
                    [U_rob,~,~,~,~,~,~,~]=create_t_utility(...
                        app.sat,app.opt_st,Tmin_max_st_rob(idx_rob),heat_sum_h_rob,heat_sum_c_rob,Vol_link_sum_rob,val_cases,ROB_STRUCT);
                    U_rob_mat(i,j) = U_rob;
                end
                
            end
            clear n_var x_rob f_rob alpha_rob epsint_rob epsext_rob heat_rob id_node_from_rob id_node_to_rob A_link_rob ...
                    Tmin_max_st_rob idx_rob heat_sum_h_rob heat_h_rob heat_sum_c_rob heat_c_rob L_link_rob Vol_link_rob Vol_link_sum_rob ROB_STRUCT
            
            sigma_U = std([U, U_rob_mat],[],2); % standard deviation of every solution
            if isrow(sigma_U)
                sigma_U = sigma_U';
            end

            %% Plot and saving

            % error bars
            cla(app.UIAxes9)
            plot(app.UIAxes9, 1:size(fval_transient,1), U, 'p','markerFaceColor','k')
            hold(app.UIAxes9,"on")
            [max_U, max_U_id] = max(U);
            plot(app.UIAxes9, max_U_id, max_U,'or','MarkerSize',10)
            errorbar(app.UIAxes9,1:size(fval_transient,1), U, sigma_U, "LineStyle","none","Color",[0, 0.4470, 0.7410])
            legend(app.UIAxes9,"All Solutions","Maximum Utility Solution","1\sigma Standard Deviation","Location","southeast")
            grid(app.UIAxes9,"on")
            text(app.UIAxes9, (1:size(fval_transient,1))-0.2, U-0.03, string(1:size(fval_transient,1)))
            xlim(app.UIAxes9, [0, size(fval_transient,1)+1])
            ylim(app.UIAxes9, [min(U)-0.2, max_U+0.1])            
            

            % plot 1
            app.UIAxes10.Visible=1;            
            cla(app.UIAxes10)
            plot(app.UIAxes10, 1:size(fval_transient,1), sigma_U, 'p','markerFaceColor','k')
            hold(app.UIAxes10,"on")
            [min_sigma_U, min_sigma_U_id] = min(sigma_U);
            plot(app.UIAxes10, min_sigma_U_id, min_sigma_U,'or','MarkerSize',10)
            legend(app.UIAxes10,"All Solutions","Maximum Robustness Solution","Location","northeast")
            grid(app.UIAxes10,"on")
            text(app.UIAxes10, (1:size(fval_transient,1)), sigma_U, " "+string(1:size(fval_transient,1)))
            xlim(app.UIAxes10, [0, size(fval_transient,1)+1])
            title(app.UIAxes10,"Solution Robustness")

            % plot 2
            app.UIAxes11.Visible=1;
            cla(app.UIAxes11)
            plot(app.UIAxes11, sigma_U, U, 'p','markerFaceColor','k')
            hold(app.UIAxes11,"on")
            text(app.UIAxes11, sigma_U, U, " "+string(1:size(fval_transient,1)))
            grid(app.UIAxes11,"on")
            title(app.UIAxes11,"Utility - Robustness")

            % append sigmaU in UITable12
            app.UITable12.Data(:,end)=table(sigma_U);
           
            % visibility
            app.StartRobustnessAnalysisButton.Text="Robustness Analysis Completed!";
            drawnow
            app.Lamp_8.Color = [1 0 0];
            app.Lamp_8.Color = [0 1 0];

            app.opt_st.rob = 1; % robustness analysis done
            % recover pruning setting
            app.opt_st.transient_pruning = old_transient_pruning;
            clear old_transient_pruning

            save optimization_result.mat A_link	A_node_face A_node_face_avg	alg_name alg_name_menu algorithm_id ...
                alpha_str attitude col_n diss_mat_tot env epsext_str epsint_str fval_everything fval_pareto fval_transient ...
           	    G_c G_hc heat heat_cold_names heat_hot_names id_from_sel id_node_from id_node_to id_to_sel idx L_link lb...
            	N_str_face names node_id opt_st orbit r_dc rsun sat sim_data Source_term_partial Source_term_partial_cold tdata time_opt ...
                Tmin_max_st U U_H_cold U_H_hot U_L U_T_high_cold U_T_high_hot U_T_low_cold U_T_low_hot ...
                U_T_low_hot_f U_T_high_hot_f U_T_low_cold_f U_T_high_cold_f U_H_hot_f U_H_cold_f U_L_f ...
                ub v_dc val_cases val_test Vf Vol_link x_everything x_pareto x_test x_transient ...
                sigma_U U_rob_mat

        end

        % Button pushed function: ConfirmOperatingModesButton
        function ConfirmOperatingModesButtonPushed(app, event)
           
    if app.T_orbit == 0
        uialert(app.S2T2, 'Compute the orbital environment first (External Environment tab).', 'Orbital period missing');
        return
    end

    N_modes = app.NModesEditField.Value;

    mode_names = "Mode" + string(1:N_modes)';
    t_end_default = zeros(N_modes,1);
    t_end_default(end) = app.T_orbit;

    tdata = table((1:N_modes)', mode_names, zeros(N_modes,1), t_end_default, ...
        'VariableNames', {'ID_modes','mode_name','t_start [s]','t_end [s]'});

    app.OperativeModesTable.Data = tdata;
    app.ModesLamp.Color = [0 1 0];


        end

        % Value changed function: NModesEditField
        function NModesEditFieldValueChanged(app, event)
             app.ModesLamp.Color = [1 0 0];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.S2T2 = uifigure('Visible', 'off');
            app.S2T2.Name = 'UI Figure';
            app.LoadMenu = uimenu(app.S2T2);
            app.LoadMenu.Text = 'Load';
            app.InputMenu = uimenu(app.LoadMenu);
            app.InputMenu.Text = 'Input';
            app.AllInputMenu = uimenu(app.InputMenu);
            app.AllInputMenu.MenuSelectedFcn = createCallbackFcn(app, @AllInputMenuSelected, true);
            app.AllInputMenu.Text = 'All Input';
            app.EnvironmentMenu = uimenu(app.InputMenu);
            app.EnvironmentMenu.MenuSelectedFcn = createCallbackFcn(app, @EnvironmentMenuSelected, true);
            app.EnvironmentMenu.Text = 'Environment';
            app.GMMMenu = uimenu(app.InputMenu);
            app.GMMMenu.MenuSelectedFcn = createCallbackFcn(app, @GMMMenuSelected, true);
            app.GMMMenu.Text = 'GMM';
            app.TMMandDissipationMenu = uimenu(app.InputMenu);
            app.TMMandDissipationMenu.MenuSelectedFcn = createCallbackFcn(app, @TMMandDissipationMenuSelected, true);
            app.TMMandDissipationMenu.Enable = 'off';
            app.TMMandDissipationMenu.Text = 'TMM and Dissipation';
            app.ResultsMenu = uimenu(app.LoadMenu);
            app.ResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResultsMenuSelected, true);
            app.ResultsMenu.Text = 'Results';
            app.TransientMenu = uimenu(app.ResultsMenu);
            app.TransientMenu.MenuSelectedFcn = createCallbackFcn(app, @TransientMenuSelected, true);
            app.TransientMenu.Text = 'Transient';
            app.OptimizationMenu = uimenu(app.ResultsMenu);
            app.OptimizationMenu.MenuSelectedFcn = createCallbackFcn(app, @OptimizationMenuSelected, true);
            app.OptimizationMenu.Text = 'Optimization';
            app.GridLayout_all = uigridlayout(app.S2T2);
            app.TabGroup = uitabgroup(app.GridLayout_all);
            app.ExternalEnvironmentTab = uitab(app.TabGroup);
            app.ExternalEnvironmentTab.Title = 'External Environment';
            app.GridLayout18 = uigridlayout(app.ExternalEnvironmentTab);
            app.UIAxes = uiaxes(app.GridLayout18);
            title(app.UIAxes, 'Orbit')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.CentralbodypropertiesPanel = uipanel(app.GridLayout18);
            app.CentralbodypropertiesPanel.Title = 'Central body properties';
            app.GridLayout51 = uigridlayout(app.CentralbodypropertiesPanel);
            app.Wm2Label = uilabel(app.GridLayout51);
            app.Wm2Label.Text = '[W/m^2]';
            app.Label = uilabel(app.GridLayout51);
            app.Label.Text = '[]';
            app.Wm2Label_2 = uilabel(app.GridLayout51);
            app.Wm2Label_2.Text = '[W/m^2]';
            app.KmLabel = uilabel(app.GridLayout51);
            app.KmLabel.Text = '[Km]';
            app.Km3s2Label = uilabel(app.GridLayout51);
            app.Km3s2Label.Text = '[Km^3/s^2]';
            app.CentralBodyDropDownLabel = uilabel(app.GridLayout51);
            app.CentralBodyDropDownLabel.Text = 'Central Body';
            app.CentralBodyDropDown = uidropdown(app.GridLayout51);
            app.CentralBodyDropDown.Items = {'Earth', 'Moon', 'Mars', 'User'};
            app.CentralBodyDropDown.ValueChangedFcn = createCallbackFcn(app, @CentralBodyDropDownValueChanged, true);
            app.CentralBodyDropDown.Value = 'Earth';
            app.SolarConstEditFieldLabel = uilabel(app.GridLayout51);
            app.SolarConstEditFieldLabel.Text = 'Solar Const';
            app.SolarConstEditField = uieditfield(app.GridLayout51, 'numeric');
            app.SolarConstEditField.Value = 1367;
            app.GravitationalConstantLabel = uilabel(app.GridLayout51);
            app.GravitationalConstantLabel.Text = 'Gravitational Constant';
            app.GravitationalConstantEditField = uieditfield(app.GridLayout51, 'numeric');
            app.GravitationalConstantEditField.Editable = 'off';
            app.GravitationalConstantEditField.Value = 398600;
            app.AlbedoCoefEditFieldLabel = uilabel(app.GridLayout51);
            app.AlbedoCoefEditFieldLabel.Text = 'Albedo Coef';
            app.AlbedoCoefEditField = uieditfield(app.GridLayout51, 'numeric');
            app.AlbedoCoefEditField.Value = 0.36;
            app.PlanetRadiusEditFieldLabel = uilabel(app.GridLayout51);
            app.PlanetRadiusEditFieldLabel.Text = 'Planet Radius';
            app.PlanetRadiusEditField = uieditfield(app.GridLayout51, 'numeric');
            app.PlanetRadiusEditField.Editable = 'off';
            app.PlanetRadiusEditField.Value = 6371;
            app.PlanetIRfluxEditFieldLabel = uilabel(app.GridLayout51);
            app.PlanetIRfluxEditFieldLabel.Text = 'Planet IR flux';
            app.PlanetIRfluxEditField = uieditfield(app.GridLayout51, 'numeric');
            app.PlanetIRfluxEditField.Value = 234;
            app.SimulationDataPanel = uipanel(app.GridLayout18);
            app.SimulationDataPanel.Title = 'Simulation Data';
            app.GridLayout49 = uigridlayout(app.SimulationDataPanel);
            app.sLabel = uilabel(app.GridLayout49);
            app.sLabel.Text = '[s]';
            app.SimulationdateLabel = uilabel(app.GridLayout49);
            app.SimulationdateLabel.Text = 'Simulation date';
            app.SimulationdateDatePicker = uidatepicker(app.GridLayout49);
            app.SimulationdateDatePicker.Value = datetime([2020 4 2]);
            app.TimeStepEditFieldLabel = uilabel(app.GridLayout49);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.GridLayout49, 'numeric');
            app.TimeStepEditField.Limits = [0 Inf];
            app.TimeStepEditField.Value = 30;
            app.OrbitsEditFieldLabel = uilabel(app.GridLayout49);
            app.OrbitsEditFieldLabel.Text = '# Orbits';
            app.OrbitsEditField = uieditfield(app.GridLayout49, 'numeric');
            app.OrbitsEditField.Limits = [0 Inf];
            app.OrbitsEditField.Value = 10;
            app.hLabel = uilabel(app.GridLayout49);
            app.hLabel.Text = '[0,24 h]';
            app.HourdecimalEditFieldLabel = uilabel(app.GridLayout49);
            app.HourdecimalEditFieldLabel.Text = 'Hour (decimal)';
            app.HourdecimalEditField = uieditfield(app.GridLayout49, 'numeric');
            app.Label_3 = uilabel(app.GridLayout49);
            app.Label_3.Text = '[]';
            app.SCOrbitalDataPanel = uipanel(app.GridLayout18);
            app.SCOrbitalDataPanel.Title = 'S/C Orbital Data';
            app.GridLayout50 = uigridlayout(app.SCOrbitalDataPanel);
            app.Switch = uiswitch(app.GridLayout50, 'slider');
            app.Switch.Items = {'Classic', 'Basic'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Classic';
            app.Label_2 = uilabel(app.GridLayout50);
            app.Label_2.Text = '[]';
            app.KmLabel_2 = uilabel(app.GridLayout50);
            app.KmLabel_2.Text = '[Km]';
            app.degLabel = uilabel(app.GridLayout50);
            app.degLabel.Text = '[deg]';
            app.degLabel_2 = uilabel(app.GridLayout50);
            app.degLabel_2.Text = '[deg]';
            app.degLabel_3 = uilabel(app.GridLayout50);
            app.degLabel_3.Text = '[deg]';
            app.KmLabel_3 = uilabel(app.GridLayout50);
            app.KmLabel_3.Text = '[Km]';
            app.degLabel_4 = uilabel(app.GridLayout50);
            app.degLabel_4.Text = '[deg]';
            app.EccentricityEditFieldLabel = uilabel(app.GridLayout50);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.GridLayout50, 'numeric');
            app.InclinationEditFieldLabel = uilabel(app.GridLayout50);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.GridLayout50, 'numeric');
            app.InclinationEditField.Value = 97.5;
            app.ArgumentperiapsidLabel = uilabel(app.GridLayout50);
            app.ArgumentperiapsidLabel.Text = 'Argument periapsid';
            app.ArgumentperiapsidEditField = uieditfield(app.GridLayout50, 'numeric');
            app.SMAEditFieldLabel = uilabel(app.GridLayout50);
            app.SMAEditFieldLabel.Text = 'SMA';
            app.SMAEditField = uieditfield(app.GridLayout50, 'numeric');
            app.SMAEditField.Value = 6871;
            app.RAANEditFieldLabel = uilabel(app.GridLayout50);
            app.RAANEditFieldLabel.Text = 'RAAN';
            app.RAANEditField = uieditfield(app.GridLayout50, 'numeric');
            app.RAANEditField.Value = 106.98;
            app.BetaAngleEditFieldLabel = uilabel(app.GridLayout50);
            app.BetaAngleEditFieldLabel.Text = 'Beta Angle';
            app.BetaAngleEditField = uieditfield(app.GridLayout50, 'numeric');
            app.BetaAngleEditField.Enable = 'off';
            app.AltitudeEditFieldLabel = uilabel(app.GridLayout50);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.GridLayout50, 'numeric');
            app.AltitudeEditField.Enable = 'off';
            app.GenerateplotandSaveButton = uibutton(app.GridLayout18, 'push');
            app.GenerateplotandSaveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateplotandSaveButtonPushed, true);
            app.GenerateplotandSaveButton.Text = 'Generate plot and Save';
            app.AttitudePanel = uipanel(app.GridLayout18);
            app.AttitudePanel.Title = 'Attitude';
            app.GridLayout19 = uigridlayout(app.AttitudePanel);
            app.WorstCasePanel = uipanel(app.GridLayout19);
            app.WorstCasePanel.Title = 'Worst Case';
            app.GridLayout52 = uigridlayout(app.WorstCasePanel);
            app.PointingDropDown_2 = uidropdown(app.GridLayout52);
            app.PointingDropDown_2.Items = {'Velocity'};
            app.PointingDropDown_2.Value = 'Velocity';
            app.FaceDropDown_2 = uidropdown(app.GridLayout52);
            app.FaceDropDown_2.Items = {'+X', '+Y', '+Z', '-X', '-Y', '-Z'};
            app.FaceDropDown_2.Value = '-Y';
            app.SpinCheckBox = uicheckbox(app.GridLayout52);
            app.SpinCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpinCheckBoxValueChanged, true);
            app.SpinCheckBox.Text = 'Spin';
            app.PointingDropDownLabel = uilabel(app.GridLayout52);
            app.PointingDropDownLabel.Text = 'Pointing';
            app.PointingDropDown = uidropdown(app.GridLayout52);
            app.PointingDropDown.Items = {'Nadir'};
            app.PointingDropDown.Value = 'Nadir';
            app.FaceDropDownLabel = uilabel(app.GridLayout52);
            app.FaceDropDownLabel.Text = 'Face';
            app.FaceDropDown = uidropdown(app.GridLayout52);
            app.FaceDropDown.Items = {'+X', '+Y', '+Z', '-X', '-Y', '-Z'};
            app.FaceDropDown.Value = '-Z';
            app.WorstCasePanel_2 = uipanel(app.GridLayout19);
            app.WorstCasePanel_2.Title = 'Worst Case';
            app.WorstCasePanel_2.Visible = 'off';
            app.GridLayout53 = uigridlayout(app.WorstCasePanel_2);
            app.PointingDropDown_4 = uidropdown(app.GridLayout53);
            app.PointingDropDown_4.Items = {'Nadir', 'Velocity'};
            app.PointingDropDown_4.Value = 'Velocity';
            app.FaceDropDown_4 = uidropdown(app.GridLayout53);
            app.FaceDropDown_4.Items = {'+X', '+Y', '+Z', '-X', '-Y', '-Z'};
            app.FaceDropDown_4.Value = '-Y';
            app.SpinCheckBox_2 = uicheckbox(app.GridLayout53);
            app.SpinCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SpinCheckBox_2ValueChanged, true);
            app.SpinCheckBox_2.Text = 'Spin';
            app.PointingDropDown_3Label = uilabel(app.GridLayout53);
            app.PointingDropDown_3Label.Text = 'Pointing';
            app.PointingDropDown_3 = uidropdown(app.GridLayout53);
            app.PointingDropDown_3.Items = {'Nadir', 'Velocity'};
            app.PointingDropDown_3.Value = 'Nadir';
            app.FaceDropDown_3Label = uilabel(app.GridLayout53);
            app.FaceDropDown_3Label.Text = 'Face';
            app.FaceDropDown_3 = uidropdown(app.GridLayout53);
            app.FaceDropDown_3.Items = {'+X', '+Y', '+Z', '-X', '-Y', '-Z'};
            app.FaceDropDown_3.Value = '-Z';
            app.OrbitalPeriodTextArea = uitextarea(app.GridLayout18);
            app.OrbitalPeriodTextArea.ValueChangedFcn = createCallbackFcn(app, @OrbitalPeriodTextAreaValueChanged, true);
            app.CasestudiesSwitchLabel = uilabel(app.GridLayout18);
            app.CasestudiesSwitchLabel.Text = 'Case studies';
            app.CasestudiesSwitch = uiswitch(app.GridLayout18, 'slider');
            app.CasestudiesSwitch.Items = {'1', '2'};
            app.CasestudiesSwitch.ValueChangedFcn = createCallbackFcn(app, @CasestudiesSwitchValueChanged, true);
            app.CasestudiesSwitch.Value = '1';
            app.InternalEnvironmentTab = uitab(app.TabGroup);
            app.InternalEnvironmentTab.Title = 'Internal Environment';
            app.OperatingModesPanel = uipanel(app.InternalEnvironmentTab);
            app.OperatingModesPanel.Title = 'Operating Modes';
            app.Label_4 = uilabel(app.OperatingModesPanel);
            app.Label_4.Text = '';
            app.NumberofOperatingModesEditFieldLabel = uilabel(app.OperatingModesPanel);
            app.NumberofOperatingModesEditFieldLabel.Text = 'Number of Operating Modes';
            app.NModesEditField = uieditfield(app.OperatingModesPanel, 'numeric');
            app.NModesEditField.Limits = [1 Inf];
            app.NModesEditField.RoundFractionalValues = 'on';
            app.NModesEditField.ValueChangedFcn = createCallbackFcn(app, @NModesEditFieldValueChanged, true);
            app.NModesEditField.Value = 1;
            app.OperativeModesTable = uitable(app.OperatingModesPanel);
            app.OperativeModesTable.ColumnName = {'ID_modes'; 'mode_name'; 't_start [s]'; 't_end[s]'};
            app.OperativeModesTable.RowName = {'{}'};
            app.OperativeModesTable.ColumnEditable = [false true true true];
            app.ConfirmOperatingModesButton = uibutton(app.OperatingModesPanel, 'push');
            app.ConfirmOperatingModesButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmOperatingModesButtonPushed, true);
            app.ConfirmOperatingModesButton.Text = 'Confirm operative modes';
            app.ModesLamp = uilamp(app.OperatingModesPanel);
            app.GMMTab = uitab(app.TabGroup);
            app.GMMTab.Title = 'GMM';
            app.GridLayout26 = uigridlayout(app.GMMTab);
            app.UIAxes2_2 = uiaxes(app.GridLayout26);
            title(app.UIAxes2_2, 'GMM')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.UIAxes2_2.Visible = 'off';
            app.TabGroup3 = uitabgroup(app.GridLayout26);
            app.StructureTab = uitab(app.TabGroup3);
            app.StructureTab.Title = 'Structure';
            app.GridLayout27 = uigridlayout(app.StructureTab);
            app.NodesconfigurationandGeometryPanel_2 = uipanel(app.GridLayout27);
            app.NodesconfigurationandGeometryPanel_2.Title = 'Nodes configuration and Geometry';
            app.GridLayout41 = uigridlayout(app.NodesconfigurationandGeometryPanel_2);
            app.UITable1_ex = uitable(app.GridLayout41);
            app.UITable1_ex.ColumnName = {'ID_Item'; 'Nx'; 'Ny'; 'Nz'; 'X_centre [mm]'; 'Y_centre [mm]'; 'Z_centre [mm]'; 'Lx [mm]'; 'Ly [mm]'; 'Lz [mm]'; 'Color'; 'Item_name'};
            app.UITable1_ex.RowName = {};
            app.UITable1_ex.ColumnEditable = [false true true true true true true true true true true true];
            app.PropertiesPanel_2 = uipanel(app.GridLayout27);
            app.PropertiesPanel_2.Title = 'Properties';
            app.GridLayout42 = uigridlayout(app.PropertiesPanel_2);
            app.UITable2_ex = uitable(app.GridLayout42);
            app.UITable2_ex.ColumnName = {'ID_face'; 'T_0 [K]'; 'Alpha'; 'Eps (int)'; 'Eps (ext)'; 'Cond [W/(K*m)]'; 'Density [kg/m^3]'; 'Specific Heat [J/(Kg*K)]'; 'Thickness [mm]'; 'T_op_min [°C]'; 'T_op_max [°C]'};
            app.UITable2_ex.RowName = {};
            app.UITable2_ex.ColumnEditable = [false true true true true true true true true true true];
            app.SolarPanelsTab = uitab(app.TabGroup3);
            app.SolarPanelsTab.Title = 'Solar Panels';
            app.GridLayout28 = uigridlayout(app.SolarPanelsTab);
            app.SolarPanelsInitializationButton = uibutton(app.GridLayout28, 'push');
            app.SolarPanelsInitializationButton.ButtonPushedFcn = createCallbackFcn(app, @SolarPanelsInitializationButtonPushed, true);
            app.SolarPanelsInitializationButton.Text = 'Solar Panels Initialization';
            app.okLamp_3 = uilamp(app.GridLayout28);
            app.NodesconfigurationandGeometryPanel = uipanel(app.GridLayout28);
            app.NodesconfigurationandGeometryPanel.Title = 'Nodes configuration and Geometry';
            app.GridLayout29 = uigridlayout(app.NodesconfigurationandGeometryPanel);
            app.UITable_2 = uitable(app.GridLayout29);
            app.UITable_2.ColumnName = {'ID_Solar_Panel'; 'Nx'; 'Ny'; 'X_centre [mm]'; 'Y_centre [mm]'; 'Z_centre [mm]'; 'Lx [mm]'; 'Ly [mm]'; 'alpha[deg]'; 'beta [deg]'; 'gamma [deg]'; 'Color'; 'Item_name'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false true true true true true true true true true true true true];
            app.PropertiesPanel = uipanel(app.GridLayout28);
            app.PropertiesPanel.Title = 'Properties';
            app.GridLayout30 = uigridlayout(app.PropertiesPanel);
            app.UITable2_3 = uitable(app.GridLayout30);
            app.UITable2_3.ColumnName = {'ID_face'; 'T_0 [K]'; 'Alpha'; 'Eps'; 'Cond [W/(K*m)]'; 'Density [kg/m^3]'; 'Specific Heat [J/(Kg*K)]'; 'Thickness [mm]'; 'T_op_min [°C]'; 'T_op_max [°C]'};
            app.UITable2_3.RowName = {};
            app.UITable2_3.ColumnEditable = [false true true true true true true true true true];
            app.DeleteItemButton = uibutton(app.GridLayout28, 'push');
            app.DeleteItemButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteItemButtonPushed, true);
            app.DeleteItemButton.Text = 'Delete Item';
            app.SolarPanelsSpinner = uispinner(app.GridLayout28);
            app.SolarPanelsSpinner.Limits = [0 Inf];
            app.SolarPanelsSpinner.RoundFractionalValues = 'on';
            app.SolarPanelsSpinner.ValueDisplayFormat = '%.0f';
            app.SolarPanelsSpinner.ValueChangedFcn = createCallbackFcn(app, @SolarPanelsSpinnerValueChanged, true);
            app.SolarPanelsLabel = uilabel(app.GridLayout28);
            app.SolarPanelsLabel.Text = '# Solar Panels';
            app.BoardsTab = uitab(app.TabGroup3);
            app.BoardsTab.Title = 'Boards';
            app.GridLayout33 = uigridlayout(app.BoardsTab);
            app.BoardsInitializationButton = uibutton(app.GridLayout33, 'push');
            app.BoardsInitializationButton.ButtonPushedFcn = createCallbackFcn(app, @BoardsInitializationButtonPushed, true);
            app.BoardsInitializationButton.Text = 'Boards Initialization';
            app.okLamp_4 = uilamp(app.GridLayout33);
            app.NodesconfigurationandGeometryPanel_3 = uipanel(app.GridLayout33);
            app.NodesconfigurationandGeometryPanel_3.Title = 'Nodes configuration and Geometry';
            app.GridLayout31 = uigridlayout(app.NodesconfigurationandGeometryPanel_3);
            app.UITable_3 = uitable(app.GridLayout31);
            app.UITable_3.ColumnName = {'ID_Board'; 'Nx'; 'Ny'; 'X_centre [mm]'; 'Y_centre [mm]'; 'Z_centre [mm]'; 'Lx [mm]'; 'Ly [mm]'; 'alpha[deg]'; 'beta [deg]'; 'gamma [deg]'; 'Color'; 'Board_name'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = [false true true true true true true true true true true true true];
            app.PropertiesPanel_3 = uipanel(app.GridLayout33);
            app.PropertiesPanel_3.Title = 'Properties';
            app.GridLayout32 = uigridlayout(app.PropertiesPanel_3);
            app.UITable2_4 = uitable(app.GridLayout32);
            app.UITable2_4.ColumnName = {'ID_face'; 'T_0 [K]'; 'Alpha'; 'Eps'; 'Cond [W/(K*m)]'; 'Density [kg/m^3]'; 'Specific Heat [J/(Kg*K)]'; 'Thickness [mm]'; 'T_op_min [°C]'; 'T_op_max [°C]'};
            app.UITable2_4.RowName = {};
            app.UITable2_4.ColumnEditable = [false true true true true true true true true true];
            app.DeleteItemButton_2 = uibutton(app.GridLayout33, 'push');
            app.DeleteItemButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteItemButton_2Pushed, true);
            app.DeleteItemButton_2.Text = 'Delete Item';
            app.BoardsSpinnerLabel = uilabel(app.GridLayout33);
            app.BoardsSpinnerLabel.Text = '# Boards';
            app.BoardsSpinner = uispinner(app.GridLayout33);
            app.BoardsSpinner.Limits = [0 Inf];
            app.BoardsSpinner.RoundFractionalValues = 'on';
            app.BoardsSpinner.ValueDisplayFormat = '%.0f';
            app.BoardsSpinner.ValueChangedFcn = createCallbackFcn(app, @BoardsSpinnerValueChanged, true);
            app.ParallelepipedsTab = uitab(app.TabGroup3);
            app.ParallelepipedsTab.Title = 'Parallelepipeds';
            app.GridLayout37 = uigridlayout(app.ParallelepipedsTab);
            app.ParallelepipedsInitializationButton = uibutton(app.GridLayout37, 'push');
            app.ParallelepipedsInitializationButton.ButtonPushedFcn = createCallbackFcn(app, @ParallelepipedsInitializationButtonPushed, true);
            app.ParallelepipedsInitializationButton.Text = 'Parallelepipeds Initialization';
            app.okLamp_5 = uilamp(app.GridLayout37);
            app.NodesconfigurationandGeometryPanel_4 = uipanel(app.GridLayout37);
            app.NodesconfigurationandGeometryPanel_4.Title = 'Nodes configuration and Geometry';
            app.GridLayout34 = uigridlayout(app.NodesconfigurationandGeometryPanel_4);
            app.UITable_4 = uitable(app.GridLayout34);
            app.UITable_4.ColumnName = {'ID_Parallelepipeds'; 'Nx'; 'Ny'; 'Nz'; 'X_centre [mm]'; 'Y_centre [mm]'; 'Z_centre [mm]'; 'Lx [mm]'; 'Ly [mm]'; 'Lz [mm]'; 'alpha[deg]'; 'beta [deg]'; 'gamma [deg]'; 'Color'; 'Item_name'};
            app.UITable_4.RowName = {};
            app.UITable_4.ColumnEditable = [false true true true true true true true true true true true true true true true];
            app.MechanicalPropertiesPanel = uipanel(app.GridLayout37);
            app.MechanicalPropertiesPanel.Title = 'Mechanical Properties';
            app.GridLayout36 = uigridlayout(app.MechanicalPropertiesPanel);
            app.UITable2_5 = uitable(app.GridLayout36);
            app.UITable2_5.ColumnName = {'ID_Item'; 'T_0 [K]'; 'Cond [W/(K*m)]'; 'Density [kg/m^3]'; 'Specific Heat [J/(Kg*K)]'; 'Thickness [mm]'; 'T_op_min [°C]'; 'T_op_max [°C]'};
            app.UITable2_5.RowName = {};
            app.UITable2_5.ColumnEditable = [false true true true true true true true true true];
            app.OpticalPropertiesPanel = uipanel(app.GridLayout37);
            app.OpticalPropertiesPanel.Title = 'Optical Properties';
            app.GridLayout35 = uigridlayout(app.OpticalPropertiesPanel);
            app.UITable2_7 = uitable(app.GridLayout35);
            app.UITable2_7.ColumnName = {'ID_Item'; 'Alpha _1(-Y)'; 'Eps_1(-Y)'; 'Alpha _2(+X)'; 'Eps_2(+X)'; 'Alpha _3(+Y)'; 'Eps_3(+Y)'; 'Alpha _4(-X)'; 'Eps_4(-X)'; 'Alpha _5(-Z)'; 'Eps_5(-Z)'; 'Alpha _6(+Z)'; 'Eps_6(+Z)'};
            app.UITable2_7.RowName = {};
            app.UITable2_7.ColumnEditable = [false true true true true true true true true true true true true];
            app.DeleteItemButton_3 = uibutton(app.GridLayout37, 'push');
            app.DeleteItemButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteItemButton_3Pushed, true);
            app.DeleteItemButton_3.Text = 'Delete Item';
            app.ParallelepipedsSpinner = uispinner(app.GridLayout37);
            app.ParallelepipedsSpinner.Limits = [0 Inf];
            app.ParallelepipedsSpinner.RoundFractionalValues = 'on';
            app.ParallelepipedsSpinner.ValueDisplayFormat = '%.0f';
            app.ParallelepipedsSpinner.ValueChangedFcn = createCallbackFcn(app, @ParallelepipedsSpinnerValueChanged, true);
            app.ParallelepipedsLabel = uilabel(app.GridLayout37);
            app.ParallelepipedsLabel.Text = '# Parallelepipeds';
            app.CylindersTab = uitab(app.TabGroup3);
            app.CylindersTab.Title = 'Cylinders';
            app.GridLayout38 = uigridlayout(app.CylindersTab);
            app.CylindersInitializationButton = uibutton(app.GridLayout38, 'push');
            app.CylindersInitializationButton.ButtonPushedFcn = createCallbackFcn(app, @CylindersInitializationButtonPushed, true);
            app.CylindersInitializationButton.Text = 'Cylinders Initialization';
            app.okLamp_6 = uilamp(app.GridLayout38);
            app.NodesconfigurationandGeometryPanel_5 = uipanel(app.GridLayout38);
            app.NodesconfigurationandGeometryPanel_5.Title = 'Nodes configuration and Geometry';
            app.GridLayout39 = uigridlayout(app.NodesconfigurationandGeometryPanel_5);
            app.UITable_5 = uitable(app.GridLayout39);
            app.UITable_5.ColumnName = {'ID_Cylinder'; 'Radius'; 'Length'; 'X_centre [mm]'; 'Y_centre [mm]'; 'Z_centre[mm]'; 'N_radius'; 'N_circum'; 'N_length'; 'alpha [deg]'; 'beta [deg]'; 'gamma [deg]'; 'Color'; 'Item_name'};
            app.UITable_5.RowName = {};
            app.UITable_5.ColumnEditable = [false true true true true true true true true true true true true true];
            app.PropertiesPanel_5 = uipanel(app.GridLayout38);
            app.PropertiesPanel_5.Title = 'Properties';
            app.GridLayout40 = uigridlayout(app.PropertiesPanel_5);
            app.UITable2_6 = uitable(app.GridLayout40);
            app.UITable2_6.ColumnName = {'ID_face'; 'T_0 [K]'; 'Alpha_top'; 'Eps_top'; 'Alpha_bottom'; 'Eps_bottom'; 'Alpha_lateral'; 'Eps_lateral'; 'Cond [W/(K*m)]'; 'Density [kg/m^3]'; 'Specific Heat [J/(Kg*K)]'; 'Thickness [mm]'; 'T_op_min [°C]'; 'T_op_max [°C]'};
            app.UITable2_6.RowName = {};
            app.UITable2_6.ColumnEditable = [false true true true true true true true true true true true true];
            app.DeleteItemButton_4 = uibutton(app.GridLayout38, 'push');
            app.DeleteItemButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeleteItemButton_4Pushed, true);
            app.DeleteItemButton_4.Text = 'Delete Item';
            app.CylindersSpinner = uispinner(app.GridLayout38);
            app.CylindersSpinner.Limits = [0 Inf];
            app.CylindersSpinner.RoundFractionalValues = 'on';
            app.CylindersSpinner.ValueDisplayFormat = '%.0f';
            app.CylindersSpinner.ValueChangedFcn = createCallbackFcn(app, @CylindersSpinnerValueChanged, true);
            app.CylindersLabel = uilabel(app.GridLayout38);
            app.CylindersLabel.Text = '# Cylinders';
            app.PreviewandUpdateGMMButton = uibutton(app.GridLayout26, 'push');
            app.PreviewandUpdateGMMButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewandUpdateGMMButtonPushed, true);
            app.PreviewandUpdateGMMButton.Text = 'Preview and Update GMM';
            app.IDListBox_3 = uilistbox(app.GridLayout26);
            app.IDListBox_3.Visible = 'off';
            app.ShowIDButton_3 = uibutton(app.GridLayout26, 'push');
            app.ShowIDButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowIDButton_3Pushed, true);
            app.ShowIDButton_3.Visible = 'off';
            app.ShowIDButton_3.Text = 'Show ID';
            app.CalculateViewFactorButton = uibutton(app.GridLayout26, 'push');
            app.CalculateViewFactorButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateViewFactorButtonPushed, true);
            app.CalculateViewFactorButton.Visible = 'off';
            app.CalculateViewFactorButton.Text = 'Calculate View Factor';
            app.VisibilitySlider_3Label = uilabel(app.GridLayout26);
            app.VisibilitySlider_3Label.Visible = 'off';
            app.VisibilitySlider_3Label.Text = 'Visibility';
            app.VisibilitySlider_3 = uislider(app.GridLayout26);
            app.VisibilitySlider_3.Limits = [0 1];
            app.VisibilitySlider_3.MajorTicks = [0 0.5 1];
            app.VisibilitySlider_3.ValueChangedFcn = createCallbackFcn(app, @VisibilitySlider_3ValueChanged, true);
            app.VisibilitySlider_3.MinorTicks = [0 0.25 0.5 0.75 1];
            app.VisibilitySlider_3.Visible = 'off';
            app.VisibilitySlider_3.Value = 0.9;
            app.TMMDissipationTab = uitab(app.TabGroup);
            app.TMMDissipationTab.Title = 'TMM & Dissipation';
            app.GridLayout3 = uigridlayout(app.TMMDissipationTab);
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, 'TMM & Dissipation')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.Visible = 'off';
            app.GenerateTMMButton = uibutton(app.GridLayout3, 'push');
            app.GenerateTMMButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTMMButtonPushed, true);
            app.GenerateTMMButton.Enable = 'off';
            app.GenerateTMMButton.Text = 'Generate TMM';
            app.DissipationsPanel = uipanel(app.GridLayout3);
            app.DissipationsPanel.Title = 'Dissipations';
            app.DissipationsPanel.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.DissipationsPanel);
            app.ItemDissipationsPanel = uipanel(app.GridLayout2);
            app.ItemDissipationsPanel.Title = 'Item Dissipations';
            app.GridLayout14 = uigridlayout(app.ItemDissipationsPanel);
            app.UITable3 = uitable(app.GridLayout14);
            app.UITable3.ColumnName = {'ID_Item'; 'Item_name'; 'Power [W]'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = [false false true];
            app.NodalDissipationsPanel = uipanel(app.GridLayout2);
            app.NodalDissipationsPanel.Title = 'Nodal Dissipations';
            app.GridLayout15 = uigridlayout(app.NodalDissipationsPanel);
            app.UITable4 = uitable(app.GridLayout15);
            app.UITable4.ColumnName = {'ID_node'; 'Power [W]'};
            app.UITable4.RowName = {};
            app.UITable4.ColumnEditable = true;
            app.Add_dissipationButton = uibutton(app.GridLayout2, 'push');
            app.Add_dissipationButton.ButtonPushedFcn = createCallbackFcn(app, @Add_dissipationButtonPushed, true);
            app.Add_dissipationButton.Text = 'Add_dissipation';
            app.PreviewButton = uibutton(app.GridLayout2, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.Del_dissipationButton = uibutton(app.GridLayout2, 'push');
            app.Del_dissipationButton.ButtonPushedFcn = createCallbackFcn(app, @Del_dissipationButtonPushed, true);
            app.Del_dissipationButton.Text = 'Del_dissipation';
            app.PreviewButton_3 = uibutton(app.GridLayout2, 'push');
            app.PreviewButton_3.ButtonPushedFcn = createCallbackFcn(app, @PreviewButton_3Pushed, true);
            app.PreviewButton_3.Text = 'Preview';
            app.AdditionalconductionPanel = uipanel(app.GridLayout3);
            app.AdditionalconductionPanel.Title = 'Additional conduction';
            app.AdditionalconductionPanel.Visible = 'off';
            app.GridLayout = uigridlayout(app.AdditionalconductionPanel);
            app.UITable5 = uitable(app.GridLayout);
            app.UITable5.ColumnName = {'ID_node1'; 'ID_node2'; 'k [W/(K*m)]'; 'A [m^2]'; 'L [m]'};
            app.UITable5.RowName = {};
            app.UITable5.ColumnEditable = true;
            app.Add_conductionButton = uibutton(app.GridLayout, 'push');
            app.Add_conductionButton.ButtonPushedFcn = createCallbackFcn(app, @Add_conductionButtonPushed, true);
            app.Add_conductionButton.Text = 'Add_conduction';
            app.Del_conductionButton = uibutton(app.GridLayout, 'push');
            app.Del_conductionButton.ButtonPushedFcn = createCallbackFcn(app, @Del_conductionButtonPushed, true);
            app.Del_conductionButton.Text = 'Del_conduction';
            app.PreviewButton_2 = uibutton(app.GridLayout, 'push');
            app.PreviewButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviewButton_2Pushed, true);
            app.PreviewButton_2.Text = 'Preview';
            app.IDListBox_2 = uilistbox(app.GridLayout3);
            app.IDListBox_2.Visible = 'off';
            app.ShowIDButton_2 = uibutton(app.GridLayout3, 'push');
            app.ShowIDButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowIDButton_2Pushed, true);
            app.ShowIDButton_2.Visible = 'off';
            app.ShowIDButton_2.Text = 'Show ID';
            app.VisibilitySlider_2Label = uilabel(app.GridLayout3);
            app.VisibilitySlider_2Label.Visible = 'off';
            app.VisibilitySlider_2Label.Text = 'Visibility';
            app.VisibilitySlider_2 = uislider(app.GridLayout3);
            app.VisibilitySlider_2.Limits = [0 1];
            app.VisibilitySlider_2.MajorTicks = [0 0.5 1];
            app.VisibilitySlider_2.MajorTickLabels = {'0', '0.5', '1'};
            app.VisibilitySlider_2.ValueChangedFcn = createCallbackFcn(app, @VisibilitySlider_2ValueChanged, true);
            app.VisibilitySlider_2.MinorTicks = [0 0.25 0.5 0.75 1];
            app.VisibilitySlider_2.Visible = 'off';
            app.VisibilitySlider_2.Value = 0.5;
            app.AnalysisPostprocessingTab = uitab(app.TabGroup);
            app.AnalysisPostprocessingTab.Title = 'Analysis & Post-processing';
            app.GridLayout48 = uigridlayout(app.AnalysisPostprocessingTab);
            app.TabGroup2 = uitabgroup(app.GridLayout48);
            app.RunGraphTab = uitab(app.TabGroup2);
            app.RunGraphTab.Title = 'Run & Graph';
            app.GridLayout23 = uigridlayout(app.RunGraphTab);
            app.UIAxes4 = uiaxes(app.GridLayout23);
            title(app.UIAxes4, 'Temperature Trends')
            xlabel(app.UIAxes4, 'Time [s]')
            ylabel(app.UIAxes4, 'T[°C]')
            app.UIAxes4.Visible = 'off';
            app.RunSimulationButton = uibutton(app.GridLayout23, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Enable = 'off';
            app.RunSimulationButton.Text = 'Run Simulation';
            app.Lamp_4 = uilamp(app.GridLayout23);
            app.Lamp_4.Visible = 'off';
            app.PreparationLabel = uilabel(app.GridLayout23);
            app.PreparationLabel.Visible = 'off';
            app.PreparationLabel.Text = 'Preparation';
            app.PlotTemperatureButton = uibutton(app.GridLayout23, 'push');
            app.PlotTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTemperatureButtonPushed, true);
            app.PlotTemperatureButton.Visible = 'off';
            app.PlotTemperatureButton.Text = 'Plot Temperature';
            app.WorstcasePanel_3 = uipanel(app.GridLayout23);
            app.WorstcasePanel_3.Title = 'Worst case';
            app.WorstcasePanel_3.Visible = 'off';
            app.GridLayout24 = uigridlayout(app.WorstcasePanel_3);
            app.Tree = uitree(app.GridLayout24);
            app.Tree.Multiselect = 'on';
            app.WorstcasePanel_4 = uipanel(app.GridLayout23);
            app.WorstcasePanel_4.Title = 'Worst case';
            app.WorstcasePanel_4.Visible = 'off';
            app.GridLayout25 = uigridlayout(app.WorstcasePanel_4);
            app.Tree_2 = uitree(app.GridLayout25);
            app.Tree_2.Multiselect = 'on';
            app.HeatMapTab = uitab(app.TabGroup2);
            app.HeatMapTab.Title = 'Heat Map';
            app.GridLayout4 = uigridlayout(app.HeatMapTab);
            app.SatellitepositionPanel = uipanel(app.GridLayout4);
            app.SatellitepositionPanel.Title = 'Satellite position';
            app.SatellitepositionPanel.Visible = 'off';
            app.GridLayout16 = uigridlayout(app.SatellitepositionPanel);
            app.UIAxes5 = uiaxes(app.GridLayout16);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.HeatMapTemperatureCPanel = uipanel(app.GridLayout4);
            app.HeatMapTemperatureCPanel.Title = 'Heat Map - Temperature [°C]';
            app.HeatMapTemperatureCPanel.Visible = 'off';
            app.GridLayout17 = uigridlayout(app.HeatMapTemperatureCPanel);
            app.UIAxes6 = uiaxes(app.GridLayout17);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.TimeControlPanel = uipanel(app.GridLayout4);
            app.TimeControlPanel.Title = 'Time Control';
            app.TimeControlPanel.Visible = 'off';
            app.TimeminSliderLabel = uilabel(app.TimeControlPanel);
            app.TimeminSliderLabel.Text = 'Time [min]';
            app.TimeminSlider = uislider(app.TimeControlPanel);
            app.TimeminSlider.MajorTicks = [0 50 100];
            app.TimeminSlider.ValueChangedFcn = createCallbackFcn(app, @TimeminSliderValueChanged, true);
            app.TimesEditFieldLabel = uilabel(app.TimeControlPanel);
            app.TimesEditFieldLabel.Text = 'Time [s]';
            app.TimesEditField = uieditfield(app.TimeControlPanel, 'numeric');
            app.TimesEditField.ValueDisplayFormat = '%.0f';
            app.TimesEditField.Editable = 'off';
            app.TrasparencyButtonGroup = uibuttongroup(app.GridLayout4);
            app.TrasparencyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrasparencyButtonGroupSelectionChanged, true);
            app.TrasparencyButtonGroup.Title = 'Trasparency';
            app.TrasparencyButtonGroup.Visible = 'off';
            app.ExternalButton = uiradiobutton(app.TrasparencyButtonGroup);
            app.ExternalButton.Text = 'External';
            app.ExternalButton.Value = true;
            app.InternalButton = uiradiobutton(app.TrasparencyButtonGroup);
            app.InternalButton.Text = 'Internal';
            app.BothButton = uiradiobutton(app.TrasparencyButtonGroup);
            app.BothButton.Text = 'Both';
            app.HeatMapTab_2 = uitab(app.TabGroup2);
            app.GridLayout5 = uigridlayout(app.HeatMapTab_2);
            app.SatellitepositionPanel_2 = uipanel(app.GridLayout5);
            app.SatellitepositionPanel_2.Title = 'Satellite position';
            app.SatellitepositionPanel_2.Visible = 'off';
            app.UIAxes5_2 = uiaxes(app.SatellitepositionPanel_2);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            app.HeatMapTemperatureCPanel_2 = uipanel(app.GridLayout5);
            app.HeatMapTemperatureCPanel_2.Title = 'Heat Map - Temperature [°C]';
            app.HeatMapTemperatureCPanel_2.Visible = 'off';
            app.UIAxes6_2 = uiaxes(app.HeatMapTemperatureCPanel_2);
            title(app.UIAxes6_2, 'Title')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            app.TimeControlPanel_2 = uipanel(app.GridLayout5);
            app.TimeControlPanel_2.Title = 'Time Control';
            app.TimeControlPanel_2.Visible = 'off';
            app.TimeminSlider_2Label = uilabel(app.TimeControlPanel_2);
            app.TimeminSlider_2Label.Text = 'Time [min]';
            app.TimeminSlider_2 = uislider(app.TimeControlPanel_2);
            app.TimeminSlider_2.MajorTicks = [0 50 100];
            app.TimeminSlider_2.ValueChangedFcn = createCallbackFcn(app, @TimeminSlider_2ValueChanged, true);
            app.TimesEditField_2Label = uilabel(app.TimeControlPanel_2);
            app.TimesEditField_2Label.Text = 'Time [s]';
            app.TimesEditField_2 = uieditfield(app.TimeControlPanel_2, 'numeric');
            app.TimesEditField_2.Editable = 'off';
            app.TrasparencyButtonGroup_2 = uibuttongroup(app.GridLayout5);
            app.TrasparencyButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @TrasparencyButtonGroup_2SelectionChanged, true);
            app.TrasparencyButtonGroup_2.Title = 'Trasparency';
            app.TrasparencyButtonGroup_2.Visible = 'off';
            app.ExternalButton_2 = uiradiobutton(app.TrasparencyButtonGroup_2);
            app.ExternalButton_2.Text = 'External';
            app.ExternalButton_2.Value = true;
            app.InternalButton_2 = uiradiobutton(app.TrasparencyButtonGroup_2);
            app.InternalButton_2.Text = 'Internal';
            app.BothButton_2 = uiradiobutton(app.TrasparencyButtonGroup_2);
            app.BothButton_2.Text = 'Both';
            app.DesignTab = uitab(app.TabGroup);
            app.DesignTab.Title = 'Design';
            app.GridLayout43 = uigridlayout(app.DesignTab);
            app.TabGroup4 = uitabgroup(app.GridLayout43);
            app.OptimizationsettingsTab = uitab(app.TabGroup4);
            app.OptimizationsettingsTab.Title = 'Optimization settings';
            app.GridLayout6 = uigridlayout(app.OptimizationsettingsTab);
            app.UIAxes8 = uiaxes(app.GridLayout6);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.Visible = 'off';
            app.OpticalpropertiesPanel_2 = uipanel(app.GridLayout6);
            app.OpticalpropertiesPanel_2.Title = 'Optical properties';
            app.OpticalpropertiesPanel_2.Visible = 'off';
            app.GridLayout7 = uigridlayout(app.OpticalpropertiesPanel_2);
            app.UITable8 = uitable(app.GridLayout7);
            app.UITable8.ColumnName = {'Variable'; 'Lower bound'; 'Upper bound'};
            app.UITable8.RowName = {};
            app.UITable8.ColumnEditable = [false true true];
            app.UITable8.Visible = 'off';
            app.Tree2 = uitree(app.GridLayout7, 'checkbox');
            app.ConfirmpropertyselectionButton = uibutton(app.GridLayout7, 'push');
            app.ConfirmpropertyselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmpropertyselectionButtonPushed, true);
            app.ConfirmpropertyselectionButton.Text = 'Confirm property selection';
            app.Lamp_6 = uilamp(app.GridLayout7);
            app.ConductancelinksThermalstrapsPanel = uipanel(app.GridLayout6);
            app.ConductancelinksThermalstrapsPanel.Title = 'Conductance links / Thermal straps';
            app.ConductancelinksThermalstrapsPanel.Visible = 'off';
            app.GridLayout8 = uigridlayout(app.ConductancelinksThermalstrapsPanel);
            app.LinksSpinnerLabel = uilabel(app.GridLayout8);
            app.LinksSpinnerLabel.Text = '# Links';
            app.LinksSpinner = uispinner(app.GridLayout8);
            app.LinksSpinner.Limits = [0 100];
            app.LinksSpinner.RoundFractionalValues = 'on';
            app.LinksSpinner.ValueDisplayFormat = '%.0f';
            app.LinksSpinner.ValueChangedFcn = createCallbackFcn(app, @LinksSpinnerValueChanged, true);
            app.StartingitemgroupofthelinksPanel = uipanel(app.GridLayout8);
            app.StartingitemgroupofthelinksPanel.Title = 'Starting item group of the link(s)';
            app.StartingitemgroupofthelinksPanel.Visible = 'off';
            app.GridLayout9 = uigridlayout(app.StartingitemgroupofthelinksPanel);
            app.Tree3 = uitree(app.GridLayout9, 'checkbox');
            app.EndingitemgroupofthelinksPanel = uipanel(app.GridLayout8);
            app.EndingitemgroupofthelinksPanel.Title = 'Ending item group of the link(s)';
            app.EndingitemgroupofthelinksPanel.Visible = 'off';
            app.GridLayout9_2 = uigridlayout(app.EndingitemgroupofthelinksPanel);
            app.Tree3_2 = uitree(app.GridLayout9_2, 'checkbox');
            app.Sectionarealowerboundmm2EditFieldLabel = uilabel(app.GridLayout8);
            app.Sectionarealowerboundmm2EditFieldLabel.Visible = 'off';
            app.Sectionarealowerboundmm2EditFieldLabel.Text = 'Section area lower bound [mm^2]';
            app.Sectionarealowerboundmm2EditField = uieditfield(app.GridLayout8, 'numeric');
            app.Sectionarealowerboundmm2EditField.Limits = [0 Inf];
            app.Sectionarealowerboundmm2EditField.Visible = 'off';
            app.Sectionareaupperboundmm2EditFieldLabel = uilabel(app.GridLayout8);
            app.Sectionareaupperboundmm2EditFieldLabel.Visible = 'off';
            app.Sectionareaupperboundmm2EditFieldLabel.Text = 'Section area upper bound [mm^2]';
            app.Sectionareaupperboundmm2EditField = uieditfield(app.GridLayout8, 'numeric');
            app.Sectionareaupperboundmm2EditField.Limits = [0 Inf];
            app.Sectionareaupperboundmm2EditField.Visible = 'off';
            app.Sectionareaupperboundmm2EditField.Value = 100;
            app.ConductivityWmKEditFieldLabel = uilabel(app.GridLayout8);
            app.ConductivityWmKEditFieldLabel.Visible = 'off';
            app.ConductivityWmKEditFieldLabel.Text = 'Conductivity [W/(m*K)]';
            app.ConductivityWmKEditField = uieditfield(app.GridLayout8, 'numeric');
            app.ConductivityWmKEditField.Limits = [0 Inf];
            app.ConductivityWmKEditField.Visible = 'off';
            app.ConductivityWmKEditField.Value = 385;
            app.HeatersPanel = uipanel(app.GridLayout6);
            app.HeatersPanel.Title = 'Heaters';
            app.HeatersPanel.Visible = 'off';
            app.GridLayout10 = uigridlayout(app.HeatersPanel);
            app.ConfirmheaterselectionButton = uibutton(app.GridLayout10, 'push');
            app.ConfirmheaterselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmheaterselectionButtonPushed, true);
            app.ConfirmheaterselectionButton.Text = 'Confirm heater selection';
            app.UITable10 = uitable(app.GridLayout10);
            app.UITable10.ColumnName = {'ID Item'; 'Item name'; 'Power lower bound [W]'; 'Power upper bound [W]'};
            app.UITable10.RowName = {};
            app.UITable10.Visible = 'off';
            app.ItemwithheaterPanel = uipanel(app.GridLayout10);
            app.ItemwithheaterPanel.Title = 'Item with heater';
            app.GridLayout12 = uigridlayout(app.ItemwithheaterPanel);
            app.Tree3_3 = uitree(app.GridLayout12, 'checkbox');
            app.ItemwithheaterPanel_2 = uipanel(app.GridLayout10);
            app.ItemwithheaterPanel_2.Title = 'Item with heater';
            app.ItemwithheaterPanel_2.Visible = 'off';
            app.GridLayout13 = uigridlayout(app.ItemwithheaterPanel_2);
            app.Tree3_4 = uitree(app.GridLayout13, 'checkbox');
            app.Lamp_5 = uilamp(app.GridLayout10);
            app.UITable10_2 = uitable(app.GridLayout10);
            app.UITable10_2.ColumnName = {'ID Item'; 'Item name'; 'Power lower bound [W]'; 'Power upper bound [W]'};
            app.UITable10_2.RowName = {};
            app.UITable10_2.Visible = 'off';
            app.OptimizationalgorithmsettingPanel = uipanel(app.GridLayout6);
            app.OptimizationalgorithmsettingPanel.Title = 'Optimization algorithm setting';
            app.OptimizationalgorithmsettingPanel.Visible = 'off';
            app.GridLayout11 = uigridlayout(app.OptimizationalgorithmsettingPanel);
            app.OptimizationalgorithmDropDownLabel = uilabel(app.GridLayout11);
            app.OptimizationalgorithmDropDownLabel.Text = 'Optimization algorithm';
            app.OptimizationalgorithmDropDown = uidropdown(app.GridLayout11);
            app.OptimizationalgorithmDropDown.Items = {'MOEA-D', 'RVEA', 'MOPSO', 'RSA', 'NGSA-III', 'NGSA-II', 'MOGWO'};
            app.OptimizationalgorithmDropDown.Value = 'RSA';
            app.MaxfunctionevaluationsEditFieldLabel = uilabel(app.GridLayout11);
            app.MaxfunctionevaluationsEditFieldLabel.Text = 'Max function evaluations';
            app.MaxfunctionevaluationsEditField = uieditfield(app.GridLayout11, 'numeric');
            app.MaxfunctionevaluationsEditField.Limits = [1 Inf];
            app.MaxfunctionevaluationsEditField.RoundFractionalValues = 'on';
            app.MaxfunctionevaluationsEditField.ValueDisplayFormat = '%.0f';
            app.MaxfunctionevaluationsEditField.Value = 10000;
            app.PopulationsizeEditFieldLabel = uilabel(app.GridLayout11);
            app.PopulationsizeEditFieldLabel.Text = 'Population size';
            app.PopulationsizeEditField = uieditfield(app.GridLayout11, 'numeric');
            app.PopulationsizeEditField.Limits = [1 Inf];
            app.PopulationsizeEditField.RoundFractionalValues = 'on';
            app.PopulationsizeEditField.ValueDisplayFormat = '%.0f';
            app.PopulationsizeEditField.Value = 100;
            app.TimesteptransientsEditFieldLabel = uilabel(app.GridLayout11);
            app.TimesteptransientsEditFieldLabel.Text = 'Time step transient [s]';
            app.TimesteptransientsEditField = uieditfield(app.GridLayout11, 'numeric');
            app.TimesteptransientsEditField.Limits = [1 Inf];
            app.TimesteptransientsEditField.Value = 60;
            app.PlotobjectivespaceduringoptimizationCheckBox = uicheckbox(app.GridLayout11);
            app.PlotobjectivespaceduringoptimizationCheckBox.Text = 'Plot objective space during optimization';
            app.PlotobjectivespaceduringoptimizationCheckBox.Value = true;
            app.OrbitstransientEditFieldLabel = uilabel(app.GridLayout11);
            app.OrbitstransientEditFieldLabel.Text = '# Orbits transient';
            app.OrbitstransientEditField = uieditfield(app.GridLayout11, 'numeric');
            app.OrbitstransientEditField.Limits = [1 Inf];
            app.OrbitstransientEditField.RoundFractionalValues = 'on';
            app.OrbitstransientEditField.ValueDisplayFormat = '%.0f';
            app.OrbitstransientEditField.Value = 5;
            app.SavetransientresultsafteroptimizationCheckBox = uicheckbox(app.GridLayout11);
            app.SavetransientresultsafteroptimizationCheckBox.Text = 'Save transient results after optimization';
            app.ConfirmStartOptimizationButton = uibutton(app.GridLayout6, 'push');
            app.ConfirmStartOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmStartOptimizationButtonPushed, true);
            app.ConfirmStartOptimizationButton.Visible = 'off';
            app.ConfirmStartOptimizationButton.Text = 'Confirm & Start Optimization';
            app.DecisionmakeralgorithmsettingPanel = uipanel(app.GridLayout6);
            app.DecisionmakeralgorithmsettingPanel.Title = 'Decision maker algorithm setting';
            app.DecisionmakeralgorithmsettingPanel.Visible = 'off';
            app.GridLayout44 = uigridlayout(app.DecisionmakeralgorithmsettingPanel);
            app.PerformtransientonlyonParetosolutionsCheckBox = uicheckbox(app.GridLayout44);
            app.PerformtransientonlyonParetosolutionsCheckBox.Text = 'Perform transient only on Pareto solutions';
            app.PerformtransientonlyonParetosolutionsCheckBox.Value = true;
            app.PerformpruningofsolutionduringtransientCheckBox = uicheckbox(app.GridLayout44);
            app.PerformpruningofsolutionduringtransientCheckBox.Text = {'Perform pruning of solutions during transient'; 'Warning: may lead to no acceptable solutions'};
            app.UITable13 = uitable(app.GridLayout44);
            app.UITable13.ColumnName = {'Objective'; 'Relative weight'};
            app.UITable13.RowName = {};
            app.UITable13.ColumnEditable = [false true];
            app.Lamp_7 = uilamp(app.GridLayout6);
            app.Lamp_7.Visible = 'off';
            app.ResultsTab = uitab(app.TabGroup4);
            app.ResultsTab.Title = 'Results';
            app.GridLayout20 = uigridlayout(app.ResultsTab);
            app.UIAxes9 = uiaxes(app.GridLayout20);
            title(app.UIAxes9, 'Utility Value')
            xlabel(app.UIAxes9, 'Solution ID')
            ylabel(app.UIAxes9, 'Utility')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes9.Visible = 'off';
            app.AllsolutionsPanel = uipanel(app.GridLayout20);
            app.AllsolutionsPanel.Title = 'All solutions';
            app.AllsolutionsPanel.Visible = 'off';
            app.GridLayout21 = uigridlayout(app.AllsolutionsPanel);
            app.UITable12 = uitable(app.GridLayout21);
            app.UITable12.ColumnName = {'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'; 'a'};
            app.UITable12.RowName = {};
            app.UITable12.ColumnSortable = true;
            app.ExportAllsolutionstabulardatatospreadsheetButton = uibutton(app.GridLayout20, 'push');
            app.ExportAllsolutionstabulardatatospreadsheetButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAllsolutionstabulardatatospreadsheetButtonPushed, true);
            app.ExportAllsolutionstabulardatatospreadsheetButton.Text = '     Export "All solutions" tabular data to spreadsheet     ';
            app.RobustnessanalysisTab = uitab(app.TabGroup4);
            app.RobustnessanalysisTab.Title = 'Robustness analysis';
            app.GridLayout45 = uigridlayout(app.RobustnessanalysisTab);
            app.UIAxes10 = uiaxes(app.GridLayout45);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, 'Solution ID')
            ylabel(app.UIAxes10, 'Utility Standard Deviation')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes10.Visible = 'off';
            app.UIAxes11 = uiaxes(app.GridLayout45);
            title(app.UIAxes11, 'Title')
            xlabel(app.UIAxes11, 'Utility Standard Deviation')
            ylabel(app.UIAxes11, 'Utility')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes11.Visible = 'off';
            app.RobustnessanalysissettingsPanel = uipanel(app.GridLayout45);
            app.RobustnessanalysissettingsPanel.Title = 'Robustness analysis settings';
            app.RobustnessanalysissettingsPanel.Visible = 'off';
            app.GridLayout46 = uigridlayout(app.RobustnessanalysissettingsPanel);
            app.PerturbationsforeverytransientsolutionEditFieldLabel = uilabel(app.GridLayout46);
            app.PerturbationsforeverytransientsolutionEditFieldLabel.Text = '# Perturbations for every transient solution';
            app.PerturbationsforeverytransientsolutionEditField = uieditfield(app.GridLayout46, 'numeric');
            app.PerturbationsforeverytransientsolutionEditField.Limits = [1 Inf];
            app.PerturbationsforeverytransientsolutionEditField.RoundFractionalValues = 'on';
            app.PerturbationsforeverytransientsolutionEditField.ValueDisplayFormat = '%.0f';
            app.PerturbationsforeverytransientsolutionEditField.Value = 10;
            app.MaximumrelativevariationofoptimizationparametersEditFieldLabel = uilabel(app.GridLayout46);
            app.MaximumrelativevariationofoptimizationparametersEditFieldLabel.Text = 'Maximum relative variation of optimization parameters [0,1]';
            app.Maximumrelativevariationofoptimizationparameters01EditField = uieditfield(app.GridLayout46, 'numeric');
            app.Maximumrelativevariationofoptimizationparameters01EditField.Limits = [0 1];
            app.Maximumrelativevariationofoptimizationparameters01EditField.Value = 0.1;
            app.StartRobustnessAnalysisButton = uibutton(app.GridLayout46, 'push');
            app.StartRobustnessAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartRobustnessAnalysisButtonPushed, true);
            app.StartRobustnessAnalysisButton.Text = 'Start Robustness Analysis';
            app.GridLayout47 = uigridlayout(app.GridLayout46);
            app.Lamp_8 = uilamp(app.GridLayout47);
            app.PerturbationoptionsButtonGroup = uibuttongroup(app.GridLayout46);
            app.PerturbationoptionsButtonGroup.Title = 'Perturbation options';
            app.ApplyperturbationbasedonupperandlowerboundsButton = uiradiobutton(app.PerturbationoptionsButtonGroup);
            app.ApplyperturbationbasedonupperandlowerboundsButton.Text = 'Apply perturbation based on upper and lower bounds';
            app.ApplyperturbationbasedonsolutionparametersButton = uiradiobutton(app.PerturbationoptionsButtonGroup);
            app.ApplyperturbationbasedonsolutionparametersButton.Text = 'Apply perturbation based on solution parameters';
            app.ApplyperturbationbasedonsolutionparametersButton.Value = true;
            app.MCRTReflectionTab = uitab(app.TabGroup);
            app.MCRTReflectionTab.Title = 'MCRT Reflection';
            app.GridLayoutRef = uigridlayout(app.MCRTReflectionTab);
            app.TabGroupRef = uitabgroup(app.GridLayoutRef);
            app.RunGraphTabRef = uitab(app.TabGroupRef);
            app.RunGraphTabRef.Title = 'Run & Graph';
            app.GridLayoutRef_2 = uigridlayout(app.RunGraphTabRef);
            app.UIAxesRef_1 = uiaxes(app.GridLayoutRef_2);
            title(app.UIAxesRef_1, 'Temperature Trends')
            xlabel(app.UIAxesRef_1, 'Time [s]')
            ylabel(app.UIAxesRef_1, 'T[°C]')
            app.UIAxesRef_1.Visible = 'off';
            app.LampRef = uilamp(app.GridLayoutRef_2);
            app.LampRef.Visible = 'off';
            app.PreparationLabelRef = uilabel(app.GridLayoutRef_2);
            app.PreparationLabelRef.Visible = 'off';
            app.PreparationLabelRef.Text = 'Preparation';
            app.PlotTemperatureButtonRef_1 = uibutton(app.GridLayoutRef_2, 'push');
            app.PlotTemperatureButtonRef_1.ButtonPushedFcn = createCallbackFcn(app, @PlotTemperatureButtonRef_1Pushed, true);
            app.PlotTemperatureButtonRef_1.Visible = 'off';
            app.PlotTemperatureButtonRef_1.Text = 'Plot Temperature';
            app.WorstcasePanelRef_1 = uipanel(app.GridLayoutRef_2);
            app.WorstcasePanelRef_1.Title = 'Worst case';
            app.WorstcasePanelRef_1.Visible = 'off';
            app.GridLayoutRef_3 = uigridlayout(app.WorstcasePanelRef_1);
            app.TreeRef_1 = uitree(app.GridLayoutRef_3);
            app.TreeRef_1.Multiselect = 'on';
            app.WorstcasePanelRef_2 = uipanel(app.GridLayoutRef_2);
            app.WorstcasePanelRef_2.Title = 'Worst case';
            app.WorstcasePanelRef_2.Visible = 'off';
            app.GridLayoutRef_4 = uigridlayout(app.WorstcasePanelRef_2);
            app.TreeRef_2 = uitree(app.GridLayoutRef_4);
            app.TreeRef_2.Multiselect = 'on';
            app.RunSimulationButtonRef = uibutton(app.GridLayoutRef_2, 'push');
            app.RunSimulationButtonRef.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonRefPushed, true);
            app.RunSimulationButtonRef.Visible = 'off';
            app.RunSimulationButtonRef.Text = 'Run Simulation';
            app.EditFieldLabel = uilabel(app.GridLayoutRef_2);
            app.EditFieldLabel.Visible = 'off';
            app.EditFieldLabel.Text = {'Select ID of the optimization solution'; '(0 = non-optimized solution)'};
            app.EditField = uieditfield(app.GridLayoutRef_2, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.Visible = 'off';
            app.HeatMapTabRef_1 = uitab(app.TabGroupRef);
            app.HeatMapTabRef_1.Title = 'Heat Map';
            app.GridLayoutRef_5 = uigridlayout(app.HeatMapTabRef_1);
            app.SatellitepositionPanelRef_1 = uipanel(app.GridLayoutRef_5);
            app.SatellitepositionPanelRef_1.Title = 'Satellite position';
            app.SatellitepositionPanelRef_1.Visible = 'off';
            app.GridLayout16_3 = uigridlayout(app.SatellitepositionPanelRef_1);
            app.UIAxesRef_2 = uiaxes(app.GridLayout16_3);
            title(app.UIAxesRef_2, 'Title')
            xlabel(app.UIAxesRef_2, 'X')
            ylabel(app.UIAxesRef_2, 'Y')
            app.HeatMapTemperatureCPanelRef_1 = uipanel(app.GridLayoutRef_5);
            app.HeatMapTemperatureCPanelRef_1.Title = 'Heat Map - Temperature [°C]';
            app.HeatMapTemperatureCPanelRef_1.Visible = 'off';
            app.GridLayout17_3 = uigridlayout(app.HeatMapTemperatureCPanelRef_1);
            app.UIAxesRef_3 = uiaxes(app.GridLayout17_3);
            title(app.UIAxesRef_3, 'Title')
            xlabel(app.UIAxesRef_3, 'X')
            ylabel(app.UIAxesRef_3, 'Y')
            app.TimeControlPanelRef_1 = uipanel(app.GridLayoutRef_5);
            app.TimeControlPanelRef_1.Title = 'Time Control';
            app.TimeControlPanelRef_1.Visible = 'off';
            app.TimeminSlider_3Label_2 = uilabel(app.TimeControlPanelRef_1);
            app.TimeminSlider_3Label_2.Text = 'Time [min]';
            app.TimeminSliderRef_1 = uislider(app.TimeControlPanelRef_1);
            app.TimeminSliderRef_1.MajorTicks = [0 50 100];
            app.TimeminSliderRef_1.ValueChangedFcn = createCallbackFcn(app, @TimeminSliderRef_1ValueChanged, true);
            app.TimesEditField_3Label_2 = uilabel(app.TimeControlPanelRef_1);
            app.TimesEditField_3Label_2.Text = 'Time [s]';
            app.TimesEditFieldRef_1 = uieditfield(app.TimeControlPanelRef_1, 'numeric');
            app.TimesEditFieldRef_1.ValueDisplayFormat = '%.0f';
            app.TimesEditFieldRef_1.Editable = 'off';
            app.TrasparencyButtonGroupRef_1 = uibuttongroup(app.GridLayoutRef_5);
            app.TrasparencyButtonGroupRef_1.SelectionChangedFcn = createCallbackFcn(app, @TrasparencyButtonGroupRef_1SelectionChanged, true);
            app.TrasparencyButtonGroupRef_1.Title = 'Trasparency';
            app.TrasparencyButtonGroupRef_1.Visible = 'off';
            app.ExternalButtonRef = uiradiobutton(app.TrasparencyButtonGroupRef_1);
            app.ExternalButtonRef.Text = 'External';
            app.ExternalButtonRef.Value = true;
            app.InternalButtonRef = uiradiobutton(app.TrasparencyButtonGroupRef_1);
            app.InternalButtonRef.Text = 'Internal';
            app.BothButtonRef = uiradiobutton(app.TrasparencyButtonGroupRef_1);
            app.BothButtonRef.Text = 'Both';
            app.HeatMapTabRef_2 = uitab(app.TabGroupRef);
            app.GridLayout5_3 = uigridlayout(app.HeatMapTabRef_2);
            app.SatellitepositionPanelRef_2 = uipanel(app.GridLayout5_3);
            app.SatellitepositionPanelRef_2.Title = 'Satellite position';
            app.SatellitepositionPanelRef_2.Visible = 'off';
            app.UIAxesRef_4 = uiaxes(app.SatellitepositionPanelRef_2);
            title(app.UIAxesRef_4, 'Title')
            xlabel(app.UIAxesRef_4, 'X')
            ylabel(app.UIAxesRef_4, 'Y')
            app.HeatMapTemperatureCPanelRef_2 = uipanel(app.GridLayout5_3);
            app.HeatMapTemperatureCPanelRef_2.Title = 'Heat Map - Temperature [°C]';
            app.HeatMapTemperatureCPanelRef_2.Visible = 'off';
            app.UIAxesRef_5 = uiaxes(app.HeatMapTemperatureCPanelRef_2);
            title(app.UIAxesRef_5, 'Title')
            xlabel(app.UIAxesRef_5, 'X')
            ylabel(app.UIAxesRef_5, 'Y')
            app.TimeControlPanelRef_2 = uipanel(app.GridLayout5_3);
            app.TimeControlPanelRef_2.Title = 'Time Control';
            app.TimeControlPanelRef_2.Visible = 'off';
            app.TimeminSlider_4Label_2 = uilabel(app.TimeControlPanelRef_2);
            app.TimeminSlider_4Label_2.Text = 'Time [min]';
            app.TimeminSliderRef_2 = uislider(app.TimeControlPanelRef_2);
            app.TimeminSliderRef_2.MajorTicks = [0 50 100];
            app.TimeminSliderRef_2.ValueChangedFcn = createCallbackFcn(app, @TimeminSliderRef_2ValueChanged, true);
            app.TimesEditField_4Label_2 = uilabel(app.TimeControlPanelRef_2);
            app.TimesEditField_4Label_2.Text = 'Time [s]';
            app.TimesEditFieldRef_2 = uieditfield(app.TimeControlPanelRef_2, 'numeric');
            app.TimesEditFieldRef_2.Editable = 'off';
            app.TrasparencyButtonGroupRef_2 = uibuttongroup(app.GridLayout5_3);
            app.TrasparencyButtonGroupRef_2.SelectionChangedFcn = createCallbackFcn(app, @TrasparencyButtonGroupRef_2SelectionChanged, true);
            app.TrasparencyButtonGroupRef_2.Title = 'Trasparency';
            app.TrasparencyButtonGroupRef_2.Visible = 'off';
            app.ExternalButton_5 = uiradiobutton(app.TrasparencyButtonGroupRef_2);
            app.ExternalButton_5.Text = 'External';
            app.ExternalButton_5.Value = true;
            app.InternalButton_5 = uiradiobutton(app.TrasparencyButtonGroupRef_2);
            app.InternalButton_5.Text = 'Internal';
            app.BothButton_5 = uiradiobutton(app.TrasparencyButtonGroupRef_2);
            app.BothButton_5.Text = 'Both';
            app.InputsetupLabel = uilabel(app.GridLayout_all);
            app.InputsetupLabel.Text = 'Input setup';
            app.InputSetLamp = uilamp(app.GridLayout_all);
            app.EnvironmentLabel = uilabel(app.GridLayout_all);
            app.EnvironmentLabel.Text = 'Environment';
            app.Lamp = uilamp(app.GridLayout_all);
            app.GMMLabel = uilabel(app.GridLayout_all);
            app.GMMLabel.Text = 'GMM';
            app.Lamp_3 = uilamp(app.GridLayout_all);
            app.TMMDissipationLabel = uilabel(app.GridLayout_all);
            app.TMMDissipationLabel.Text = 'TMM & Dissipation';
            app.ConfirmSaveButton = uibutton(app.GridLayout_all, 'push');
            app.ConfirmSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmSaveButtonPushed, true);
            app.ConfirmSaveButton.Enable = 'off';
            app.ConfirmSaveButton.Text = 'Confirm & Save';
            app.Lamp_2 = uilamp(app.GridLayout_all);
            app.ReadytoRunSimulationLabel = uilabel(app.GridLayout_all);
            app.ReadytoRunSimulationLabel.Text = 'Ready to Run Simulation';
            % Show the figure after all components are created
            app.S2T2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TAT2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.S2T2)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.S2T2)
        end
    end
end